classdef GUI_Syre < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        CloseallButton                  matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        MainDataTab                     matlab.ui.container.Tab
        ScaleCheck                      matlab.ui.control.StateButton
        CustomPanel                     matlab.ui.container.Panel
        CustomClear                     matlab.ui.control.Button
        CustomLoad                      matlab.ui.control.Button
        StateLamp                       matlab.ui.control.Lamp
        PreliminaryDesignPanel          matlab.ui.container.Panel
        RotoryokefactorpuEditField      matlab.ui.control.EditField
        RotoryokefactorpuEditFieldLabel  matlab.ui.control.Label
        SaturationFactorDropDown        matlab.ui.control.DropDown
        syrmDesignGammaFixDropDown      matlab.ui.control.DropDown
        CarrierDesignDropDown           matlab.ui.control.DropDown
        syrmDesignThermalInputDropDown  matlab.ui.control.DropDown
        BarrierDesignDropDow            matlab.ui.control.DropDown
        FEAfixPushButt                  matlab.ui.control.Button
        syrmDesignPushButt              matlab.ui.control.Button
        FEAfixPopUp                     matlab.ui.control.DropDown
        ofFEAfixsimulationsDropDownLabel  matlab.ui.control.Label
        ktEdit                          matlab.ui.control.EditField
        ToothsizefactorpuLabel          matlab.ui.control.Label
        BfeEdit                         matlab.ui.control.EditField
        IronLoadingTEditFieldLabel      matlab.ui.control.Label
        CurrLoXBEdit                    matlab.ui.control.EditField
        CurrentoverloadpuEditFieldLabel  matlab.ui.control.Label
        bRangeEdit                      matlab.ui.control.EditField
        RangeofbairgapironsplitEditFieldLabel  matlab.ui.control.Label
        xRangeEdit                      matlab.ui.control.EditField
        RangeofxrotorstatorsplitEditFieldLabel  matlab.ui.control.Label
        TypeOfRotorList                 matlab.ui.control.DropDown
        TypeofrotorDropDownLabel        matlab.ui.control.Label
        StackLenghtEdit                 matlab.ui.control.EditField
        StacklengthmmEditFieldLabel     matlab.ui.control.Label
        ShaftRadEdit                    matlab.ui.control.EditField
        ShaftradiusmmEditFieldLabel     matlab.ui.control.Label
        AirGapRadiusEdit                matlab.ui.control.EditField
        AirgapradiusmmEditFieldLabel    matlab.ui.control.Label
        StatorOuterRadEdit              matlab.ui.control.EditField
        StatorouterradiusmmEditFieldLabel  matlab.ui.control.Label
        GapThiEdit                      matlab.ui.control.EditField
        AirgapthicknessmmEditFieldLabel  matlab.ui.control.Label
        NumOfSlotsEdit                  matlab.ui.control.EditField
        NumberofslotspolephaseEditFieldLabel  matlab.ui.control.Label
        PolePairsEdit                   matlab.ui.control.EditField
        NumberofpolepairsEditFieldLabel  matlab.ui.control.Label
        GeometryTab                     matlab.ui.container.Tab
        StatorParametersPanel           matlab.ui.container.Panel
        SlotWidthEdit                   matlab.ui.control.EditField
        SlotwidthmmLabel                matlab.ui.control.Label
        FillCorSlotEdit                 matlab.ui.control.EditField
        FilletatslotbottommmEditFieldLabel  matlab.ui.control.Label
        ToothTangAngleEdit              matlab.ui.control.EditField
        ToothtangangleEditFieldLabel    matlab.ui.control.Label
        ToothTanDepEdit                 matlab.ui.control.EditField
        ToothtangdepthmmEditFieldLabel  matlab.ui.control.Label
        SlotshapeDropDown               matlab.ui.control.DropDown
        SlotshapeDropDownLabel          matlab.ui.control.Label
        StatorSlotOpeEdit               matlab.ui.control.EditField
        StatorslotopeningpuEditFieldLabel  matlab.ui.control.Label
        ToothWidthEdit                  matlab.ui.control.EditField
        ToothwidthmmEditFieldLabel      matlab.ui.control.Label
        ToothLengEdit                   matlab.ui.control.EditField
        ToothlengthmmEditFieldLabel     matlab.ui.control.Label
        RotorParametersPanel            matlab.ui.container.Panel
        CentralBarriersShrinkEdit       matlab.ui.control.EditField
        CentralbarriersshrinkpuLabel    matlab.ui.control.Label
        NarrowFactorEdit                matlab.ui.control.EditField
        OuterbranchnarrowingfactorpuLabel  matlab.ui.control.Label
        RadShiftInnerEdit               matlab.ui.control.EditField
        InnerbranchradialshiftmmLabel   matlab.ui.control.Label
        ThetaFBSEdit                    matlab.ui.control.EditField
        ThetaFBSmechEditFieldLabel      matlab.ui.control.Label
        BetaEdit                        matlab.ui.control.EditField
        PMshapefactorbetapuEditFieldLabel  matlab.ui.control.Label
        DxEdit                          matlab.ui.control.EditField
        BarriersoffsetpuLabel           matlab.ui.control.Label
        hcmmEdit                        matlab.ui.control.EditField
        BarrierswidthmmLabel            matlab.ui.control.Label
        hcpuEdit                        matlab.ui.control.EditField
        BarrierswidthpuLabel            matlab.ui.control.Label
        AlphadegreeEdit                 matlab.ui.control.EditField
        BarriersanglesalphaEditFieldLabel  matlab.ui.control.Label
        AlphapuEdit                     matlab.ui.control.EditField
        BarriersanglesalphapuEditFieldLabel  matlab.ui.control.Label
        NumberOfLayersEdit              matlab.ui.control.EditField
        NumberofrotorbarriersEditFieldLabel  matlab.ui.control.Label
        OptionsTab                      matlab.ui.container.Tab
        RibsDesignPanel                 matlab.ui.container.Panel
        TanRibCheck                     matlab.ui.control.StateButton
        RotorfilletmmEditFieldLabel_5   matlab.ui.control.Label
        RotorFilletTan2EditField        matlab.ui.control.EditField
        SplitRibsEditField              matlab.ui.control.EditField
        RotorFilletRadOutEditField      matlab.ui.control.EditField
        RotorfilletmmEditFieldLabel_4   matlab.ui.control.Label
        RadRibCheck                     matlab.ui.control.StateButton
        RotorfilletmmEditFieldLabel_    matlab.ui.control.Label
        RotorFilletRadInEditField       matlab.ui.control.EditField
        RotorfilletmmEditFieldLabel_2   matlab.ui.control.Label
        RadialRibsOffsetEditField       matlab.ui.control.EditField
        TangentialribswidthmmEditFieldLabel_3  matlab.ui.control.Label
        RadialRibsAngleEditField        matlab.ui.control.EditField
        TangentialribswidthmmEditFieldLabel_2  matlab.ui.control.Label
        RotorFilletTan1EditField        matlab.ui.control.EditField
        RotorfilletmmEditFieldLabel     matlab.ui.control.Label
        RadRibEdit                      matlab.ui.control.EditField
        RadialribswidthmmEditFieldLabel  matlab.ui.control.Label
        TanRibEdit                      matlab.ui.control.EditField
        TangentialribswidthmmEditFieldLabel  matlab.ui.control.Label
        StructuralParametersPanel       matlab.ui.container.Panel
        MecTolerEdit                    matlab.ui.control.EditField
        MinimummechtolerancemmLabel     matlab.ui.control.Label
        OverSpeedEdit                   matlab.ui.control.EditField
        OverspeedrpmLabel               matlab.ui.control.Label
        MeshControlPanel                matlab.ui.container.Panel
        MeshMOOAEdit                    matlab.ui.control.EditField
        Mesh_MOOALabel                  matlab.ui.control.Label
        MeshEdit                        matlab.ui.control.EditField
        MeshLabel                       matlab.ui.control.Label
        ThermalParametersPanel          matlab.ui.container.Panel
        CurrentdensityEdit              matlab.ui.control.EditField
        RMScurrentdensityAmm2Label      matlab.ui.control.Label
        Rsedit                          matlab.ui.control.EditField
        PhresistancetargettemperatureOhmLabel  matlab.ui.control.Label
        EstimatedCoppTemp               matlab.ui.control.EditField
        EstimatedcoppertemperatureCLabel  matlab.ui.control.Label
        JouleLossesEdit                 matlab.ui.control.EditField
        AdmittedcopperlossWLabel        matlab.ui.control.Label
        HousingTempEdit                 matlab.ui.control.EditField
        HousingtemperatureCEditFieldLabel  matlab.ui.control.Label
        CalculatedRatedCurrent          matlab.ui.control.EditField
        CalculatedratedcurrentALabel    matlab.ui.control.Label
        CopperTempEdit                  matlab.ui.control.EditField
        TargetcoppertemperatureCLabel   matlab.ui.control.Label
        ThermalLoadKj                   matlab.ui.control.EditField
        ThermalloadingkjWm2Label        matlab.ui.control.Label
        WindingsTab                     matlab.ui.container.Tab
        SlotModelPanel                  matlab.ui.container.Panel
        ParallelNumberEdit              matlab.ui.control.EditField
        NumberofparallelsLabel          matlab.ui.control.Label
        ConductorSlotGapEdit            matlab.ui.control.EditField
        ConductorslotgapmmLabel         matlab.ui.control.Label
        SlotModelTemperatureEdit        matlab.ui.control.EditField
        TemperaturevectorCLabel         matlab.ui.control.Label
        EvalSlotModelPush               matlab.ui.control.Button
        DrawSlotModelPush               matlab.ui.control.Button
        SlotModelFrequencyEdit          matlab.ui.control.EditField
        FrequencyvectorHzEditFieldLabel  matlab.ui.control.Label
        ConductorHeightEdit             matlab.ui.control.EditField
        ConductorheightmmEditFieldLabel  matlab.ui.control.Label
        ConductorWidthEdit              matlab.ui.control.EditField
        ConductorwidthmmEditFieldLabel  matlab.ui.control.Label
        ConductorRadiusEdit             matlab.ui.control.EditField
        ConductorradiusmmEditFieldLabel  matlab.ui.control.Label
        ConductorNumberEdit             matlab.ui.control.EditField
        NumberofconductorsEditFieldLabel  matlab.ui.control.Label
        ConductorInsulationEdit         matlab.ui.control.EditField
        InsulationthicknessmmEditFieldLabel  matlab.ui.control.Label
        ConductorTypeEdit               matlab.ui.control.DropDown
        ConductortypeDropDownLabel      matlab.ui.control.Label
        SetDefaultWinding               matlab.ui.control.Button
        SaveConfPush                    matlab.ui.control.Button
        WinTable                        matlab.ui.control.Table
        Num3PhaseCircuitEdit            matlab.ui.control.EditField
        Numberof3phasesetsEditFieldLabel  matlab.ui.control.Label
        SlotSimulEdit                   matlab.ui.control.EditField
        NumberofsimulatedslotsEditFieldLabel  matlab.ui.control.Label
        SlotlayerposDropDown            matlab.ui.control.DropDown
        SlotlayerpositionDropDownLabel  matlab.ui.control.Label
        PitchWindEdit                   matlab.ui.control.EditField
        PitchshorteningfactorEditFieldLabel  matlab.ui.control.Label
        TurnsSeriesEdit                 matlab.ui.control.EditField
        TurnsinseriesperphaseEditFieldLabel  matlab.ui.control.Label
        SlotFillFacEdit                 matlab.ui.control.EditField
        SlotfillingfactorEditFieldLabel  matlab.ui.control.Label
        MaterialsTab_2                  matlab.ui.container.Tab
        MagnetPanel                     matlab.ui.container.Panel
        PMclearanceTable                matlab.ui.control.Table
        CarCurEdit                      matlab.ui.control.EditField
        TargetcharacteristiccurrentpuEditFieldLabel  matlab.ui.control.Label
        PMtemperatureEdit               matlab.ui.control.EditField
        PMtemperatureCEditFieldLabel_3  matlab.ui.control.Label
        BrPMEdit                        matlab.ui.control.EditField
        PMremanenceTEditFieldLabel_3    matlab.ui.control.Label
        PMdimTable                      matlab.ui.control.Table
        PMDesignPush                    matlab.ui.control.Button
        RotorInertiaLabel               matlab.ui.control.Label
        TotalmotormassLabel             matlab.ui.control.Label
        kgm2Label                       matlab.ui.control.Label
        RotorInertiaEditField           matlab.ui.control.EditField
        kgLabel_6                       matlab.ui.control.Label
        MassTotalEditField              matlab.ui.control.EditField
        kgLabel_4                       matlab.ui.control.Label
        MassFluxBarrierEditField        matlab.ui.control.EditField
        kgLabel_3                       matlab.ui.control.Label
        MassRotorIronEditField          matlab.ui.control.EditField
        kgLabel_2                       matlab.ui.control.Label
        MassStatorIronEditField         matlab.ui.control.EditField
        kgLabel                         matlab.ui.control.Label
        MassWindingEditField            matlab.ui.control.EditField
        ShaftmaterialDropDown           matlab.ui.control.DropDown
        ShaftmaterialDropDownLabel      matlab.ui.control.Label
        FluxbarriermaterialDropDown     matlab.ui.control.DropDown
        FluxbarriermaterialDropDownLabel  matlab.ui.control.Label
        RotorcorematerialDropDown       matlab.ui.control.DropDown
        RotorcorematerialDropDownLabel  matlab.ui.control.Label
        StatorcorematerialDropDown      matlab.ui.control.DropDown
        StatorcorematerialDropDownLabel  matlab.ui.control.Label
        StatorslotmaterialDropDown      matlab.ui.control.DropDown
        StatorslotmaterialDropDownLabel  matlab.ui.control.Label
        MaterialListBox                 matlab.ui.control.TextArea
        RmvMagnetPush                   matlab.ui.control.Button
        RmvConductorPush                matlab.ui.control.Button
        RmvIronPush                     matlab.ui.control.Button
        AddMagnetPush                   matlab.ui.control.Button
        AddConductorPush                matlab.ui.control.Button
        AddIronPush                     matlab.ui.control.Button
        ViewPropPush                    matlab.ui.control.Button
        BarrierLibraryPush              matlab.ui.control.Button
        ConductorLibraryPush            matlab.ui.control.Button
        IronLibraryPush                 matlab.ui.control.Button
        OptimizationTab_2               matlab.ui.container.Tab
        MechStressOptCheck              matlab.ui.control.CheckBox
        CurrentOverLoadEdit             matlab.ui.control.EditField
        CurrentoverloadpuEditFieldLabel_2  matlab.ui.control.Label
        OptimizePush                    matlab.ui.control.Button
        ObjectivesandPenalizationLimitsPanel  matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        NoLoadFluxOptCheck              matlab.ui.control.StateButton
        MaxExpNoLoadFluxEdit            matlab.ui.control.EditField
        PowerFactorOptCheck             matlab.ui.control.StateButton
        MinExpPowerFactorEdit           matlab.ui.control.EditField
        MassPMEdit                      matlab.ui.control.EditField
        MassPMOptCheck                  matlab.ui.control.StateButton
        MassCuEdit                      matlab.ui.control.EditField
        MassCuOptCheck                  matlab.ui.control.StateButton
        MaxExpeRippleTorEdit            matlab.ui.control.EditField
        TorRipOptCheck                  matlab.ui.control.StateButton
        TorqueOptCheck                  matlab.ui.control.StateButton
        MinExpTorEdit                   matlab.ui.control.EditField
        VariablesandBoundsPanel         matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        GammaBouCheck                   matlab.ui.control.StateButton
        PhaseAngleCurrBouEdit           matlab.ui.control.EditField
        BetaPMshapeBouCheck             matlab.ui.control.StateButton
        BetaPMshapeBouEdit              matlab.ui.control.EditField
        RadRibBouCheck                  matlab.ui.control.StateButton
        RadRibBouEdit                   matlab.ui.control.EditField
        TanRibBouCheck                  matlab.ui.control.StateButton
        TanRibBouEdit                   matlab.ui.control.EditField
        BrBouCheck                      matlab.ui.control.StateButton
        BrBouEdit                       matlab.ui.control.EditField
        PMdimBouCheck                   matlab.ui.control.StateButton
        PMdimBouEdit                    matlab.ui.control.EditField
        ThetaFBSBouCheck                matlab.ui.control.StateButton
        ThetaFBSBouEdit                 matlab.ui.control.EditField
        RadShiftInnerBouCheck           matlab.ui.control.StateButton
        RadShiftInnerBouEdit            matlab.ui.control.EditField
        CentralShrinkBouCheck           matlab.ui.control.StateButton
        CentralShrinkBouEdit            matlab.ui.control.EditField
        DxBouCheck                      matlab.ui.control.StateButton
        DfeBouEdit                      matlab.ui.control.EditField
        hcBouCheck                      matlab.ui.control.StateButton
        hcBouEdit                       matlab.ui.control.EditField
        DalphaBouCheck                  matlab.ui.control.StateButton
        DeltaAlphaBouEdit               matlab.ui.control.EditField
        Alpha1BouEdit                   matlab.ui.control.EditField
        Dalpha1BouCheck                 matlab.ui.control.StateButton
        GapBouCheck                     matlab.ui.control.StateButton
        GapBouEdit                      matlab.ui.control.EditField
        StatorSlotOpenBouCheck          matlab.ui.control.StateButton
        StatorSlotOpenBouEdit           matlab.ui.control.EditField
        ToothTangDepthBouCheck          matlab.ui.control.StateButton
        ToothTangDepthBouEdit           matlab.ui.control.EditField
        ToothLengthBouCheck             matlab.ui.control.StateButton
        ToothLenBouEdit                 matlab.ui.control.EditField
        ToothWidthBouCheck              matlab.ui.control.StateButton
        ToothWidthBouEdit               matlab.ui.control.EditField
        AirgapRadiusBouCheck            matlab.ui.control.StateButton
        AirgapRadiusBouEdit             matlab.ui.control.EditField
        TimesteppingforParetofrontreevaluationPanel  matlab.ui.container.Panel
        SimPosFinerEdit                 matlab.ui.control.EditField
        ofrotorpositionsEditField_2Label  matlab.ui.control.Label
        RotorPosFinerEdit               matlab.ui.control.EditField
        RotorangularexcursionEditField_2Label  matlab.ui.control.Label
        TimesteppingduringMODEPanel     matlab.ui.container.Panel
        SimPosMOEdit                    matlab.ui.control.EditField
        ofrotorpositionsEditFieldLabel  matlab.ui.control.Label
        RotorPosiMOEdit                 matlab.ui.control.EditField
        RotorangularexcursionEditFieldLabel  matlab.ui.control.Label
        OptimizationParametersPanel     matlab.ui.container.Panel
        XPopEdit                        matlab.ui.control.EditField
        PopulationsizeEditFieldLabel    matlab.ui.control.Label
        MaxGenEdit                      matlab.ui.control.EditField
        ofgenerationsEditFieldLabel     matlab.ui.control.Label
        SimulationTab                   matlab.ui.container.Tab
        AxistypeDropDown                matlab.ui.control.DropDown
        AxistypeDropDownLabel           matlab.ui.control.Label
        CustomCurrentPanel              matlab.ui.container.Panel
        LoadCustomCurrent               matlab.ui.control.Button
        CustomCurrentSwitch             matlab.ui.control.Switch
        CustomCurrentFile               matlab.ui.control.EditField
        text120_2                       matlab.ui.control.Label
        StartPProAnsysPush              matlab.ui.control.Button
        MapQuadrantsPopUp               matlab.ui.control.DropDown
        NumberofmapquadrantsLabel       matlab.ui.control.Label
        StartPProMagnetPush             matlab.ui.control.Button
        StartPProPush                   matlab.ui.control.Button
        EvalTypePopUp                   matlab.ui.control.DropDown
        EvaluationtypeDropDownLabel     matlab.ui.control.Label
        EvaluatedSpeedEdit              matlab.ui.control.EditField
        RotorspeedrpmEditFieldLabel     matlab.ui.control.Label
        NGridPPEdit                     matlab.ui.control.EditField
        Numberofpointsin0ImaxLabel      matlab.ui.control.Label
        TempPPEdit                      matlab.ui.control.EditField
        PMtemperatureCEditFieldLabel_2  matlab.ui.control.Label
        BrPPEdit                        matlab.ui.control.EditField
        PMremanenceTEditFieldLabel_2    matlab.ui.control.Label
        CurrentPP                       matlab.ui.control.EditField
        PhasecurrentAEditFieldLabel     matlab.ui.control.Label
        CurrLoPPEdit                    matlab.ui.control.EditField
        CurrentloadpuEditFieldLabel     matlab.ui.control.Label
        GammaPPEdit                     matlab.ui.control.EditField
        CurrentphaseangleeltEditFieldLabel  matlab.ui.control.Label
        NumOfRotorPosiPPEdit            matlab.ui.control.EditField
        NumberofrotorpositionsEditFieldLabel  matlab.ui.control.Label
        SpanEltPPEdit                   matlab.ui.control.EditField
        RotorangularexcursioneltEditFieldLabel  matlab.ui.control.Label
        MotorCADTab                     matlab.ui.container.Tab
        ThermalLimitsatZeroSpeedPanel   matlab.ui.container.Panel
        SimulatedRatedCurrent           matlab.ui.control.EditField
        OutputALabel_2                  matlab.ui.control.Label
        SimulatedTransientCurrent       matlab.ui.control.EditField
        OutputALabel                    matlab.ui.control.Label
        TransientTimeEditField          matlab.ui.control.EditField
        TransienttimesLabel             matlab.ui.control.Label
        TempCuLimitEdit                 matlab.ui.control.EditField
        CopperlimitCLabel               matlab.ui.control.Label
        CalculateTrans_MCAD             matlab.ui.control.Button
        CalculateCont_MCAD              matlab.ui.control.Button
        BuildThermalModelPanel          matlab.ui.container.Panel
        InlettemperatureEditField       matlab.ui.control.EditField
        InlettemperatureCEditFieldLabel  matlab.ui.control.Label
        FluidDropDown                   matlab.ui.control.DropDown
        FluidDropDownLabel              matlab.ui.control.Label
        ExportThermalParameters_MCADPush  matlab.ui.control.Button
        FluidFlowRateEditField          matlab.ui.control.EditField
        FluidflowratelminLabel          matlab.ui.control.Label
        HousingTypeDropDown             matlab.ui.control.DropDown
        HousingTypeDropDownLabel        matlab.ui.control.Label
        ElectromagneticModulePanel      matlab.ui.container.Panel
        StartMCadSimPush                matlab.ui.control.Button
        ExportFluxMapsPush              matlab.ui.control.Button
        ThermalSimulationPanel          matlab.ui.container.Panel
        AmbientTemperatureEditField     matlab.ui.control.EditField
        AmbienttemperatureCLabel        matlab.ui.control.Label
        CurrentPPMirror                 matlab.ui.control.EditField
        PhasecurrentAEditFieldLabel_2   matlab.ui.control.Label
        EvaluatedSpeedMirrorEdit        matlab.ui.control.EditField
        RotorspeedrpmEditFieldLabel_2   matlab.ui.control.Label
        TempPPMirrorEdit                matlab.ui.control.EditField
        PMtemperatureCEditFieldLabel_4  matlab.ui.control.Label
        GammaPPMirrorEdit               matlab.ui.control.EditField
        CurrentphaseangleeltEditFieldLabel_2  matlab.ui.control.Label
        ThermalevaluationtypeDropDown   matlab.ui.control.DropDown
        ThermalevaluationtypeDropDownLabel  matlab.ui.control.Label
        InitialTemperatureEditField     matlab.ui.control.EditField
        InitialtemperatureCLabel_2      matlab.ui.control.Label
        ThermalSimulation_MCAD          matlab.ui.control.Button
        TimestepEditField               matlab.ui.control.EditField
        NumberofpointsLabel             matlab.ui.control.Label
        TransientperiodEditField        matlab.ui.control.EditField
        TransientperiodsEditFieldLabel  matlab.ui.control.Label
        ExportMCadPush                  matlab.ui.control.Button
        AxisLogoMCAD                    matlab.ui.control.UIAxes
        UtilitiesTab                    matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        LaunchsyrmDesignExplorerButton  matlab.ui.control.Button
        LaunchMMMButton                 matlab.ui.control.Button
        ReleaseandToolboxesPanel        matlab.ui.container.Panel
        CheckReleaseButton              matlab.ui.control.Button
        ParallelcomputingPanel          matlab.ui.container.Panel
        DisableButton                   matlab.ui.control.Button
        EnableButton                    matlab.ui.control.Button
        CheckButton                     matlab.ui.control.Button
        DocumentationPanel              matlab.ui.container.Panel
        ReferencesButton                matlab.ui.control.Button
        UsermanualButton                matlab.ui.control.Button
        ExportPanel                     matlab.ui.container.Panel
        AnsysMotorCADButton             matlab.ui.control.Button
        SaveMachineAnsysPush            matlab.ui.control.Button
        SaveMachineMagnetPush           matlab.ui.control.Button
        dxfButton                       matlab.ui.control.Button
        LoadMachinePush                 matlab.ui.control.Button
        text120                         matlab.ui.control.Label
        currentMotFileName              matlab.ui.control.EditField
        ClearCachePush                  matlab.ui.control.Button
        text95                          matlab.ui.control.Label
        SaveMachinePush                 matlab.ui.control.Button
        AxisLogo                        matlab.ui.control.UIAxes
        AxisGeometry                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % definition of the properties of app. Used to share data among the
        % application (as dataSet)
        dataSet                             % main machine data
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUI_Syre_OpeningFcn(app)
            
            reset(groot); % reset graphics settings to default values
            
            setupPath(0);
            
            % Set figure at the center of the screen
            app.figure1.Units = 'pixels';
            screenPos = get(groot,'ScreenSize');
            figPos = app.figure1.Position;
            figPos(1:2)=screenPos(3:4)/2-figPos(3:4)/2;
            app.figure1.Position = figPos;
            app.figure1.Icon = 'icon.png';
            
            % set the first tab
            app.TabGroup.SelectedTab = app.TabGroup.Children(1);
            
            % Axes update
            
            im = imread('syre.png');
            image(app.AxisLogo,im);
            set(app.AxisLogo,'dataAspectRatio',[1 1 1],'Visible','off')
            
            im = imread('mcad.jpg');
            image(app.AxisLogoMCAD,im);
            set(app.AxisLogoMCAD,'dataAspectRatio',[1 1 1],'Visible','off')
            
            set(app.AxisGeometry,...
                'XTickLabel',[],...
                'YTickLabel',[],...
                'Box','on')
            %
            vMatlab = ver('matlab');
            vMatlab = eval(vMatlab.Version);
            if vMatlab>=9.7
                app.AxisGeometry.Interactions = [dataTipInteraction];
                app.AxisLogo.Interactions = [];
                app.AxisLogoMCAD.Interactions = [];
                set(app.AxisGeometry.Toolbar,'Visible','on')
                set(app.AxisLogo.Toolbar,'Visible','off')
                set(app.AxisLogoMCAD.Toolbar,'Visible','off')
            end


            % load default motor
            load mot_01.mat
            %             manual_dataSet;
            update_material_library([cd '\motorExamples\'],'mot_01.mat')
            [dataSet,geo,per,mat] = back_compatibility(dataSet,geo,per,0);
            dataSet.currentpathname = [cd '\motorExamples\'];
            dataSet.currentfilename = 'mot_01.mat';
            app.dataSet = dataSet;
            
            % update materials dropdowns
            mat = material_properties_conductor('0');
            app.StatorslotmaterialDropDown.Items = mat.MatList;
            
            mat = material_properties_layer('0');
            app.FluxbarriermaterialDropDown.Items = mat.MatList;
            
            mat = material_properties_iron('0');
            app.StatorcorematerialDropDown.Items = mat.MatList;
            app.RotorcorematerialDropDown.Items  = mat.MatList;
            app.ShaftmaterialDropDown.Items      = ['Air' mat.MatList];
            
            app = GUI_APP_DrawMachine(app);
            
            % update GUI fields according to the current motor
            GUI_APP_SetParameters(app);
            
            app.dataSet = dataSet;
        end

        % Button pushed function: AddConductorPush
        function AddConductorPush_Callback(app, event)
            
            answer = inputdlg('New Material Name','Conductor Material',1,{'New Conductor'});
            MatName = char(answer);
            mat=material_properties_conductor('');
            MatList=mat.MatList;
            flag=1;
            for ii=1:length(MatList)
                if strcmp(MatName,MatList{ii})
                    flag=0;
                end
            end
            if flag
                add_material_conductor(MatName);
            else
                disp('Material already present in the library')
            end
            
            % update materials dropdowns
            mat = material_properties_conductor('0');
            app.StatorslotmaterialDropDown.Items = mat.MatList;
        end

        % Button pushed function: AddIronPush
        function AddIronPush_Callback(app, event)
            % hObject    handle to AddIronPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            answer = inputdlg('New Material Name','Iron Material',1,{'New Iron'});
            MatName = char(answer);
            mat=material_properties_iron('');
            MatList=mat.MatList;
            flag=1;
            for ii=1:length(MatList)
                if strcmp(MatName,MatList{ii})
                    flag=0;
                end
            end
            if flag
                add_material_iron(MatName);
            else
                disp('Material already present in the library')
            end
            % update materials dropdowns
            mat = material_properties_iron('0');
            app.StatorcorematerialDropDown.Items = mat.MatList;
            app.RotorcorematerialDropDown.Items  = mat.MatList;
            app.ShaftmaterialDropDown.Items      = ['Air' mat.MatList];
            
        end

        % Button pushed function: AddMagnetPush
        function AddMagnetPush_Callback(app, event)
            % hObject    handle to AddMagnetPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            answer = inputdlg('New Material Name','Magnet Material',1,{'New Magnet'});
            MatName = char(answer);
            mat=material_properties_layer('');
            MatList=mat.MatList;
            flag=1;
            for ii=1:length(MatList)
                if strcmp(MatName,MatList{ii})
                    flag=0;
                end
            end
            if flag
                add_material_layer(MatName);
            else
                disp('Material already present in the library')
            end
            
            % update materials dropdowns
            mat = material_properties_layer('0');
            app.FluxbarriermaterialDropDown.Items = mat.MatList;
        end

        % Value changed function: AirGapRadiusEdit
        function AirGapRadiusEdit_Callback(app, event)
            % Airgap Radius [mm](geo.r)
            
            % hObject    handle to AirGapRadiusEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of AirGapRadiusEdit as text
            %        str2double(get(hObject,'String')) returns contents of AirGapRadiusEdit as a double
            dataSet = app.dataSet;
            dataSet.AirGapRadius = eval(app.AirGapRadiusEdit.Value);
            %             dataSet.ToothLength = dataSet.StatorOuterRadius * (1-dataSet.AirGapRadius/dataSet.StatorOuterRadius*(1+dataSet.MagLoadingYoke/dataSet.NumOfPolePairs));
            %             dataSet.ToothLength = round(dataSet.ToothLength*10000)/10000;
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: AirgapRadiusBouCheck
        function AirgapRadiusBouCheck_Callback(app, event)
            % Airgap radius [mm](bounds_xr)check box
            % hObject    handle to AirgapRadiusBouCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of AirgapRadiusBouCheck
            dataSet = app.dataSet;
            dataSet.AirgapRadiusBouCheck = app.AirgapRadiusBouCheck.Value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: AirgapRadiusBouEdit
        function AirgapRadiusBouEdit_Callback(app, event)
            % Airgap radius [mm](bounds_xr)
            % hObject    handle to AirgapRadiusBouEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of AirgapRadiusBouEdit as text
            %        str2double(get(hObject,'String')) returns contents of AirgapRadiusBouEdit as a double
            dataSet = app.dataSet;
            dataSet.GapRadiusBou = eval(app.AirgapRadiusBouEdit.Value);
            [bounds, objs, geo, per, mat] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: Alpha1BouEdit
        function Alpha1BouEdit_Callback(app, event)
            % first barrier airgap angle or dalpha 1 (bounds_dalpha_1)
            % hObject    handle to Alpha1BouEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of Alpha1BouEdit as text
            %        str2double(get(hObject,'String')) returns contents of Alpha1BouEdit as a double
            dataSet = app.dataSet;
            dataSet.Alpha1Bou = eval(app.Alpha1BouEdit.Value);
            [m,n] = size(dataSet.Alpha1Bou);
            if m*n > 2 || m*n == 1
                disp('Must be vector 2x1')
                return
            end
            [bounds, objs, geo, per, ~] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: AlphadegreeEdit
        function AlphadegreeEdit_Callback(app, event)
            % alpha [degrees]
            % hObject    handle to AlphadegreeEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of AlphadegreeEdit as text
            %        str2double(get(hObject,'String')) returns contents of AlphadegreeEdit as a double
            dataSet = app.dataSet;
            dataSet.AngleSpanOfPM = eval(app.AlphadegreeEdit.Value);
            if dataSet.AngleSpanOfPM>179.5
                dataSet.AngleSpanOfPM=179.5;
                disp('PM span limited to 179.5 elt deg')
            end
            app.dataSet = dataSet;
            
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: AlphapuEdit
        function AlphapuEdit_Callback(app, event)
            % alpha [p.u.]
            % hObject    handle to AlphapuEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hO bject,'String') returns contents of AlphapuEdit as text
            %        str2double(get(hObject,'String')) returns contents of AlphapuEdit as a double
            dataSet = app.dataSet;
            ALPHApu = eval(app.AlphapuEdit.Value);
            % the sum of pu angles is rescaled to one
            % alpha1 is not rescaled: only the angles from the second barrier onwards
            if sum(ALPHApu)>(1-0.05) % 0.05 is the angular space guaranteed for the spider
                if ALPHApu(1)>(1-0.05*(dataSet.NumOfLayers)) % max ALPHApu(1)=1-0.05-0.05*(nlay-1)
                    disp('Wrong value of first alpha_pu')
                    ALPHApu(1)=1-0.05*(dataSet.NumOfLayers);
                end
                ALPHApu(2:end)=ALPHApu(2:end)/sum(ALPHApu(2:end))*(1-0.05-ALPHApu(1));
                disp('Correct value of alpha_pu')
            end
            
            %Limitazione escursione minima di ALPHApu caso Vtype - rev.Gallo
            [~,~,geo,~,~] = data0(dataSet);
            if strcmp(geo.RotType,'Vtype')
                if ALPHApu < 0.25 %escursione minima ammessa caso multibarriera
                    disp ('Value not allowed of alpha_pu')
                    ALPHApu=0.25;
                    disp('Minimum value of alpha_pu is set')
                end
            end
            
            dataSet.ALPHApu=round(ALPHApu,3);
            app.AlphapuEdit.Value = mat2str(dataSet.ALPHApu);
            %             set(hObject,'String',mat2str(dataSet.ALPHApu));
            dataSet.Dalpha1BouCheck = 0;
            dataSet.DalphaBouCheck = 0;
            
            [bounds, objs, geo, per, mat] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Button pushed function: BarrierLibraryPush
        function BarrierLibraryPush_Callback(app, event)
            % hObject    handle to BarrierLibraryPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            mat = material_properties_layer(0);
            
            material=reshape(mat.MatList,numel(mat.MatList),1);
            
            app.MaterialListBox.Value = material;
        end

        % Value changed function: BetaEdit
        function BetaEdit_Callback(app, event)
            % hObject    handle to BetaEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of BetaEdit as text
            %        str2double(get(hObject,'String')) returns contents of BetaEdit as a double
            dataSet = app.dataSet;
            % dataSet.betaPMshape = str2double(get(hObject,'String'));
            beta = eval(app.BetaEdit.Value);
            
            beta(beta>1) = 1;
            beta(beta<0) = 0;
            dataSet.betaPMshape = beta;
            
            %             set(hObject,'String',mat2str(dataSet.betaPMshape));
            app.BetaEdit.Value = mat2str(dataSet.betaPMshape);
            %set(app.FluxBarMatEdit,'String',dataSet.FluxBarrierMaterial); %rev.Gallo
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: BetaPMshapeBouCheck
        function BetaPMshapeBouCheck_Callback(app, event)
            % hObject    handle to BetaPMshapeBouCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of BetaPMshapeBouCheck
            dataSet = app.dataSet;
            dataSet.BetaPMshapeBouCheck = app.BetaPMshapeBouCheck.Value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: BetaPMshapeBouEdit
        function BetaPMshapeBouEdit_Callback(app, event)
            % hObject    handle to BetaPMshapeBouEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of BetaPMshapeBouEdit as text
            %        str2double(get(hObject,'String')) returns contents of BetaPMshapeBouEdit as a double
            dataSet = app.dataSet;
            dataSet.BetaPMshapeBou = eval(app.BetaPMshapeBouEdit.Value);
            [m,n] = size(dataSet.BetaPMshapeBou);
            if m*n > 2 || m*n == 1
                disp('Must be vector 2x1')
                return
            end
            
            if ((max(dataSet.BetaPMshapeBou)>1)||(min(dataSet.BetaPMshapeBou)<0))
                dataSet.BetaPMshapeBou = [0.5 1];
            end
            
            % dataSet.BetaPMshapeBou(dataSet.BetaPMshapeBou>1) = 1;
            % dataSet.BetaPMshapeBou(dataSet.BetaPMshapeBou<0) = 0;
            
            [bounds, objs, geo, per, ~] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: BfeEdit
        function BfeEdit_Callback(app, event)
            % hObject    handle to BfeEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of BfeEdit as text
            %        str2double(get(hObject,'String')) returns contents of BfeEdit as a double
            dataSet = app.dataSet;
            dataSet.Bfe = eval(app.BfeEdit.Value);
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: BrBouCheck
        function BrBouCheck_Callback(app, event)
            % Br [T] (bounds_Br)
            % hObject    handle to BrBouCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hint: get(hObject,'Value') returns toggle state of BrBouCheck
            dataSet = app.dataSet;
            dataSet.BrBouCheck = app.BrBouCheck.Value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: BrBouEdit
        function BrBouEdit_Callback(app, event)
            % Br [T](bounds_Br)
            % hObject    handle to BrBouEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of BrBouEdit as text
            %        str2double(get(hObject,'String')) returns contents of BrBouEdit as a double
            dataSet = app.dataSet;
            dataSet.BrBou = eval(app.BrBouEdit.Value);
            [bounds, objs, geo, per, mat] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: BrPMEdit
        function BrPMEdit_Callback(app, event)
            % Br for PMs
            
            % hObject    handle to BrPMEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of BrPMEdit as text
            %        str2double(get(hObject,'String')) returns contents of BrPMEdit as a double
            dataSet = app.dataSet;
            dataSet.Br = eval(app.BrPMEdit.Value);
            dataSet.BrPP = dataSet.Br;  % update Br post proc when Br is changed
            dataSet.BrBouCheck = 0;
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: BrPPEdit
        function BrPPEdit_Callback(app, event)
            % Br [T]
            
            % hObject    handle to BrPPEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of BrPPEdit as text
            %        str2double(get(hObject,'String')) returns contents of BrPPEdit as a double
            
            dataSet = app.dataSet;
            dataSet.BrPP = eval(app.BrPPEdit.Value);
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: CarCurEdit
        function CarCurEdit_Callback(app, event)
            % hObject    handle to CarCurEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of CarCurEdit as text
            %        str2double(get(hObject,'String')) returns contents of CarCurEdit as a double
            dataSet = app.dataSet;
            dataSet.CurrPM = eval(app.CarCurEdit.Value);
            
            app.dataSet = dataSet;
            %app = GUI_APP_DrawMachine(app);
            % % guidata(hObject,handles)
        end

        % Button pushed function: ClearCachePush
        function ClearCachePush_Callback(app, event)
            % Empty \tmp folder pushbutton
            % hObject    handle to ClearCachePush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
                rmdir([cd,'\tmp'],'s');
                msgbox('directory empty');
            catch
                msgbox('directory empty');
            end
            if exist([cd,'\tmp'],'dir') == 0
                mkdir([cd,'\tmp']);
            end
            % % guidata(hObject,handles)
        end

        % Value changed function: ConductorHeightEdit
        function ConductorHeightEdit_Callback(app, event)
            % hObject    handle to ConductorHeightEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ConductorHeightEdit as text
            %        str2double(get(hObject,'String')) returns contents of ConductorHeightEdit as a double
            dataSet = app.dataSet;
            dataSet.SlotConductorHeight = eval(app.ConductorHeightEdit.Value);
            if dataSet.SlotConductorHeight<0
                dataSet.SlotConductorHeight=-dataSet.SlotConductorHeight;
            end
            app.dataSet = dataSet;
            % app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ConductorInsulationEdit
        function ConductorInsulationEdit_Callback(app, event)
            % hObject    handle to ConductorInsulationEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ConductorInsulationEdit as text
            %        str2double(get(hObject,'String')) returns contents of ConductorInsulationEdit as a double
            dataSet = app.dataSet;
            dataSet.SlotConductorInsulation = eval(app.ConductorInsulationEdit.Value);
            if dataSet.SlotConductorInsulation<0
                dataSet.SlotConductorInsulation=0.1;
            end
            app.dataSet = dataSet;
            % app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Button pushed function: ConductorLibraryPush
        function ConductorLibraryPush_Callback(app, event)
            % hObject    handle to ConductorLibraryPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            mat = material_properties_conductor(0);
            
            material=reshape(mat.MatList,numel(mat.MatList),1);
            
            app.MaterialListBox.Value = material;
        end

        % Value changed function: ConductorNumberEdit
        function ConductorNumberEdit_Callback(app, event)
            % hObject    handle to ConductorNumberEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ConductorNumberEdit as text
            %        str2double(get(hObject,'String')) returns contents of ConductorNumberEdit as a double
            dataSet = app.dataSet;
            dataSet.SlotConductorNumber = eval(app.ConductorNumberEdit.Value);
            if dataSet.SlotConductorNumber<0
                dataSet.SlotConductorNumber=dataSet.TurnsInSeries/dataSet.NumOfPolePairs/(dataSet.NumOfSlots);
            end
            app.dataSet = dataSet;
            % app = GUI_APP_DrawMachine(app);
            GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ConductorRadiusEdit
        function ConductorRadiusEdit_Callback(app, event)
            % hObject    handle to ConductorRadiusEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ConductorRadiusEdit as text
            %        str2double(get(hObject,'String')) returns contents of ConductorRadiusEdit as a double
            dataSet = app.dataSet;
            dataSet.SlotConductorRadius = eval(app.ConductorRadiusEdit.Value);
            if dataSet.SlotConductorRadius<0
                dataSet.SlotConductorRadius=-dataSet.SlotConductorRadius;
            end
            app.dataSet = dataSet;
            % app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Callback function
        function ConductorShapeEdit_Callback(app, event)
            % hObject    handle to ConductorShapeEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ConductorShapeEdit as text
            %        str2double(get(hObject,'String')) returns contents of ConductorShapeEdit as a double
            dataSet = app.dataSet;
            dataSet.SlotConductorShape = eval(app.ConductorShapeEdit.Value);
            app.dataSet = dataSet;
            % app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ConductorTypeEdit
        function ConductorTypeEdit_Callback(app, event)
            % hObject    handle to ConductorTypeEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns ConductorTypeEdit contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from ConductorTypeEdit
            dataSet = app.dataSet;
            %             ind = get(hObject,'Value');
            %             str = get(hObject,'String');
            dataSet.SlotConductorType = app.ConductorTypeEdit.Value;
            app.dataSet = dataSet;
            % % guidata(hObject,handles)
        end

        % Value changed function: ConductorWidthEdit
        function ConductorWidthEdit_Callback(app, event)
            % hObject    handle to ConductorWidthEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ConductorWidthEdit as text
            %        str2double(get(hObject,'String')) returns contents of ConductorWidthEdit as a double
            dataSet = app.dataSet;
            dataSet.SlotConductorWidth = eval(app.ConductorWidthEdit.Value);
            if dataSet.SlotConductorWidth<0
                dataSet.SlotConductorWidth=-dataSet.SlotConductorWidth;
            end
            app.dataSet = dataSet;
            % app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: CopperTempEdit
        function CopperTempEdit_Callback(app, event)
            % Target Copper Temperature
            
            % hObject    handle to CopperTempEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of CopperTempEdit as text
            %        str2double(get(hObject,'String')) returns contents of CopperTempEdit as a double
            dataSet = app.dataSet;
            dataSet.TargetCopperTemp = eval(app.CopperTempEdit.Value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: CurrLoPPEdit
        function CurrLoPPEdit_Callback(app, event)
            % Current load [p.u.]
            % hObject    handle to CurrLoPPEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of CurrLoPPEdit as text
            %        str2double(get(hObject,'String')) returns contents of CurrLoPPEdit as a double
            
            dataSet = app.dataSet;
            dataSet.CurrLoPP = eval(app.CurrLoPPEdit.Value);
            dataSet.SimulatedCurrent = dataSet.CurrLoPP*dataSet.RatedCurrent;
            
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app)
            % % guidata(hObject,handles)
        end

        % Value changed function: CurrLoXBEdit
        function CurrLoXBEdit_Callback(app, event)
            
            dataSet = app.dataSet;
            dataSet.CurrLoPP = eval(app.CurrLoXBEdit.Value);
            
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
            
        end

        % Value changed function: CurrentOverLoadEdit
        function CurrentOverLoadEdit_Callback(app, event)
            % current overload factor used for optimization (per overload)
            
            % hObject    handle to CurrentOverLoadEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of CurrentOverLoadEdit as text
            %        str2double(get(hObject,'String')) returns contents of CurrentOverLoadEdit as a double
            dataSet = app.dataSet;
            dataSet.CurrOverLoad = eval(app.CurrentOverLoadEdit.Value);
            app.dataSet = dataSet;
            % % guidata(hObject,handles)
        end

        % Value changed function: Dalpha1BouCheck
        function Dalpha1BouCheck_Callback(app, event)
            % 1st barrier airgap angle [p.u.](bounds_dalpha_1)check box
            
            % hObject    handle to Dalpha1BouCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of Dalpha1BouCheck
            dataSet = app.dataSet;
            dataSet.Dalpha1BouCheck = app.Dalpha1BouCheck.Value;
            if dataSet.Dalpha1BouCheck ==0
                dataSet.DalphaBouCheck = 0;
            else
                dataSet.DalphaBouCheck = 1;
            end
            [bounds, objs, geo, per, mat] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: DalphaBouCheck
        function DalphaBouCheck_Callback(app, event)
            % Other barriers angles [p.u.](bounds_dalpha)
            % hObject    handle to DalphaBouCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of DalphaBouCheck
            dataSet = app.dataSet;
            dataSet.DalphaBouCheck = app.DalphaBouCheck.Value;
            if dataSet.DalphaBouCheck ==0
                dataSet.Dalpha1BouCheck = 0;
            else
                dataSet.Dalpha1BouCheck = 1;
            end
            % set(app.Dalpha1BouCheck,'Value',dataSet.Dalpha1BouCheck);
            [bounds, objs, geo, per, mat] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: DeltaAlphaBouEdit
        function DeltaAlphaBouEdit_Callback(app, event)
            % Other barriers angles [p.u.](bounds_dalpha)
            % hObject    handle to DeltaAlphaBouEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of DeltaAlphaBouEdit as text
            %        str2double(get(hObject,'String')) returns contents of DeltaAlphaBouEdit as a double
            dataSet = app.dataSet;
            dataSet.DeltaAlphaBou = eval(app.DeltaAlphaBouEdit.Value);
            [m,n] = size(dataSet.DeltaAlphaBou);
            if m*n > 2 || m*n == 1
                disp('Must be vector 2x1')
                return
            end
            [bounds, objs, geo, per, ~] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: DfeBouEdit
        function DfeBouEdit_Callback(app, event)
            % dx [p.u.](bounds_Dx)
            
            % hObject    handle to DfeBouEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of DfeBouEdit as text
            %        str2double(get(hObject,'String')) returns contents of DfeBouEdit as a double
            dataSet = app.dataSet;
            dataSet.DfeBou = eval(app.DfeBouEdit.Value);
            [m,n] = size(dataSet.DfeBou);
            if m*n > 2 || m*n == 1
                disp('Must be vector 2x1')
                return
            end
            [bounds, objs, geo, per, ~] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Button pushed function: DrawSlotModelPush
        function DrawSlotModelPush_Callback(app, event)
            % hObject    handle to DrawSlotModelPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            dataSet = app.dataSet;
            dataSet = skinEffect_draw(dataSet);
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: DxBouCheck
        function DxBouCheck_Callback(app, event)
            % dx [p.u.](bounds_Dx)
            % hObject    handle to DxBouCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of DxBouCheck
            dataSet = app.dataSet;
            dataSet.DxBouCheck = app.DxBouCheck.Value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: DxEdit
        function DxEdit_Callback(app, event)
            % hObject    handle to DxEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of DxEdit as text
            %        str2double(get(hObject,'String')) returns contents of DxEdit as a double
            dataSet = app.dataSet;
            dataSet.DepthOfBarrier = eval(app.DxEdit.Value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Callback function
        function EddyCurLossFactorEdit_Callback(app, event)
            % hObject    handle to EddyCurLossFactorEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of EddyCurLossFactorEdit as text
            %        str2double(get(hObject,'String')) returns contents of EddyCurLossFactorEdit as a double
            dataSet = app.dataSet;
            dataSet.EddyCurLossFactor = str2double(get(hObject,'String'));
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Button pushed function: EvalSlotModelPush
        function EvalSlotModelPush_Callback(app, event)
            % hObject    handle to EvalSlotModelPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            dataSet = app.dataSet;
            skinEffect_eval(dataSet);
            % guidata(hObject,handles)
        end

        % Value changed function: EvalTypePopUp
        function EvalTypePopUp_Callback(app, event)
            % hObject    handle to EvalTypePopUp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns EvalTypePopUp contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from EvalTypePopUp
            dataSet = app.dataSet;
            tmp     = app.EvalTypePopUp.Value;
            switch tmp
                case 'Single Point'
                    dataSet.EvalType = 'singt';
                    if length(dataSet.BrPP)>1
                        mat=material_properties_layer(dataSet.FluxBarrierMaterial);
                        if ~isfield(mat,'temp')
                            dataSet.tempPP=20;
                            dataSet.BrPP=mat.Br;
                        else
                            dataSet.BrPP   = mat.Br;
                            dataSet.tempPP=interp1(mat.temp.Br,mat.temp.temp,dataSet.BrPP);
                        end
                    end
                case 'Flux Map'
                    dataSet.EvalType = 'singm';
                    if length(dataSet.BrPP)>1
                        mat=material_properties_layer(dataSet.FluxBarrierMaterial);
                        if ~isfield(mat,'temp')
                            dataSet.tempPP=20;
                            dataSet.BrPP=mat.Br;
                        else
                            dataSet.BrPP   = mat.Br;
                            dataSet.tempPP=interp1(mat.temp.Br,mat.temp.temp,dataSet.BrPP);
                        end
                    end
                    
                    dataSet.CurrLoPP = dataSet.CurrLoPP(1);
                    dataSet.SimulatedCurrent = dataSet.RatedCurrent*dataSet.CurrLoPP;
                    
                case 'Demagnetization Analysis'
                    dataSet.EvalType = 'demagArea';
                    mat = material_properties_layer(dataSet.FluxBarrierMaterial);
                    if ~isfield(mat,'temp')
                        warning('The selected PM has no demag properties. Change or update material!!!')
                    else
                        dataSet.tempPP = mat.temp.temp(1);
                        dataSet.BrPP   = mat.temp.Br(1);
                    end
                case 'Demagnetization Curve'
                    dataSet.EvalType = 'idemag';
                    mat = material_properties_layer(dataSet.FluxBarrierMaterial);
                    if ~isfield(mat,'temp')
                        warning('The selected PM has no demag properties. Change or update material!!!')
                    else
                        dataSet.tempPP = mat.temp.temp;
                        dataSet.BrPP   = mat.temp.Br;
                    end
                case 'Characteristic Current'
                    dataSet.EvalType = 'ichval';
                    mat = material_properties_layer(dataSet.FluxBarrierMaterial);
                    if ~isfield(mat,'temp')
                        warning('The selected PM has no demag properties. Change or update material!!!')
                    else
                        dataSet.tempPP = mat.temp.temp;
                        dataSet.BrPP   = mat.temp.Br;
                    end
                case 'Flux Density Analysis'
                    dataSet.EvalType = 'flxdn';
                    if length(dataSet.BrPP)>1
                        mat=material_properties_layer(dataSet.FluxBarrierMaterial);
                        if ~isfield(mat,'temp')
                            dataSet.tempPP=20;
                            dataSet.BrPP=mat.Br;
                        else
                            dataSet.BrPP   = mat.Br;
                            dataSet.tempPP=interp1(mat.temp.Br,mat.temp.temp,dataSet.BrPP);
                        end
                    end
                case 'Current Offset'
                    dataSet.EvalType = 'izero';
                    if length(dataSet.BrPP)>1
                        mat=material_properties_layer(dataSet.FluxBarrierMaterial);
                        if ~isfield(mat,'temp')
                            dataSet.tempPP=20;
                            dataSet.BrPP=mat.Br;
                        else
                            dataSet.BrPP   = mat.Br;
                            dataSet.tempPP=interp1(mat.temp.Br,mat.temp.temp,dataSet.BrPP);
                        end
                    end
                case 'Airgap Force'
                    dataSet.EvalType = 'force';
                    if length(dataSet.BrPP)>1
                        mat=material_properties_layer(dataSet.FluxBarrierMaterial);
                        if ~isfield(mat,'temp')
                            dataSet.tempPP=20;
                            dataSet.BrPP=mat.Br;
                        else
                            dataSet.BrPP   = mat.Br;
                            dataSet.tempPP=interp1(mat.temp.Br,mat.temp.temp,dataSet.BrPP);
                        end
                    end
                case 'Iron Loss - Single Point'
                    dataSet.EvalType = 'singtIron';
                    if length(dataSet.BrPP)>1
                        mat=material_properties_layer(dataSet.FluxBarrierMaterial);
                        if ~isfield(mat,'temp')
                            dataSet.tempPP=20;
                            dataSet.BrPP=mat.Br;
                        else
                            dataSet.BrPP   = mat.Br;
                            dataSet.tempPP=interp1(mat.temp.Br,mat.temp.temp,dataSet.BrPP);
                        end
                    end
                    if dataSet.EvalSpeed==0
                        dataSet.EvalSpeed = 3000/dataSet.NumOfPolePairs;
                        disp('EvalSpeed must be >0!!!')
                    elseif dataSet.EvalSpeed<0
                        dataSet.EvalSpeed = -dataSet.EvalSpeed;
                    end
                    dataSet.AngularSpanPP = 360;
                case 'Iron Loss - Flux Map'
                    dataSet.EvalType = 'singmIron';
                    if length(dataSet.BrPP)>1
                        mat=material_properties_layer(dataSet.FluxBarrierMaterial);
                        if ~isfield(mat,'temp')
                            dataSet.tempPP=20;
                            dataSet.BrPP=mat.Br;
                        else
                            dataSet.BrPP   = mat.Br;
                            dataSet.tempPP=interp1(mat.temp.Br,mat.temp.temp,dataSet.BrPP);
                        end
                    end
                    if dataSet.EvalSpeed==0
                        dataSet.EvalSpeed = 3000/dataSet.NumOfPolePairs;
                        disp('EvalSpeed must be >0!!!')
                    elseif dataSet.EvalSpeed<0
                        dataSet.EvalSpeed = -dataSet.EvalSpeed;
                    end
                    dataSet.AngularSpanPP = 360;
                    
                    dataSet.CurrLoPP = dataSet.CurrLoPP(1);
                    
                    if ~strcmp(dataSet.FluxBarrierMaterial,'Air')
                        if strcmp(dataSet.TypeOfRotor,'Vtype')||strcmp(dataSet.TypeOfRotor,'SPM')
                            dataSet.CurrLoPP = dataSet.CurrLoPP*[-1 1 0 1];
                        else
                            dataSet.CurrLoPP = dataSet.CurrLoPP*[0 1 -1 1];
                        end
                    end
                    dataSet.SimulatedCurrent = dataSet.RatedCurrent*dataSet.CurrLoPP;
                    
                case 'Structural Analysis'
                    dataSet.EvalType = 'structural';
                    if dataSet.EvalSpeed==0
                        dataSet.EvalSpeed = 3000/dataSet.NumOfPolePairs;
                        disp('EvalSpeed must be >0!!!')
                    elseif dataSet.EvalSpeed<0
                        dataSet.EvalSpeed = -dataSet.EvalSpeed;
                    end
                case 'HWC Short-Circuit Current'
                    dataSet.EvalType = 'shortCircuit';
                    if length(dataSet.BrPP)>1
                        mat=material_properties_layer(dataSet.FluxBarrierMaterial);
                        if ~isfield(mat,'temp')
                            dataSet.tempPP=20;
                            dataSet.BrPP=mat.Br;
                        else
                            dataSet.BrPP   = mat.Br;
                            dataSet.tempPP=interp1(mat.temp.Br,mat.temp.temp,dataSet.BrPP);
                        end
                    end
                    
            end
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app)
            % guidata(hObject,handles)
        end

        % Value changed function: EvaluatedSpeedEdit
        function EvaluatedSpeedEdit_Callback(app, event)
            % hObject    handle to EvaluatedSpeedEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of EvaluatedSpeedEdit as text
            %        str2double(get(hObject,'String')) returns contents of EvaluatedSpeedEdit as a double
            dataSet = app.dataSet;
            dataSet.EvalSpeed = eval(app.EvaluatedSpeedEdit.Value);
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: FEAfixPopUp
        function FEAfixPopUp_Callback(app, event)
            % hObject    handle to FEAfixPopUp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns FEAfixPopUp contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from FEAfixPopUp
            dataSet = app.dataSet;
            %             ind = get(hObject,'Value');
            %             str = get(hObject,'String');
            %             dataSet.FEAfixN = eval(str{ind});
            dataSet.FEAfixN = eval(app.FEAfixPopUp.Value);
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Button pushed function: FEAfixPushButt
        function FEAfixPushButt_Callback(app, event)
            % hObject    handle to FEAfixPushButt (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            dataSet = app.dataSet;
            [dataSet,flagS] = syrmDesign(dataSet);
            app.dataSet = dataSet;
            if flagS
                dataSet = DrawAndSaveMachine(dataSet,dataSet.currentfilename,dataSet.currentpathname);
            end
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app)
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: FillCorSlotEdit
        function FillCorSlotEdit_Callback(app, event)
            % Fillet at the back iron corner of the slot[mm](geo.SFR)
            
            % hObject    handle to FillCorSlotEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of FillCorSlotEdit as text
            %        str2double(get(hObject,'String')) returns contents of FillCorSlotEdit as a double
            dataSet = app.dataSet;
            dataSet.FilletCorner = eval(app.FillCorSlotEdit.Value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Callback function
        function FluxBarMatEdit_Callback(app, event)
            % flux barrier material (geo.BLKLABELSmaterials)
            
            % hObject    handle to FluxBarMatEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of FluxBarMatEdit as text
            %        str2double(get(hObject,'String')) returns contents of FluxBarMatEdit as a double
            dataSet = app.dataSet;
            dataSet.FluxBarrierMaterial = app.FluxBarMatEdit.Value;
            mat = material_properties_layer(dataSet.FluxBarrierMaterial);
            dataSet.Br = mat.Br;
            dataSet.BrPP = dataSet.Br;
            if ~strcmp(dataSet.FluxBarrierMaterial,'Air')
                if (strcmp(dataSet.TypeOfRotor,'Seg')||strcmp(dataSet.TypeOfRotor,'ISeg'))
                    dataSet.PMdim = Inf(2,dataSet.NumOfLayers);
                    dataSet.PMdim(2,1)=0;
                elseif (strcmp(dataSet.TypeOfRotor,'Circular')||strcmp(dataSet.TypeOfRotor,'Vtype'))
                    dataSet.PMdim = [Inf(1,dataSet.NumOfLayers);zeros(1,dataSet.NumOfLayers)];
                end
            else
                dataSet.PMdim = zeros(2,dataSet.NumOfLayers);
            end
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: GammaBouCheck
        function GammaBouCheck_Callback(app, event)
            % Current phase angle[elect. deg.](bounds_gamma)
            % hObject    handle to GammaBouCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of GammaBouCheck
            dataSet = app.dataSet;
            dataSet.GammaBouCheck = app.GammaBouCheck.Value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: GammaPPEdit
        function GammaPPEdit_Callback(app, event)
            % Current phase angle[elect. deg.]
            
            % hObject    handle to GammaPPEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of GammaPPEdit as text
            %        str2double(get(hObject,'String')) returns contents of GammaPPEdit as a double
            
            dataSet = app.dataSet;
            dataSet.GammaPP = eval(app.GammaPPEdit.Value);
%             if strcmp(dataSet.AxisType,'PM')
%                 dataSet.GammaPP = dataSet.GammaPP + 90;
%             end
            app.dataSet = dataSet;
            %GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: GapBouCheck
        function GapBouCheck_Callback(app, event)
            % Airgap thickness[mm](bounds_g)
            % hObject    handle to GapBouCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hint: get(hObject,'Value') returns toggle state of GapBouCheck
            dataSet = app.dataSet;
            dataSet.GapBouCheck = app.GapBouCheck.Value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: GapBouEdit
        function GapBouEdit_Callback(app, event)
            % Airgap thickness[mm](bounds_g)
            
            % hObject    handle to GapBouEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of GapBouEdit as text
            %        str2double(get(hObject,'String')) returns contents of GapBouEdit as a double
            dataSet = app.dataSet;
            dataSet.GapBou = eval(app.GapBouEdit.Value);
            [bounds, objs, geo, per, mat] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: GapThiEdit
        function GapThiEdit_Callback(app, event)
            % Airgap Thickness[mm](geo.g)
            % hObject    handle to GapThiEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of GapThiEdit as text
            %        str2double(get(hObject,'String')) returns contents of GapThiEdit as a double
            dataSet = app.dataSet;
            dataSet.AirGapThickness = eval(app.GapThiEdit.Value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: HousingTempEdit
        function HousingTempEdit_Callback(app, event)
            % hObject    handle to HousingTempEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of HousingTempEdit as text
            %        str2double(get(hObject,'String')) returns contents of HousingTempEdit as a double
            dataSet = app.dataSet;
            dataSet.HousingTemp = eval(app.HousingTempEdit.Value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Callback function
        function HysteresisFluxDenEdit_Callback(app, event)
            % hObject    handle to HysteresisFluxDenEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of HysteresisFluxDenEdit as text
            %        str2double(get(hObject,'String')) returns contents of HysteresisFluxDenEdit as a double
            dataSet = app.dataSet;
            dataSet.HysteresisFluxDenFactor = str2double(get(hObject,'String'));
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Callback function
        function HysteresisFrequencyFactorEdit_Callback(app, event)
            % hObject    handle to HysteresisFrequencyFactorEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of HysteresisFrequencyFactorEdit as text
            %        str2double(get(hObject,'String')) returns contents of HysteresisFrequencyFactorEdit as a double
            dataSet = app.dataSet;
            dataSet.HysteresisFrequencyFactor = str2double(get(hObject,'String'));
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Callback function
        function HysteresisLossFactorEdit_Callback(app, event)
            % hObject    handle to HysteresisLossFactorEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of HysteresisLossFactorEdit as text
            %        str2double(get(hObject,'String')) returns contents of HysteresisLossFactorEdit as a double
            dataSet = app.dataSet;
            dataSet.HysteresisLossFactor = str2double(get(hObject,'String'));
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Button pushed function: IronLibraryPush
        function IronLibraryPush_Callback(app, event)
            % hObject    handle to IronLibraryPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            mat = material_properties_iron(0);
            
            material=reshape(mat.MatList,numel(mat.MatList),1);
            app.MaterialListBox.Value = material;
            %             set(app.MaterialText,'Style','Edit');
            %             set(app.MaterialText,'String',material);
        end

        % Callback function
        function LossEvaluationCheck_Callback(app, event)
            % hObject    handle to LossEvaluationCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of LossEvaluationCheck
            dataSet = app.dataSet;
            dataSet.LossEvaluationCheck = get(hObject,'Value');
            if dataSet.LossEvaluationCheck ==0
                set(app.HysteresisLossFactorEdit,'Enable','off');
                set(app.HysteresisFrequencyFactorEdit,'Enable','off');
                set(app.HysteresisFluxDenEdit,'Enable','off');
                set(app.EddyCurLossFactorEdit,'Enable','off');
                set(app.MassDensityEdit,'Enable','off');
                set(app.EvaluatedSpeedEdit,'Enable','on');
            else
                [~, ~, ~, ~, mat] = data0(dataSet);
                set(app.HysteresisLossFactorEdit,'Enable','on');
                set(app.HysteresisFrequencyFactorEdit,'Enable','on');
                set(app.HysteresisFluxDenEdit,'Enable','on');
                set(app.EddyCurLossFactorEdit,'Enable','on');
                set(app.MassDensityEdit,'Enable','on');
                set(app.EvaluatedSpeedEdit,'Enable','on');
                dataSet.HysteresisLossFactor = mat.Rotor.kh;
                dataSet.HysteresisFrequencyFactor = mat.Rotor.alpha;
                dataSet.HysteresisFluxDenFactor = mat.Rotor.beta;
                dataSet.EddyCurLossFactorEdit = mat.Rotor.ke;
                dataSet.IronMassDen = mat.Rotor.kgm3;
                set(app.HysteresisLossFactorEdit,'String',mat2str(dataSet.HysteresisLossFactor));
                set(app.HysteresisFrequencyFactorEdit,'String',mat2str(dataSet.HysteresisFrequencyFactor));
                set(app.HysteresisFluxDenEdit,'String',mat2str(dataSet.HysteresisFluxDenFactor));
                set(app.EddyCurLossFactorEdit,'String',mat2str(dataSet.EddyCurLossFactorEdit));
                set(app.MassDensityEdit,'String',mat2str(dataSet.IronMassDen));
                app.dataSet = dataSet;
                dataSet = DrawPushMachine(handles,dataSet.currentfilename,dataSet.currentpathname);
            end
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: MassCuEdit
        function MassCuEdit_Callback(app, event)
            % hObject    handle to MassCuEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of MassCuEdit as text
            %        str2double(get(hObject,'String')) returns contents of MassCuEdit as a double
            
            dataSet = app.dataSet;
            dataSet.MaxCuMass = eval(app.MassCuEdit.Value);
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: MassCuOptCheck
        function MassCuOptCheck_Callback(app, event)
            % hObject    handle to MassCuOptCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of MassCuOptCheck
            
            dataSet = app.dataSet;
            dataSet.MassCuOptCheck = app.MassCuOptCheck.Value;
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Callback function
        function MassDensityEdit_Callback(app, event)
            % hObject    handle to MassDensityEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of MassDensityEdit as text
            %        str2double(get(hObject,'String')) returns contents of MassDensityEdit as a double
            dataSet = app.dataSet;
            dataSet.IronMassDen = str2double(get(hObject,'String'));
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: MassPMEdit
        function MassPMEdit_Callback(app, event)
            % hObject    handle to MassPMEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of MassPMEdit as text
            %        str2double(get(hObject,'String')) returns contents of MassPMEdit as a double
            dataSet = app.dataSet;
            dataSet.MaxPMMass = eval(app.MassPMEdit.Value);
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: MassPMOptCheck
        function MassPMOptCheck_Callback(app, event)
            % hObject    handle to MassPMOptCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of MassPMOptCheck
            dataSet = app.dataSet;
            dataSet.MassPMOptCheck = app.MassPMOptCheck.Value;
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: MaxExpeRippleTorEdit
        function MaxExpeRippleTorEdit_Callback(app, event)
            % Maximum expected torque ripple during optimization [p.u.](per.max_exp_ripple)
            % hObject    handle to MaxExpeRippleTorEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of MaxExpeRippleTorEdit as text
            %        str2double(get(hObject,'String')) returns contents of MaxExpeRippleTorEdit as a double
            dataSet = app.dataSet;
            dataSet.MaxRippleTorque = eval(app.MaxExpeRippleTorEdit.Value);
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: MaxGenEdit
        function MaxGenEdit_Callback(app, event)
            % Maximum number of generations
            % hObject    handle to MaxGenEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of MaxGenEdit as text
            %        str2double(get(hObject,'String')) returns contents of MaxGenEdit as a double
            dataSet = app.dataSet;
            dataSet.MaxGen = eval(app.MaxGenEdit.Value);
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: MecTolerEdit
        function MecTolerEdit_Callback(app, event)
            % minimum mechanical tolerance (geo.pont0)
            
            % hObject    handle to MecTolerEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of MecTolerEdit as text
            %        str2double(get(hObject,'String')) returns contents of MecTolerEdit as a double
            dataSet = app.dataSet;
            dataSet.MinMechTol = str2double(app.MecTolerEdit.Value);
            dataSet.TanRibEdit(dataSet.TanRibEdit<dataSet.MinMechTol)=dataSet.MinMechTol;
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: MeshEdit
        function MeshEdit_Callback(app, event)
            % mesh (geo.K_mesh)
            
            % hObject    handle to MeshEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of MeshEdit as text
            %        str2double(get(hObject,'String')) returns contents of MeshEdit as a double
            dataSet = app.dataSet;
            dataSet.Mesh = eval(app.MeshEdit.Value);
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: MeshMOOAEdit
        function MeshMOOAEdit_Callback(app, event)
            % Mesh MOOA (geo.mesh_K_MOOA)
            
            % hObject    handle to MeshMOOAEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of MeshMOOAEdit as text
            %        str2double(get(hObject,'String')) returns contents of MeshMOOAEdit as a double
            dataSet = app.dataSet;
            dataSet.Mesh_MOOA = eval(app.MeshMOOAEdit.Value);
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: MinExpTorEdit
        function MinExpTorEdit_Callback(app, event)
            % Minimum expected torque (per.min_exp_torque)
            % hObject    handle to MinExpTorEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of MinExpTorEdit as text
            %        str2double(get(hObject,'String')) returns contents of MinExpTorEdit as a double
            dataSet = app.dataSet;
            dataSet.MinExpTorque = eval(app.MinExpTorEdit.Value);
            if dataSet.MinExpTorque>0
                disp('Torque limit penalization must be negative for optimization settings!!!')
                beep
                dataSet.MinExpTorque = -dataSet.MinExpTorque;
            end
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: NGridPPEdit
        function NGridPPEdit_Callback(app, event)
            % Number of points in [0 Imax] (n_grid)
            % hObject    handle to NGridPPEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of NGridPPEdit as text
            %        str2double(get(hObject,'String')) returns contents of NGridPPEdit as a double
            dataSet = app.dataSet;
            dataSet.NumGrid = eval(app.NGridPPEdit.Value);
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: Num3PhaseCircuitEdit
        function Num3PhaseCircuitEdit_Callback(app, event)
            % hObject    handle to Num3PhaseCircuitEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            dataSet = app.dataSet;
            n3ph = eval(app.Num3PhaseCircuitEdit.Value);
            dataSet.Num3PhaseCircuit = n3ph;
            if n3ph>1
                dataSet.PitchShortFac=1;
            end
            dataSet.thetaFBS=0;
            
            p = dataSet.NumOfPolePairs;
            Q = round(dataSet.NumOfSlots*6*p*n3ph);
            Windings = WindingDefinition(dataSet);
            t2 = gcd(Q,2*p);
            Qs = Q/t2;
            dataSet.Qs = Qs;
            dataSet.WinMatr = Windings(:,1:floor(Qs)); % winding matrix, only Qs columns
            
            
            dataSet.WinFlag=ones(1,(n3ph*3));
            dataSet.Num3PhaseCircuit=n3ph;
            
            app.dataSet=dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: NumOfRotorPosiPPEdit
        function NumOfRotorPosiPPEdit_Callback(app, event)
            % Number of rotor positions
            % hObject    handle to NumOfRotorPosiPPEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of NumOfRotorPosiPPEdit as text
            %        str2double(get(hObject,'String')) returns contents of NumOfRotorPosiPPEdit as a double
            dataSet = app.dataSet;
            dataSet.NumOfRotPosPP = eval(app.NumOfRotorPosiPPEdit.Value);
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: NumOfSlotsEdit
        function NumOfSlotsEdit_Callback(app, event)
            % Number of slots/(poles*phases) (geo.q)
            
            % hObject    handle to NumOfSlotsEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of NumOfSlotsEdit as text
            %        str2double(get(hObject,'String')) returns contents of NumOfSlotsEdit as a double
            dataSet = app.dataSet;
            dataSet.NumOfSlots = eval(app.NumOfSlotsEdit.Value);
            p = dataSet.NumOfPolePairs;
            n3ph = dataSet.Num3PhaseCircuit;
            Q = round(dataSet.NumOfSlots*6*p*n3ph);
            %             yq = dataSet.PitchShortFac*dataSet.NumOfSlots*3*n3ph;
            %             path = pwd;
            %             cd(fullfile (path,'koil'));
            %             system(['koil_syre.exe',' ',num2str(Q),' ',num2str(p),' ',num2str(yq)]);
            %             cd(path);
            %             Windings = MatrixWin();
            Windings = WindingDefinition(dataSet);
            t2 = gcd(Q,2*p);
            Qs = Q/t2;
            dataSet.Qs = Qs;
            dataSet.WinMatr = Windings(:,1:floor(Qs)); % winding matrix, only Qs columns
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            
        end

        % Value changed function: NumberOfLayersEdit
        function NumberOfLayersEdit_Callback(app, event)
            % Number of rotor barriers(geo.nlay)
            
            % hObject    handle to NumberOfLayersEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of NumberOfLayersEdit as text
            %        str2double(get(hObject,'String')) returns contents of NumberOfLayersEdit as a double
            dataSet = app.dataSet;
            dataSet.NumOfLayers = eval(app.NumberOfLayersEdit.Value);
            [bounds, objs, geo, per, mat] = data0(dataSet);
            data = buildDefaultRQ(bounds);
            dataSet.RQ = data;
            if(dataSet.DalphaBouCheck)
                last_index = dataSet.NumOfLayers;
                dataSet.ALPHApu = data(1:last_index);
            else
                last_index = 0;
                dataSet.ALPHApu = ones(1,dataSet.NumOfLayers)*round(1/(dataSet.NumOfLayers+0.5)*100)/100;
                if exist('calc_nr.m','file')
                    out = calc_nr(6*dataSet.Num3PhaseCircuit*dataSet.NumOfSlots);
                    index = 1:1:length(out.nlay);
                    ii = index(out.nlay==dataSet.NumOfLayers);
                    if ~isempty(ii)
                        dataSet.ALPHApu = round(out.dalpha_pu{ii},4);
                        disp([int2str(out.ns) ' / ' int2str(out.nr(ii))])
                    end
                end
            end
            
            if(dataSet.hcBouCheck)
                dataSet.HCpu = data((last_index+1):(end-1));
            else
                dataSet.HCpu = ones(1,dataSet.NumOfLayers)*0.5;
            end
            dataSet.HCpu = round(dataSet.HCpu,2);
            dataSet.DepthOfBarrier = zeros(1,dataSet.NumOfLayers);
            dataSet.TanRibEdit = dataSet.MinMechTol*ones(1,dataSet.NumOfLayers);
            dataSet.RadRibCheck = 0;
            dataSet.RadRibEdit = zeros(1,dataSet.NumOfLayers);
            dataSet.pontRangEdit    = zeros(1,dataSet.NumOfLayers);
            dataSet.pontRoffsetEdit = zeros(1,dataSet.NumOfLayers);
            dataSet.RotorFilletRadEdit = dataSet.MinMechTol*ones(1,dataSet.NumOfLayers);
            dataSet.CentralShrink = zeros(1,dataSet.NumOfLayers);
            dataSet.RadShiftInner = zeros(1,dataSet.NumOfLayers);
            dataSet.NarrowFactor = ones(1,dataSet.NumOfLayers);
            dataSet.RadRibSplit = zeros(1,dataSet.NumOfLayers);
            dataSet.RotorFilletIn = dataSet.MinMechTol*ones(1,dataSet.NumOfLayers);
            dataSet.RotorFilletOut = dataSet.MinMechTol*ones(1,dataSet.NumOfLayers);
            
            if isfinite(geo.RotorFilletTan1)
                dataSet.RotorFilletTan1 = ones(1,dataSet.NumOfLayers);
                dataSet.RotorFilletTan2 = ones(1,dataSet.NumOfLayers);
            else
                dataSet.RotorFilletTan1 = nan(1,dataSet.NumOfLayers);
                dataSet.RotorFilletTan2 = nan(1,dataSet.NumOfLayers);
            end
            
            if ((strcmp(dataSet.TypeOfRotor,'Seg')||strcmp(dataSet.TypeOfRotor,'ISeg'))&&~strcmp(dataSet.FluxBarrierMaterial,'Air'))
                dataSet.PMdim=Inf(2,dataSet.NumOfLayers);
                dataSet.PMdim(2,1)=0;
            elseif ((strcmp(dataSet.TypeOfRotor,'Circular')||strcmp(dataSet.TypeOfRotor,'Vtype'))&&~strcmp(dataSet.FluxBarrierMaterial,'Air'))
                dataSet.PMdim=Inf(2,dataSet.NumOfLayers);
                dataSet.PMdim(2,:)=0;
            else
                dataSet.PMdim=zeros(2,dataSet.NumOfLayers);
            end
            
            dataSet.PMclear = zeros(2,dataSet.NumOfLayers);
            
            if strcmp(dataSet.TypeOfRotor,'Vtype')
                dataSet.HCpu = 5*ones(1,dataSet.NumOfLayers);
                dataSet.betaPMshape = 0.5*ones(1,dataSet.NumOfLayers);
            end
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Button pushed function: OptimizePush
        function OptimizePush_Callback(app, event)
            % Optimize pushbutton
            
            % hObject    handle to OptimizePush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            dataSet = app.dataSet;
            save('dataSet','dataSet');
            figure()
            [bounds, objs, geo, per, mat] = data0(dataSet);
            filemot=([dataSet.currentpathname dataSet.currentfilename]);
            dat.geo0 = geo;
            per.objs = objs;
            dat.per = per;
            dat.mat = mat;
            eval_type = 'MO_OA';
            %%%%%%%%%% FEMM fitness handle %%%%%%%%%%%%%%%%%%%%%%%%%%
            %             eval_type = 'MO_OA';
            %             if strcmp(dataSet.XFEMMOpt,'Y')
            %                 FitnessFunction = @(x)FEMMfitnessX(x,geo,per,eval_type);
            %             else
            FitnessFunction = @(x)FEMMfitness(x,geo,per,mat,eval_type,strrep(filemot,'.mat','.fem'));
            %             end
            dat.CostProblem = FitnessFunction;           % Cost function instance
            %% ========================================================================
            NOBJ = length(objs);
            NOBJ = sum(objs(:,2));
            XPOP = dataSet.XPop;
            Esc = 0.75;
            Pm= 0.2;
            NVAR = size(bounds,1);
            MAXGEN = dataSet.MaxGen;
            MAXFUNEVALS = 20000*NVAR*NOBJ;
            %% Variables regarding the optimization problem
            dat.FieldD = bounds;
            dat.Initial = bounds;
            dat.NOBJ = NOBJ;
            dat.NRES = 0;
            dat.NVAR = NVAR;
            dat.mop = str2func('evaluateF');
            dat.eval_type = eval_type;
            dat.XPOP = XPOP;
            dat.Esc = Esc;
            dat.Pm  = Pm;
            dat.fl  = 0.1;
            dat.fu  = 0.9;
            dat.tau1= 0.1;
            dat.tau2= 0.1;
            dat.InitialPop=[];
            dat.MAXGEN = MAXGEN;
            dat.MAXFUNEVALS = MAXFUNEVALS;
            dat.SaveResults='yes';
            dat.CounterGEN=0;
            dat.CounterFES=0;
            
            % Run the algorithm.
            OUT = MODE2(dat, dataSet);
            
            delete('dataSet.mat');
            rmdir('partial_optimization','s');
        end

        % Value changed function: OverSpeedEdit
        function OverSpeedEdit_Callback(app, event)
            % overspeed (geo.nmax)
            
            % hObject    handle to OverSpeedEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of OverSpeedEdit as text
            %        str2double(get(hObject,'String')) returns contents of OverSpeedEdit as a double
            dataSet = app.dataSet;
            dataSet.OverSpeed = eval(app.OverSpeedEdit.Value);
            dataSet.RadRibCheck = 0;
            dataSet.RadRibEdit = zeros(1,dataSet.NumOfLayers);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Button pushed function: PMDesignPush
        function PMDesignPush_Callback(app, event)
            % hObject    handle to PMDesignPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            dataSet = app.dataSet;
            [dataSet] = PMdesign(dataSet);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            
            buttonS = questdlg('Save the designed machine?','SELECT','Yes','No','Yes');
            if isequal(buttonS,'Yes')
                % save new machine
                BrName = ['Br' num2str(round(dataSet.PMdesign.BrPM,2))];
                BrName(BrName=='.') = 'T';
                if isempty(BrName=='T')
                    BrName = [BrName 'T0'];
                end
                IqName = ['Ich' num2str(round(dataSet.PMdesign.iq0,2))];
                IqName(IqName=='.') = 'A';
                if isempty(IqName=='T')
                    IqName = [IqName 'A0'];
                end
                dataSet.currentfilename = [dataSet.currentfilename(1:end-4) '_' BrName '_' IqName '.mat'];
                dataSet = DrawAndSaveMachine(dataSet,dataSet.currentfilename,dataSet.currentpathname);
            end
            
            % guidata(hObject,handles)
        end

        % Value changed function: PMdimBouCheck
        function PMdimBouCheck_Callback(app, event)
            % hObject    handle to PMdimBouCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of PMdimBouCheck
            
            dataSet = app.dataSet;
            dataSet.PMdimBouCheck = app.PMdimBouCheck.Value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app)
            % guidata(hObject,handles)
        end

        % Value changed function: PMdimBouEdit
        function PMdimBouEdit_Callback(app, event)
            % hObject    handle to PMdimBouEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of PMdimBouEdit as text
            %        str2double(get(hObject,'String')) returns contents of PMdimBouEdit as a double
            
            dataSet = app.dataSet;
            dataSet.PMdimBou = eval(app.PMdimBouEdit.Value);
            [m,n] = size(dataSet.PMdimBou);
            if m*n > 2 || m*n == 1
                disp('Must be vector 2x1')
                return
            end
            
            if ((max(dataSet.PMdimBou)>1)||(min(dataSet.PMdimBou)<0))
                dataSet.PMdimBou = [0 1];
            end
            
            [bounds, objs, geo, per, ~] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app)
            % guidata(hObject,handles)
        end

        % Cell edit callback: PMdimTable
        function PMdimTable_CellEditCallback(app, event)
            % hObject    handle to PMdimTable (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.CONTROL.TABLE)
            %	Indices: row and column indices of the cell(s) edited
            %	PreviousData: previous data for the cell(s) edited
            %	EditData: string(s) entered by the user
            %	NewData: EditData or its converted form set on the Data property. Empty if Data was not changed
            %	Error: error string when failed to convert EditData to appropriate value for Data
            % handles    structure with handles and user data (see GUIDATA)
            dataSet = app.dataSet;
            %tmp = get(hObject,'data');
            tmp = app.PMdimTable.Data;
            
            if (strcmp(dataSet.TypeOfRotor,'Circular')||strcmp(dataSet.TypeOfRotor,'Vtype'))
                tmp(2,:)=0;
            elseif strcmp(dataSet.TypeOfRotor,'ISeg')
                tmp(2,1)=0;
            end
            
            dataSet.PMdim = tmp;
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app)
            % guidata(hObject,handles)
        end

        % Value changed function: PMtemperatureEdit
        function PMtemperatureEdit_Callback(app, event)
            % hObject    handle to PMtemperatureEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of PMtemperatureEdit as text
            %        str2double(get(hObject,'String')) returns contents of PMtemperatureEdit as a double
            dataSet = app.dataSet;
            tmp = eval(app.PMtemperatureEdit.Value);
            mat=material_properties_layer(dataSet.FluxBarrierMaterial);
            if isfield(mat,'temp')
                if tmp>max(mat.temp.temp)
                    tmp=max(mat.temp.temp);
                    warning('PM temperature selected higher than maximum PM temperature!!!')
                elseif tmp<min(mat.temp.temp)
                    tmp=min(mat.temp.temp);
                    warning('PM temperature selected lower than minimum PM temperature!!!')
                end
                Br = interp1(mat.temp.temp,mat.temp.Br,tmp);
            else
                warning('The selected PM material has no temperature information.')
                tmp=20;
                Br=mat.Br;
            end
            
            dataSet.PMtemp = tmp;
            dataSet.Br = Br;
            
            dataSet.tempPP = tmp;
            dataSet.BrPP = Br;
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Callback function
        function ParallelSlotCheck_Callback(app, event)
            % hObject    handle to ParallelSlotCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of ParallelSlotCheck
            dataSet = app.dataSet;
            dataSet.ParallelSlotCheck = app.ParallelSlotCheck.Value;
            % [bounds, objs, geo, per, mat] = data0(dataSet);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: PhaseAngleCurrBouEdit
        function PhaseAngleCurrBouEdit_Callback(app, event)
            % Current phase angle  [elect. deg.](bounds_gamma)
            
            % hObject    handle to PhaseAngleCurrBouEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of PhaseAngleCurrBouEdit as text
            %        str2double(get(hObject,'String')) returns contents of PhaseAngleCurrBouEdit as a double
            dataSet = app.dataSet;
            dataSet.PhaseAngleCurrBou = eval(app.PhaseAngleCurrBouEdit.Value);
            [m,n] = size(dataSet.PhaseAngleCurrBou);
            if m*n > 2 || m*n == 1
                disp('Must be vector 2x1')
                return
            end
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: PitchWindEdit
        function PitchWindEdit_Callback(app, event)
            % Pitch shortening factor(geo.Kracc)
            
            % hObject    handle to PitchWindEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of PitchWindEdit as text
            %        str2double(get(hObject,'String')) returns contents of PitchWindEdit as a double
            dataSet = app.dataSet;
            dataSet.PitchShortFac = eval(app.PitchWindEdit.Value);
            if dataSet.Num3PhaseCircuit>1
                dataSet.PitchShortFac=1;
                disp('Shortening Factor not available with multi-3phase windings.')
            end
            
            p = dataSet.NumOfPolePairs;
            Q = round(dataSet.NumOfSlots*6*p*dataSet.Num3PhaseCircuit);
            %             yq = dataSet.PitchShortFac*dataSet.NumOfSlots*3;
            %             path = pwd;
            %             cd(fullfile (path,'koil'));
            %             system(['koil_syre.exe',' ',num2str(Q),' ',num2str(p),' ',num2str(yq)]);
            %             cd(path);
            %             Windings = MatrixWin();
            %             t2 = gcd(round(dataSet.NumOfSlots*6*dataSet.NumOfPolePairs),2*dataSet.NumOfPolePairs);
            %             Qs = Q/t2;
            %             dataSet.Qs = Qs;
            %             dataSet.WinMatr = Windings(:,1:floor(Qs)); % winding matrix, only Qs columns
            t2 = gcd(Q,2*p);
            Qs = Q/t2;
            Windings = WindingDefinition(dataSet);
            dataSet.WinMatr = Windings(:,1:Qs);
            dataSet.Qs      = Qs;
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: PolePairsEdit
        function PolePairsEdit_Callback(app, event)
            % Number of pole pairs(geo.p)
            
            % hObject    handle to PolePairsEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of PolePairsEdit as text
            %        str2double(get(hObject,'String')) returns contents of PolePairsEdit as a double
            dataSet = app.dataSet;
            dataSet.NumOfPolePairs = eval(app.PolePairsEdit.Value);
            %             %             p = dataSet.NumOfPolePairs;
            %             Q = round(dataSet.NumOfSlots*6*p);
            %             yq = dataSet.PitchShortFac*dataSet.NumOfSlots*3;
            %             t2 = gcd(round(dataSet.NumOfSlots*6*dataSet.NumOfPolePairs),2*dataSet.NumOfPolePairs);
            %             dataSet.Qs = Q/t2;
            %             dataSet.ToothLength = dataSet.StatorOuterRadius * (1-dataSet.AirGapRadius/dataSet.StatorOuterRadius*(1+dataSet.MagLoadingYoke/dataSet.NumOfPolePairs));
            %             dataSet.ToothLength = round(dataSet.ToothLength*10000)/10000;
            %             dataSet = WindingDefinition(dataSet);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            
        end

        % Value changed function: RadRibCheck
        function RadRibCheck_Callback(app, event)
            % hObject    handle to RadRibCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of RadRibCheck
            
            dataSet = app.dataSet;
            dataSet.RadRibCheck = app.RadRibCheck.Value;
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: RadRibEdit
        function RadRibEdit_Callback(app, event)
            % hObject    handle to RadRibEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of RadRibEdit as text
            %        str2double(get(hObject,'String')) returns contents of RadRibEdit as a double
            
            dataSet = app.dataSet;
            dataSet.RadRibEdit = eval(app.RadRibEdit.Value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Callback function
        function RadRibSplitCheck_Callback(app, event)
            % hObject    handle to RadRibSplitCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of RadRibSplitCheck
            dataSet = app.dataSet;
            dataSet.RadRibSplit = app.RadRibSplitCheck.Value;
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Callback function
        function RemTMPRadio_Callback(app, event)
            % remove TMP file (geo.RemoveTMPfile)it only works with XFEMM
            
            % hObject    handle to RemTMPRadio (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hint: get(hObject,'Value') returns toggle state of RemTMPRadio
            dataSet = app.dataSet;
            a = get(hObject,'Value');
            if a == 1
                dataSet.RMVTmp = 'ON';
            else
                dataSet.RMVTmp = 'OFF';
            end
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Button pushed function: RmvConductorPush
        function RmvConductorPush_Callback(app, event)
            % hObject    handle to RmvConductorPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            prompt={'Material Name'};
            answer={'MatName'};
            answer = inputdlg(prompt,'Remove material',1,answer);
            MatName = answer{1};
            remove_material(MatName,'Conductor');
            
            % update materials dropdowns
            mat = material_properties_conductor('0');
            app.StatorslotmaterialDropDown.Items = mat.MatList;
        end

        % Button pushed function: RmvIronPush
        function RmvIronPush_Callback(app, event)
            % hObject    handle to RmvIronPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            prompt={'Material Name'};
            answer={'MatName'};
            answer = inputdlg(prompt,'Remove material',1,answer);
            MatName = answer{1};
            remove_material(MatName,'Iron');
            
            % update materials dropdowns
            mat = material_properties_iron('0');
            app.StatorcorematerialDropDown.Items = mat.MatList;
            app.RotorcorematerialDropDown.Items  = mat.MatList;
            app.ShaftmaterialDropDown.Items      = ['Air' mat.MatList];
        end

        % Button pushed function: RmvMagnetPush
        function RmvMagnetPush_Callback(app, event)
            % hObject    handle to RmvMagnetPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            prompt={'Material Name'};
            answer={'MatName'};
            answer = inputdlg(prompt,'Remove material',1,answer);
            MatName = answer{1};
            remove_material(MatName,'Layer');
            
            % update materials dropdowns
            mat = material_properties_layer('0');
            app.FluxbarriermaterialDropDown.Items = mat.MatList;
        end

        % Callback function
        function RotorMatEdit_Callback(app, event)
            % rotor material (geo.BLKLABELSmaterials)
            
            % hObject    handle to RotorMatEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of RotorMatEdit as text
            %        str2double(get(hObject,'String')) returns contents of RotorMatEdit as a double
            dataSet = app.dataSet;
            dataSet.RotorMaterial = app.RotorMatEdit.Value;
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: RotorPosFinerEdit
        function RotorPosFinerEdit_Callback(app, event)
            % Rotor angular excursion for Pareto
            
            % hObject    handle to RotorPosFinerEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of RotorPosFinerEdit as text
            %        str2double(get(hObject,'String')) returns contents of RotorPosFinerEdit as a double
            dataSet = app.dataSet;
            dataSet.RotPoFine = eval(app.RotorPosFinerEdit.Value);
            dataSet.AngularSpanPP = eval(app.RotorPosFinerEdit.Value);
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: RotorPosiMOEdit
        function RotorPosiMOEdit_Callback(app, event)
            % Rotor angular excursion for MODE (geo.delta_sim_MOOA)
            
            % hObject    handle to RotorPosiMOEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of RotorPosiMOEdit as text
            %        str2double(get(hObject,'String')) returns contents of RotorPosiMOEdit as a double
            dataSet = app.dataSet;
            dataSet.RotPoMOOA = eval(app.RotorPosiMOEdit.Value);
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Button pushed function: SaveConfPush
        function SaveConfPush_Callback(app, event)
            % Save Configuration pushbutton
            % hObject    handle to SaveConfPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            new_avv = app.WinTable.Data;
            dataSet = app.dataSet;
            [~, ~, geo, ~, ~] = data0(dataSet);
            avv=geo.win.avv;  %AS
            n3phase=geo.win.n3phase; %AS
            [m,n] = size(new_avv);
            
            % Create avv_flag (size = # of phases) for fault case
            for i = 1 : m
                for k = 1 : n %create avv_flag (size = # of phases)
                    if ~isequal(new_avv(i,k),avv(i,k)) && new_avv(i,k)==0
                        avv_flag(abs(avv(i,k)))=0;  % if 0 the coil is deactivated
                    else
                        avv_flag(abs(new_avv(i,k)))=1;
                    end
                end
            end
            
            % Correct the winding matix
            for ih=1:(3*n3phase)
                for i = 1 : m %rows
                    for k = 1 : n %columns
                        if avv_flag(ih)==0 && abs(new_avv(i,k))==ih
                            new_avv(i,k)=0;
                        end
                    end
                end
            end
            dataSet.WinMatr = new_avv; %save winding in dataSet
            dataSet.WinFlag=avv_flag;
            msgbox('Matrix of windings saved');
            app.dataSet = dataSet;
            set(app.WinTable,'data',dataSet.WinMatr(:,1:floor(geo.Qs)));
            app.WinTable.Data = dataSet.WinMatr(:,1:floor(geo.Qs));
            % guidata(hObject,handles)
        end

        % Button pushed function: SaveMachineMagnetPush
        function SaveMachineMagnetPush_Callback(app, event)
            % hObject    handle to SaveMachineMagnetPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            dataSet = app.dataSet;
            dataSet = DrawAndSaveMachine_MN(dataSet);
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Button pushed function: SaveMachinePush
        function SaveMachinePush_Callback(app, event)
            
            % Save machine pushbutton
            
            % hObject    handle to SaveMachinePush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.dataSet.ScaleCheck =0;
            dataSet = app.dataSet;
            set(app.ScaleCheck,'Value',dataSet.ScaleCheck);
            GUI_APP_SetParameters(app);
            dataSet = DrawAndSaveMachine(dataSet);
            app.currentMotFileName.Value = dataSet.currentfilename;
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Button pushed function: SetDefaultWinding
        function SetDefaultWinding_Callback(app, event)
            % hObject    handle to SetDefaultWinding (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            dataSet = app.dataSet;
            [~, ~, geo, ~, ~] = data0(dataSet);
            Windings = MatrixWin();
            app.WinTable.Data = Windings(:,1:floor(geo.Qs));
            %             set(app.WinTable,'data',Windings(:,1:floor(geo.Qs)));
        end

        % Callback function
        function ShaftMatEdit_Callback(app, event)
            % shaft material (geo.BLKLABELSmaterials)
            
            % hObject    handle to ShaftMatEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of ShaftMatEdit as text
            %        str2double(get(hObject,'String')) returns contents of ShaftMatEdit as a double
            dataSet = app.dataSet;
            dataSet.ShaftMaterial = app.ShaftMatEdit.Value;
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: ShaftRadEdit
        function ShaftRadEdit_Callback(app, event)
            % Shaft radius [mm] (geo.Ar)
            
            % hObject    handle to ShaftRadEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of ShaftRadEdit as text
            %        str2double(get(hObject,'String')) returns contents of ShaftRadEdit as a double
            dataSet = app.dataSet;
            dataSet.ShaftRadius = eval(app.ShaftRadEdit.Value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: SimPosFinerEdit
        function SimPosFinerEdit_Callback(app, event)
            % Rotor position for Pareto(geo.nsim_singt)
            
            % hObject    handle to SimPosFinerEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of SimPosFinerEdit as text
            %        str2double(get(hObject,'String')) returns contents of SimPosFinerEdit as a double
            dataSet = app.dataSet;
            dataSet.SimPoFine = eval(app.SimPosFinerEdit.Value);
            dataSet.NumOfRotPosPP = eval(app.SimPosFinerEdit.Value);
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: SimPosMOEdit
        function SimPosMOEdit_Callback(app, event)
            % Simulated position for MODE(geo.nsim_MOOA)
            
            % hObject    handle to SimPosMOEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of SimPosMOEdit as text
            %        str2double(get(hObject,'String')) returns contents of SimPosMOEdit as a double
            dataSet = app.dataSet;
            dataSet.SimPoMOOA = eval(app.SimPosMOEdit.Value);
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: SlotFillFacEdit
        function SlotFillFacEdit_Callback(app, event)
            % Slot filling factor(geo.Kcu)
            
            % hObject    handle to SlotFillFacEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of SlotFillFacEdit as text
            %        str2double(get(hObject,'String')) returns contents of SlotFillFacEdit as a double
            dataSet = app.dataSet;
            dataSet.SlotFillFactor = eval(app.SlotFillFacEdit.Value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Callback function
        function SlotLayerPosCheck_Callback(app, event)
            % hObject    handle to SlotLayerPosCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of SlotLayerPosCheck
            
            dataSet = app.dataSet;
            dataSet.SlotLayerPosCheck = app.SlotLayerPosCheck.Value;
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Callback function
        function SlotMatEdit_Callback(app, event)
            % Slot material(geo.BLKLABELSmaterials)
            
            % hObject    handle to SlotMatEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of SlotMatEdit as text
            %        str2double(get(hObject,'String')) returns contents of SlotMatEdit as a double
            dataSet = app.dataSet;
            dataSet.SlotMaterial = app.SlotMatEdit.Value;
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: SlotModelFrequencyEdit
        function SlotModelFrequencyEdit_Callback(app, event)
            % hObject    handle to SlotModelFrequencyEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of SlotModelFrequencyEdit as text
            %        str2double(get(hObject,'String')) returns contents of SlotModelFrequencyEdit as a double
            dataSet = app.dataSet;
            dataSet.SlotConductorFrequency = eval(app.SlotModelFrequencyEdit.Value);
            dataSet.SlotConductorFrequency(dataSet.SlotConductorFrequency<=0)=0.1;
            dataSet.SlotConductorFrequency = unique(dataSet.SlotConductorFrequency);
            dataSet.SlotConductorFrequency = sort(dataSet.SlotConductorFrequency);
            app.dataSet = dataSet;
            % app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: SlotSimulEdit
        function SlotSimulEdit_Callback(app, event)
            % hObject    handle to SlotSimulEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of SlotSimulEdit as text
            %        str2double(get(hObject,'String')) returns contents of SlotSimulEdit as a double
            dataSet = app.dataSet;
            Qs = eval(app.SlotSimulEdit.Value);
            p = dataSet.NumOfPolePairs;
            n3ph = dataSet.Num3PhaseCircuit;
            Q = round(dataSet.NumOfSlots*6*p*n3ph);
            %             yq = dataSet.PitchShortFac*dataSet.NumOfSlots*3;
            %             path = pwd;
            %             cd(fullfile (path,'koil'));
            %             system(['koil_syre.exe',' ',num2str(Q),' ',num2str(p),' ',num2str(yq)]);
            %             cd(path);
            %             Windings = MatrixWin();
            % check if Qs and ps is feasible (if ps is integer)
            t2 = gcd(Q,2*p);
            QsCalc = Q/t2;
            psCalc = 2*p/t2;
            ps = psCalc/QsCalc*Qs;
            Windings = WindingDefinition(dataSet);
            
            if not((ps-floor(ps))==0)
                disp('Error in the setting of Qs. Qs is reset to calculated standard value')
                ps=psCalc;
                Qs=QsCalc;
            end
            
            if dataSet.thetaFBS~=0
                if rem(ps,2)~=0
                    ps=2;
                    Qs=Q*ps/(2*p);
                    disp('FBS enabled, the number of poles must be even!')
                end
            end
            dataSet.Qs = Qs;
            dataSet.WinMatr = Windings(:,1:Qs); % winding matrix, only Qs columns
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: SpanEltPPEdit
        function SpanEltPPEdit_Callback(app, event)
            % Rotor angular excursion[elect. deg.]
            
            % hObject    handle to SpanEltPPEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of SpanEltPPEdit as text
            %        str2double(get(hObject,'String')) returns contents of SpanEltPPEdit as a double
            dataSet = app.dataSet;
            dataSet.AngularSpanPP = eval(app.SpanEltPPEdit.Value);
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: StackLenghtEdit
        function StackLenghtEdit_Callback(app, event)
            % Stack length [mm] (geo.l)
            
            % hObject    handle to StackLenghtEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of StackLenghtEdit as text
            %        str2double(get(hObject,'String')) returns contents of StackLenghtEdit as a double
            dataSet = app.dataSet;
            dataSet.StackLength = eval(app.StackLenghtEdit.Value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Button pushed function: StartPProMagnetPush
        function StartPProMagnetPush_Callback(app, event)
            % hObject    handle to StartPProMagnetPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            dataSet = app.dataSet;
            % check if speed is greater than zero
            if dataSet.EvalSpeed==0
                msg = 'Speed MUST be greater than one in MagNet simulations! - see PostProcessing TAB';
                title = 'WARNING';
                f = warndlg(msg,title,'modal');
                error(msg);
            end
            
            % dataSet.SimulatedCurrent = dataSet.CurrLoPP*dataSet.RatedCurrent;
            
            switch dataSet.EvalType
                case {'singt','singtIron'}
                    eval_operatingPointMN(dataSet);
                case {'singm','singmIron'}
                    eval_fluxMapMN(dataSet);
                otherwise
                    error('Simulation type not supported in MagNet!!!')
            end
            
            % guidata(hObject,handles)
        end

        % Button pushed function: StartPProPush
        function StartPProPush_Callback(app, event)
            % Start pushbutton of processing
            % hObject    handle to StartPProPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            dataSet = app.dataSet;
            
            switch dataSet.EvalType
                case {'singt','flxdn','izero','force','singtIron'}
                    eval_operatingPoint(dataSet);
                case {'singm','singmIron'}
                    eval_fluxMap(dataSet);
                case 'demagArea'
                    eval_demagArea(dataSet);
                case 'idemag'
                    eval_idemag(dataSet);
                case 'ichval'
                    eval_ich(dataSet);
                case 'structural'
                    eval_vonMisesStress(dataSet);
                case 'shortCircuit'
                    eval_peakShortCircuitCurrent(dataSet);
            end
            
            % guidata(hObject,handles)
        end

        % Callback function
        function StatorMatEdit_Callback(app, event)
            % stator material(geo.BLKLABELSmaterials)
            
            % hObject    handle to StatorMatEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of StatorMatEdit as text
            %        str2double(get(hObject,'String')) returns contents of StatorMatEdit as a double
            dataSet = app.dataSet;
            dataSet.StatorMaterial = app.StatorMatEdit.Value;
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: StatorOuterRadEdit
        function StatorOuterRadEdit_Callback(app, event)
            % Stator outer radius[mm](geo.R)
            
            % hObject    handle to StatorOuterRadEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of StatorOuterRadEdit as text
            %        str2double(get(hObject,'String')) returns contents of StatorOuterRadEdit as a double
            preValue = app.dataSet.StatorOuterRadius;
            dataSet = app.dataSet;
            dataSet.StatorOuterRadius = eval(app.StatorOuterRadEdit.Value);
            %dataSet.ToothLength = dataSet.StatorOuterRadius * (1-dataSet.AirGapRadius/dataSet.StatorOuterRadius*(1+dataSet.MagLoadingYoke/dataSet.NumOfPolePairs));
            %dataSet.ToothLength = round(dataSet.ToothLength*10000)/10000;
            if dataSet.ScaleCheck
                [dataSet] = scaleMachine(dataSet,preValue);
            end
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
            
        end

        % Value changed function: StatorSlotOpeEdit
        function StatorSlotOpeEdit_Callback(app, event)
            % Stator slot opening [p.u.] (geo.acs)
            
            % hObject    handle to StatorSlotOpeEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of StatorSlotOpeEdit as text
            %        str2double(get(hObject,'String')) returns contents of StatorSlotOpeEdit as a double
            dataSet = app.dataSet;
            dataSet.StatorSlotOpen = eval(app.StatorSlotOpeEdit.Value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: StatorSlotOpenBouCheck
        function StatorSlotOpenBouCheck_Callback(app, event)
            % stator slot open [p.u.] (bounds_acs)
            % hObject    handle to StatorSlotOpenBouCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of StatorSlotOpenBouCheck
            dataSet = app.dataSet;
            dataSet.StatorSlotOpenBouCheck = app.StatorSlotOpenBouCheck.Value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: StatorSlotOpenBouEdit
        function StatorSlotOpenBouEdit_Callback(app, event)
            % stator slot open [p.u.] (bounds_acs)
            % hObject    handle to StatorSlotOpenBouEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of StatorSlotOpenBouEdit as text
            %        str2double(get(hObject,'String')) returns contents of StatorSlotOpenBouEdit as a double
            dataSet = app.dataSet;
            dataSet.StatorSlotOpenBou = eval(app.StatorSlotOpenBouEdit.Value);
            [bounds, objs, geo, per, mat] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: TanRibEdit
        function TanRibEdit_Callback(app, event)
            % hObject    handle to TanRibEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of TanRibEdit as text
            %        str2double(get(hObject,'String')) returns contents of TanRibEdit as a double
            dataSet = app.dataSet;
            dataSet.TanRibEdit = eval(app.TanRibEdit.Value);
            dataSet.TanRibEdit(dataSet.TanRibEdit<dataSet.MinMechTol)=dataSet.MinMechTol;
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            %             % guidata(hObject,handles)
        end

        % Value changed function: TempPPEdit
        function TempPPEdit_Callback(app, event)
            % hObject    handle to TempPPEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of TempPPEdit as text
            %        str2double(get(hObject,'String')) returns contents of TempPPEdit as a double
            dataSet = app.dataSet;
            dataSet.tempPP = eval(app.TempPPEdit.Value);
            tempPP=dataSet.tempPP;
            BrPP=dataSet.BrPP;
            mat=material_properties_layer(dataSet.FluxBarrierMaterial);
            warning('off','backtrace')
            if isfield(mat,'temp')
                if tempPP>max(mat.temp.temp)
                    tempPP=max(mat.temp.temp);
                    warning(['Minimum PMs temperature = ' num2str(tempPP) '°C'])
                elseif tempPP<min(mat.temp.temp)
                    tempPP=min(mat.temp.temp);
                    warning(['Maximum PMs temperature = ' num2str(tempPP) '°C'])
                end
                BrPP=interp1(mat.temp.temp,mat.temp.Br,tempPP);
            else
                warning('This PM material do not have temperature data!!!')
            end
            warning('on','backtrace')
            dataSet.tempPP=tempPP;
            dataSet.BrPP=BrPP;
            set(app.BrPPEdit,'Value',mat2str(dataSet.BrPP));
            set(app.TempPPEdit,'Value',mat2str(dataSet.tempPP));
            app.dataSet = dataSet;
            % % guidata(hObject,handles)
        end

        % Value changed function: ThermalLoadKj
        function ThermalLoadKj_Callback(app, event)
            % hObject    handle to ThermalLoadKj (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ThermalLoadKj as text
            %        str2double(get(hObject,'String')) returns contents of ThermalLoadKj as a double
            dataSet = app.dataSet;
            
            dataSet.ThermalLoadKj = eval(app.ThermalLoadKj.Value);
            dataSet.AdmiJouleLosses = NaN;
            dataSet.CurrentDensity = NaN;
            
            app.dataSet = dataSet;
            
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ThetaFBSBouCheck
        function ThetaFBSBouCheck_Callback(app, event)
            % hObject    handle to ThetaFBSBouCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of ThetaFBSBouCheck
            dataSet = app.dataSet;
            dataSet.ThetaFBSBouCheck = app.ThetaFBSBouCheck.Value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            p=dataSet.NumOfPolePairs;
            Q=round(6*p*dataSet.NumOfSlots*dataSet.Num3PhaseCircuit);
            ps=2;
            Qs=Q*ps/(2*p);
            % check if Qs and ps is feasible (if ps is integer)
            t2 = gcd(Q,2*p);
            QsCalc = Q/t2;
            psCalc = 2*p/t2;
            
            if rem(Qs,2)>0
                disp('Error in the geometry reconstrunction. FBS not applied')
                Qs=QsCalc;
                dataSet.thetsFBS=0;
            else
                dataSet.Qs=Qs;
                %                 yq = dataSet.PitchShortFac*dataSet.NumOfSlots*3;
                %                 path = pwd;
                %                 cd(fullfile (path,'koil'));
                %                 system(['koil_syre.exe',' ',num2str(Q),' ',num2str(p),' ',num2str(yq)]);
                %                 cd(path);
                %                 Windings = MatrixWin();
                
                Windings = WindingDefinition(dataSet);
                dataSet.WinMatr = Windings(:,1:floor(Qs)); % winding matrix, only Qs columns
            end
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ThetaFBSBouEdit
        function ThetaFBSBouEdit_Callback(app, event)
            % hObject    handle to ThetaFBSBouEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ThetaFBSBouEdit as text
            %        str2double(get(hObject,'String')) returns contents of ThetaFBSBouEdit as a double
            dataSet = app.dataSet;
            dataSet.ThetaFBSBou = eval(app.ThetaFBSBouEdit.Value);
            [m,n] = size(dataSet.DfeBou);
            if m*n > 2 || m*n == 1
                disp('Must be vector 2x1')
                return
            end
            [bounds, objs, geo, per, ~] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ThetaFBSEdit
        function ThetaFBSEdit_Callback(app, event)
            % hObject    handle to ThetaFBSEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ThetaFBSEdit as text
            %        str2double(get(hObject,'String')) returns contents of ThetaFBSEdit as a double
            dataSet = app.dataSet;
            dataSet.thetaFBS = eval(app.ThetaFBSEdit.Value);
            p=dataSet.NumOfPolePairs;
            Q=round(6*p*dataSet.NumOfSlots*dataSet.Num3PhaseCircuit);
            ps=2;
            Qs=Q*ps/(2*p);
            % check if Qs and ps is feasible (if ps is integer)
            t2 = gcd(Q,2*p);
            QsCalc = Q/t2;
            psCalc = 2*p/t2;
            
            if rem(Qs,2)>0
                disp('Error in the geometry reconstrunction. FBS not applied')
                Qs=QsCalc;
                dataSet.thetsFBS=0;
                app.ThetaFBSEdit.Value = '0';
            else
                dataSet.Qs=Qs;
                %                 yq = dataSet.PitchShortFac*dataSet.NumOfSlots*3;
                %                 path = pwd;
                %                 cd(fullfile (path,'koil'));
                %                 system(['koil_syre.exe',' ',num2str(Q),' ',num2str(p),' ',num2str(yq)]);
                %                 cd(path);
                %                 Windings = MatrixWin();
                %                 dataSet.WinMatr = Windings(:,1:floor(Qs)); % winding matrix, only Qs columns
                Windings = WindingDefinition(dataSet);
                dataSet.Qs = Qs;
                dataSet.WinMatr = Windings(:,1:Qs);
                
            end
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ToothLenBouEdit
        function ToothLenBouEdit_Callback(app, event)
            % Tooth length [mm](bounds_lt)
            
            % hObject    handle to ToothLenBouEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ToothLenBouEdit as text
            %        str2double(get(hObject,'String')) returns contents of ToothLenBouEdit as a double
            dataSet = app.dataSet;
            dataSet.ToothLeBou = eval(app.ToothLenBouEdit.Value);
            if dataSet.ToothLeBou(2)>(dataSet.StatorOuterRadius - dataSet.AirGapRadius-dataSet.AirGapThickness)
                dataSet.ToothLeBou(2) = dataSet.StatorOuterRadius - dataSet.AirGapRadius-dataSet.AirGapThickness;
            end
            [bounds, objs, geo, per, mat] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ToothLengEdit
        function ToothLengEdit_Callback(app, event)
            % Tooth length [mm](geo.lt)
            
            % hObject    handle to ToothLengEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of ToothLengEdit as text
            %        str2double(get(hObject,'String')) returns contents of ToothLengEdit as a double
            dataSet = app.dataSet;
            dataSet.ToothLength = eval(app.ToothLengEdit.Value);
            if dataSet.ToothLength>(dataSet.StatorOuterRadius - dataSet.AirGapRadius-dataSet.AirGapThickness)
                dataSet.ToothLength = dataSet.StatorOuterRadius - dataSet.AirGapRadius-dataSet.AirGapThickness;
            end
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ToothLengthBouCheck
        function ToothLengthBouCheck_Callback(app, event)
            % Tooth length [mm] (bounds_lt) check box
            
            % hObject    handle to ToothLengthBouCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of ToothLengthBouCheck
            dataSet = app.dataSet;
            dataSet.ToothLengthBouCheck = app.ToothLengthBouCheck.Value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ToothTanDepEdit
        function ToothTanDepEdit_Callback(app, event)
            % Tooth tang. depth [mm](geo.ttd)
            
            % hObject    handle to ToothTanDepEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of ToothTanDepEdit as text
            %        str2double(get(hObject,'String')) returns contents of ToothTanDepEdit as a double
            dataSet = app.dataSet;
            dataSet.ToothTangDepth = eval(app.ToothTanDepEdit.Value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ToothTangAngleEdit
        function ToothTangAngleEdit_Callback(app, event)
            % Tooth tang. angle [mech. deg.](geo.tta)
            
            % hObject    handle to ToothTangAngleEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of ToothTangAngleEdit as text
            %        str2double(get(hObject,'String')) returns contents of ToothTangAngleEdit as a double
            dataSet = app.dataSet;
            dataSet.ToothTangAngle = eval(app.ToothTangAngleEdit.Value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ToothTangDepthBouCheck
        function ToothTangDepthBouCheck_Callback(app, event)
            % tooth tang depth[mm]  (bounds_ttd)
            % hObject    handle to ToothTangDepthBouCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of ToothTangDepthBouCheck
            dataSet = app.dataSet;
            dataSet.ToothTangDepthBouCheck = app.ToothTangDepthBouCheck.Value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ToothTangDepthBouEdit
        function ToothTangDepthBouEdit_Callback(app, event)
            % tooth tang depth[mm]  (bounds_ttd)
            % hObject    handle to ToothTangDepthBouEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ToothTangDepthBouEdit as text
            %        str2double(get(hObject,'String')) returns contents of ToothTangDepthBouEdit as a double
            dataSet = app.dataSet;
            dataSet.ToothTangDepthBou = eval(app.ToothTangDepthBouEdit.Value);
            [bounds, objs, geo, per, mat] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ToothWidthBouCheck
        function ToothWidthBouCheck_Callback(app, event)
            % Tooth width [mm](bounds_wt)check box
            
            % hObject    handle to ToothWidthBouCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of ToothWidthBouCheck
            dataSet = app.dataSet;
            dataSet.ToothWidthBouCheck = app.ToothWidthBouCheck.Value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ToothWidthBouEdit
        function ToothWidthBouEdit_Callback(app, event)
            % Tooth width [mm](bounds_wt)
            % hObject    handle to ToothWidthBouEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ToothWidthBouEdit as text
            %        str2double(get(hObject,'String')) returns contents of ToothWidthBouEdit as a double
            dataSet = app.dataSet;
            dataSet.ToothWiBou = eval(app.ToothWidthBouEdit.Value);
            [bounds, objs, geo, per, mat] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ToothWidthEdit
        function ToothWidthEdit_Callback(app, event)
            % Tooth width [mm](geo.wt)
            
            % hObject    handle to ToothWidthEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of ToothWidthEdit as text
            %        str2double(get(hObject,'String')) returns contents of ToothWidthEdit as a double
            dataSet = app.dataSet;
            dataSet.ToothWidth = eval(app.ToothWidthEdit.Value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: TorRipOptCheck
        function TorRipOptCheck_Callback(app, event)
            % hObject    handle to TorRipOptCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of TorRipOptCheck
            dataSet = app.dataSet;
            dataSet.TorRipOptCheck = app.TorRipOptCheck.Value;
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: TorqueOptCheck
        function TorqueOptCheck_Callback(app, event)
            % hObject    handle to TorqueOptCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of TorqueOptCheck
            dataSet = app.dataSet;
            dataSet.TorqueOptCheck = app.TorqueOptCheck.Value;
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: TurnsSeriesEdit
        function TurnsSeriesEdit_Callback(app, event)
            % Turns in series per phase(geo.Ns)
            
            % hObject    handle to TurnsSeriesEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of TurnsSeriesEdit as text
            %        str2double(get(hObject,'String')) returns contents of TurnsSeriesEdit as a double
            dataSet = app.dataSet;
            dataSet.TurnsInSeries = eval(app.TurnsSeriesEdit.Value);
            dataSet.SlotConductorNumber=dataSet.TurnsInSeries/dataSet.NumOfPolePairs/(dataSet.NumOfSlots);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: TypeOfRotorList
        function TypeOfRotorList_Callback(app, event)
            % Type of rotor (geo.RotType)
            
            % hObject    handle to TypeOfRotorList (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: contents = cellstr(get(hObject,'String')) returns TypeOfRotorList contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from TypeOfRotorList
            
            dataSet = app.dataSet;
            dataSet.TypeOfRotor = app.TypeOfRotorList.Value;
            
            % set(app.SlopeBarrier,'Enable','off'); %rev.Gallo
            % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if (strcmp(dataSet.TypeOfRotor,'SPM')||strcmp(dataSet.TypeOfRotor,'Vtype'))
                dataSet.betaPMshape = 0.5*ones(1,dataSet.NumOfLayers);
                dataSet.MapQuadrants = 2;
            else
                dataSet.betaPMshape = zeros(1,dataSet.NumOfLayers);
                dataSet.MapQuadrants = 1;
            end
            
            if ~(strcmp(dataSet.TypeOfRotor,'Circular')||strcmp(dataSet.TypeOfRotor,'Seg'))
                dataSet.thetaFBS=0;
                dataSet.ThetaFBSBouCheck=0;
            end
            
            %Set initial value Vtype parameters - rev.Gallo
            if strcmp(dataSet.TypeOfRotor,'Vtype')
                %                 if dataSet.NumOfLayers~=1
                %                     dataSet.NumOfLayers = 1;
                %                     dataSet.ALPHApu = [0.5];
                %                     dataSet.HCpu = [5];
                %                     dataSet.DepthOfBarrier = 0;
                %                     dataSet.betaPMshape = [0.5];
                %                     dataSet.RadRibEdit = 0;
                %                     dataSet.RadRibCheck = 0;
                %                     dataSet.RadRibSplit = 0;
                %                     dataSet.TanRibEdit  = dataSet.MinMechTol;
                %                 end
                dataSet.HCpu = dataSet.HCpu*6;
                dataSet.BetaPMshapeBou=[0.4 1];
                dataSet.BetaPMshapeBouCheck=0;
                warning('V-type geometry will be deleted in next releases. Use Seg geometry with central barrier shrink=0.')
            end
            
            dataSet.DxBouCheck = 0;
            dataSet.PMdimBouCheck = 0;
            
            if strcmp(dataSet.TypeOfRotor,'SPM')
                dataSet.DepthOfBarrier = 1;
                dataSet.Dalpha1BouCheck =0;
                dataSet.DalphaBouCheck = 0;
                dataSet.DxBouCheck = 0;
                dataSet.betaPMshape = 1;
                dataSet.FluxBarrierMaterial = 'BMN-38EH';
                [~,~,~,~,mat] = data0(dataSet);
                dataSet.Br = mat.LayerMag.Br;
                dataSet.BrPP = dataSet.Br;
                dataSet.bRange = [4 6];
                dataSet.bRange = round(dataSet.bRange*100)/100;
            else
                if strcmp(dataSet.TypeOfRotor,'Vtype') %rev.Gallo
                    dataSet.FluxBarrierMaterial = 'BMN-38EH'; %imposto materiale magnete commerciale caso Vtype
                    dataSet.PMdim = inf(2,dataSet.NumOfLayers);
                    
                else
                    dataSet.FluxBarrierMaterial = 'Air';
                    dataSet.PMdim = zeros(2,dataSet.NumOfLayers);
                    dataSet.Br = 0;
                end
                
                if (strcmp(dataSet.TypeOfRotor,'Fluid') || strcmp(dataSet.TypeOfRotor,'Seg') || strcmp(dataSet.TypeOfRotor,'Circular'))
                    if not(length(dataSet.DepthOfBarrier) == dataSet.NumOfLayers)
                        dataSet.DepthOfBarrier = zeros(1,dataSet.NumOfLayers);
                    end
                end
                
                [bounds, ~, geo, ~, mat] = data0(dataSet);
                data = buildDefaultRQ(bounds);
                dataSet.RQ = data;
                dataSet.Br = mat.LayerMag.Br;
                dataSet.BrPP = dataSet.Br;
                if max(dataSet.bRange)>1
                    dataSet.bRange = [0.4 0.6];
                end
                [truefalse, index] = ismember('dx', geo.RQnames);
                if truefalse
                    dataSet.DfeBou = bounds(index,:);            % barrier offset [p.u.]
                end
            end
            
            dataSet.PMclear = zeros(2,dataSet.NumOfLayers);
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles);
        end

        % Button pushed function: ViewPropPush
        function ViewPropPush_Callback(app, event)
            % hObject    handle to ViewPropPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            answer = inputdlg('Material Name','Properties viewer',1);
            MatName = char(answer);
            mat = material_properties_iron(MatName);
            if isfield(mat,'kgm3')
                plot_iron_prop(mat);
            else
                clear mat
                mat = material_properties_conductor(MatName);
                if isfield(mat,'kgm3')
                    plot_conductor_prop(mat);
                else
                    clear mat
                    mat = material_properties_layer(MatName);
                    if isfield(mat,'kgm3')
                        plot_layer_prop(mat);
                    else
                        msgbox('Select a correct material name to see the properties','Properties viewer');
                    end
                end
            end
        end

        % Callback function
        function XFEMMOptRadio_Callback(app, event)
            % XFEMM option check box
            % hObject    handle to XFEMMOptRadio (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hint: get(hObject,'Value') returns toggle state of XFEMMOptRadio
            
            dataSet = app.dataSet;
            if get(hObject,'Value') > 0
                dataSet.XFEMMOpt = 'Y';
            else
                dataSet.XFEMMOpt = 'N';
            end
            app.dataSet = dataSet;
            % % guidata(hObject,handles)
        end

        % Value changed function: XPopEdit
        function XPopEdit_Callback(app, event)
            % Population Size
            % hObject    handle to XPopEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of XPopEdit as text
            %        str2double(get(hObject,'String')) returns contents of XPopEdit as a double
            dataSet = app.dataSet;
            dataSet.XPop = eval(app.XPopEdit.Value);
            app.dataSet = dataSet;
            % % guidata(hObject,handles)
        end

        % Value changed function: bRangeEdit
        function bRangeEdit_Callback(app, event)
            % hObject    handle to bRangeEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of bRangeEdit as text
            %        str2double(get(hObject,'String')) returns contents of bRangeEdit as a double
            dataSet = app.dataSet;
            dataSet.bRange = eval(app.bRangeEdit.Value);
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: hcBouCheck
        function hcBouCheck_Callback(app, event)
            % hc [p.u.](bounds_hc) check box
            % hObject    handle to hcBouCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of hcBouCheck
            dataSet = app.dataSet;
            dataSet.hcBouCheck = app.hcBouCheck.Value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: hcBouEdit
        function hcBouEdit_Callback(app, event)
            % hc[p.u.](bounds_hc)
            
            % hObject    handle to hcBouEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of hcBouEdit as text
            %        str2double(get(hObject,'String')) returns contents of hcBouEdit as a double
            dataSet = app.dataSet;
            dataSet.hcBou = eval(app.hcBouEdit.Value);
            [m,n] = size(dataSet.hcBou);
            if m*n > 2 || m*n == 1
                disp('Must be vector 2x1')
                return
            end
            [bounds, objs, geo, per, ~] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: hcmmEdit
        function hcmmEdit_Callback(app, event)
            % hc [mm]
            % hObject    handle to hcmmEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of hcmmEdit as text
            %        str2double(get(hObject,'String')) returns contents of hcmmEdit as a double
            dataSet = app.dataSet;
            dataSet.ThicknessOfPM = eval(app.hcmmEdit.Value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: hcpuEdit
        function hcpuEdit_Callback(app, event)
            % hc [p.u.]
            % hObject    handle to hcpuEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of hcpuEdit as text
            %        str2double(get(hObject,'String')) returns contents of hcpuEdit as a double
            dataSet = app.dataSet;
            dataSet.HCpu = eval(app.hcpuEdit.Value);
            dataSet.hcBouCheck = 0;
            
            %Aggiornamento valori di angle su GUI quando modifico hcpu - rev.Gallo
            [~,~,geo,~,mat] = data0(dataSet);
            [bounds,~,~,~,~] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ktEdit
        function ktEdit_Callback(app, event)
            % hObject    handle to ktEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ktEdit as text
            %        str2double(get(hObject,'String')) returns contents of ktEdit as a double
            dataSet = app.dataSet;
            dataSet.kt = eval(app.ktEdit.Value);
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Button pushed function: syrmDesignPushButt
        function syrmDesignPushButt_Callback(app, event)
            % hObject    handle to syrmDesignPushButt (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            dataSet = app.dataSet;
            FEAfixTmp = dataSet.FEAfixN;
            dataSet.FEAfixN = 0;
            [dataSet,flagS] = syrmDesign(dataSet);
            dataSet.FEAfixN = FEAfixTmp;
            app.dataSet = dataSet;
            if flagS
                dataSet = DrawAndSaveMachine(dataSet,dataSet.currentfilename,dataSet.currentpathname);
            end
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: xRangeEdit
        function xRangeEdit_Callback(app, event)
            % hObject    handle to xRangeEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of xRangeEdit as text
            %        str2double(get(hObject,'String')) returns contents of xRangeEdit as a double
            dataSet = app.dataSet;
            dataSet.xRange = eval(app.xRangeEdit.Value);
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Button pushed function: LoadMachinePush
        function LoadMachinePushButtonPushed(app, event)
            % Load new motor model. It replace the toolbox command from the
            % old GUIDE-style GUI
            [FileName,PathName] = uigetfile('*.mat');
            if FileName
                if strcmp(FileName(end-3:end),'.fig')
                    hfig = openfig([PathName FileName],'invisible');
                    map = get(hfig,'UserData');
                    dataSet = map.dataSet;
                    [~,~,geo,per,~] = data0(dataSet);
                    close(hfig);
                else
                    load([PathName FileName]);
                    update_material_library(PathName,FileName);
                end
                
                if ~exist('geo')
                    geo = geo0;
                end
                
                if exist('dataSet')~=1
                    dataSet=build_dataSet(geo,per);
                    disp('dataSet reconstructed. Please check the data')
                end


                [dataSet,geo,per] = back_compatibility(dataSet,geo,per);
                dataSet.RQ = round(dataSet.RQ*10000)/10000;
                
                % Set tab to main tab
                app.TabGroup.SelectedTab = app.TabGroup.Children(1);
                
                
                dataSet.currentpathname = PathName;
                dataSet.currentfilename = [FileName(1:end-4) '.mat'];
                
                app.dataSet = dataSet;
                app = GUI_APP_DrawMachine(app);
                GUI_APP_SetParameters(app);
                
                if app.dataSet.custom
                    app.StateLamp.Color = [0 1 0];
                else
                    app.StateLamp.Color = [1 0 0];
                end
                
            end
            
            figure(app.figure1)
        end

        % Value changed function: SlotshapeDropDown
        function SlotshapeDropDownValueChanged(app, event)
            value = app.SlotshapeDropDown.Value;
            dataSet = app.dataSet;
            tmp = app.SlotshapeDropDown.Value;
            if strcmp(tmp,'Trapezoidal')
                dataSet.ParallelSlotCheck = 0;
            else
                dataSet.ParallelSlotCheck = 1;
            end
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: SlotlayerposDropDown
        function SlotlayerposDropDownValueChanged(app, event)
            dataSet = app.dataSet;
            tmp = app.SlotlayerposDropDown.Value;
            if strcmp(tmp,'Stacked')
                dataSet.SlotLayerPosCheck = 0;
            else
                dataSet.SlotLayerPosCheck = 1;
            end
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: StatorslotmaterialDropDown
        function StatorslotmaterialDropDownValueChanged(app, event)
            dataSet = app.dataSet;
            dataSet.SlotMaterial = app.StatorslotmaterialDropDown.Value;
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: StatorcorematerialDropDown
        function StatorcorematerialDropDownValueChanged(app, event)
            dataSet = app.dataSet;
            dataSet.StatorMaterial = app.StatorcorematerialDropDown.Value;
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: RotorcorematerialDropDown
        function RotorcorematerialDropDownValueChanged(app, event)
            dataSet = app.dataSet;
            dataSet.RotorMaterial = app.RotorcorematerialDropDown.Value;
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: FluxbarriermaterialDropDown
        function FluxbarriermaterialDropDownValueChanged(app, event)
            dataSet = app.dataSet;
            dataSet.FluxBarrierMaterial = app.FluxbarriermaterialDropDown.Value;
            mat = material_properties_layer(dataSet.FluxBarrierMaterial);
            dataSet.Br = mat.Br;
            dataSet.BrPP = dataSet.Br;
            if isfield(mat,'temp')
                dataSet.Br = interp1(mat.temp.temp,mat.temp.Br,dataSet.PMtemp);
                dataSet.BrPP = interp1(mat.temp.temp,mat.temp.Br,dataSet.tempPP);
            end
            if ~strcmp(dataSet.FluxBarrierMaterial,'Air')
                if (strcmp(dataSet.TypeOfRotor,'Seg')||strcmp(dataSet.TypeOfRotor,'ISeg'))
                    dataSet.PMdim = Inf(2,dataSet.NumOfLayers);
                elseif (strcmp(dataSet.TypeOfRotor,'Circular')||strcmp(dataSet.TypeOfRotor,'Vtype'))
                    dataSet.PMdim = [Inf(1,dataSet.NumOfLayers);zeros(1,dataSet.NumOfLayers)];
                elseif strcmp(dataSet.TypeOfRotor,'ISeg')
                    dataSet.PMdim(2,1)=0;
                end
            else
                dataSet.PMdim = zeros(2,dataSet.NumOfLayers);
            end
            dataSet.PMclear = dataSet.MinMechTol/2*ones(2,dataSet.NumOfLayers);
            
            if strcmp(dataSet.FluxBarrierMaterial,'Air')
                dataSet.MapQuadrants = 1;
            else
                dataSet.MapQuadrants = 2;
            end
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: ShaftmaterialDropDown
        function ShaftmaterialDropDownValueChanged(app, event)
            dataSet = app.dataSet;
            dataSet.ShaftMaterial = app.ShaftmaterialDropDown.Value;
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Button pushed function: ExportMCadPush
        function ExportMCadPushButtonPushed(app, event)
            dataSet = app.dataSet;
            dataSet = DrawAndSaveMachine_MCAD(dataSet);
            app.dataSet = dataSet;
        end

        % Button pushed function: StartMCadSimPush
        function StartMCadSimPushButtonPushed(app, event)
            dataSet = app.dataSet;
            switch dataSet.EvalType
                case {'singt'}
                    eval_operatingPointMCAD(dataSet);
                otherwise
                    error('Simulation type not supported in Motor-CAD!!!')
            end
        end

        % Button pushed function: ExportFluxMapsPush
        function ExportFluxMapsPushButtonPushed(app, event)
            dataSet = app.dataSet;
            dataSet = Export_FdFq_tables_in_MCAD(dataSet);
            app.dataSet = dataSet;
        end

        % Value changed function: InlettemperatureEditField
        function InlettemperatureEditFieldValueChanged(app, event)
            dataSet = app.dataSet;
            dataSet.InletTemperature = eval(app.InlettemperatureEditField.Value);
            app.dataSet = dataSet;
            
        end

        % Value changed function: TransientperiodEditField
        function TransientperiodEditFieldValueChanged(app, event)
            dataSet = app.dataSet;
            dataSet.TransientPeriod = eval(app.TransientperiodEditField.Value);
            app.dataSet = dataSet;
        end

        % Value changed function: HousingTypeDropDown
        function HousingTypeDropDownValueChanged(app, event)
            dataSet = app.dataSet;
            dataSet.HousingType = app.HousingTypeDropDown.Value;
            app.dataSet = dataSet;
            %app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            
        end

        % Button pushed function: ExportThermalParameters_MCADPush
        function ExportThermalParameters_MCADPushButtonPushed(app, event)
            dataSet = app.dataSet;
            dataSet = ExportThermalParameters_MCAD(dataSet);
            app.dataSet = dataSet;
        end

        % Button pushed function: ThermalSimulation_MCAD
        function ThermalSimulation_MCADButtonPushed(app, event)
            dataSet = app.dataSet;
            dataSet = ThermalSimulation_MCAD(dataSet);
            app.dataSet = dataSet;
        end

        % Value changed function: TimestepEditField
        function TimestepEditFieldValueChanged(app, event)
            dataSet = app.dataSet;
            dataSet.TransientTimeStep = eval(app.TimestepEditField.Value);
            app.dataSet = dataSet;
            
        end

        % Value changed function: RotorFilletTan1EditField
        function RotorFilletTan1EditFieldValueChanged(app, event)
            % Rotor fillet [mm]
            
            dataSet = app.dataSet;
            dataSet.RotorFilletTan1 = eval(app.RotorFilletTan1EditField.Value);
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Cell edit callback: PMclearanceTable
        function PMclearanceTableCellEdit(app, event)
            %             indices = event.Indices;
            %             newData = event.NewData;
            dataSet = app.dataSet;
            %tmp = get(hObject,'data');
            tmp = app.PMclearanceTable.Data;
            
            if (strcmp(dataSet.TypeOfRotor,'Circular')||strcmp(dataSet.TypeOfRotor,'Vtype'))
                tmp(2,:)=0;
            elseif strcmp(dataSet.TypeOfRotor,'ISeg')
                tmp(2,1)=0;
            end
            
            dataSet.PMclear = tmp;
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app)
        end

        % Value changed function: SlotModelTemperatureEdit
        function SlotModelTemperatureEditValueChanged(app, event)
            value = app.SlotModelTemperatureEdit.Value;
            value = eval(value);
            value = sort(value);
            
            dataSet = app.dataSet;
            dataSet.SlotConductorTemperature = value;
            
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
            
        end

        % Value changed function: RadialRibsAngleEditField
        function RadialRibsAngleEditFieldValueChanged(app, event)
            dataSet = app.dataSet;
            dataSet.pontRangEdit = eval(app.RadialRibsAngleEditField.Value);
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            
        end

        % Value changed function: RadialRibsOffsetEditField
        function RadialRibsOffsetEditFieldValueChanged(app, event)
            dataSet = app.dataSet;
            dataSet.pontRoffsetEdit = eval(app.RadialRibsOffsetEditField.Value);
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            
        end

        % Value changed function: RotorFilletRadInEditField
        function RotorFilletRadInEditFieldValueChanged(app, event)
            dataSet = app.dataSet;
            dataSet.RotorFilletIn = eval(app.RotorFilletRadInEditField.Value);
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: RotorFilletRadOutEditField
        function RotorFilletRadOutEditFieldValueChanged(app, event)
            dataSet = app.dataSet;
            dataSet.RotorFilletOut = eval(app.RotorFilletRadOutEditField.Value);
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            
        end

        % Callback function
        function SplitRibsEditField1ValueChanged(app, event)
            value = app.SplitRibsEdit.Value;
            
            dataSet = app.dataSet;
            dataSet.RadRibSplit = eval(value);
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: RadShiftInnerEdit
        function RadShiftInnerEditValueChanged(app, event)
            value = app.RadShiftInnerEdit.Value;
            dataSet = app.dataSet;
            dataSet.RadShiftInner = eval(value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: NarrowFactorEdit
        function NarrowFactorEditValueChanged(app, event)
            value = app.NarrowFactorEdit.Value;
            dataSet = app.dataSet;
            dataSet.NarrowFactor = eval(value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: SplitRibsEditField
        function SplitRibsEditFieldValueChanged(app, event)
            value = app.SplitRibsEditField.Value;
            dataSet = app.dataSet;
            dataSet.RadRibSplit = eval(value);
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: CentralBarriersShrinkEdit
        function CentralBarriersShrinkEditValueChanged(app, event)
            value = app.CentralBarriersShrinkEdit.Value;
            dataSet = app.dataSet;
            dataSet.CentralShrink = eval(value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: FluidDropDown
        function FluidDropDownValueChanged(app, event)
            value = app.FluidDropDown.Value;
            
            dataSet = app.dataSet;
            dataSet.Fluid = value;
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
        end

        % Value changed function: FluidFlowRateEditField
        function FluidFlowRateEditFieldValueChanged(app, event)
            value = app.FluidFlowRateEditField.Value;
            dataSet = app.dataSet;
            dataSet.FlowRate = eval(value);
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
        end

        % Value changed function: ThermalevaluationtypeDropDown
        function ThermalevaluationtypeDropDownValueChanged(app, event)
            value = app.ThermalevaluationtypeDropDown.Value;
            dataSet = app.dataSet;
            dataSet.th_eval_type = value;
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
        end

        % Value changed function: InitialTemperatureEditField
        function InitialTemperatureEditFieldValueChanged2(app, event)
            value = app.InitialTemperatureEditField.Value;
            
%             dataSet = app.dataSet;
%             dataSet.MachineTemperature = eval(value);
%             app.dataSet = dataSet;
%             GUI_APP_SetParameters(app);
            dataSet = app.dataSet;
            dataSet.TempPMLimit = eval(value);
            app.dataSet = dataSet;
            %GUI_APP_SetParameters(app);
        end

        % Callback function
        function ThermalLoadKj_mirrorValueChanged(app, event)
            value = app.ThermalLoadKj_mirror.Value;
            
            dataSet = app.dataSet;
            dataSet.ThermalLoadKj = eval(value);
            
            app.dataSet = dataSet;
            
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Callback function
        function CurrLoPPMirrorEditValueChanged(app, event)
            value = app.CurrLoPPMirrorEdit.Value;
            dataSet = app.dataSet;
            dataSet.CurrLoPP = eval(value);
            dataSet.SimulatedCurrent = dataSet.CurrLoPP*dataSet.RatedCurrent;
            
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app)
        end

        % Value changed function: GammaPPMirrorEdit
        function GammaPPMirrorEditValueChanged(app, event)
            value = app.GammaPPMirrorEdit.Value;
            dataSet = app.dataSet;
            dataSet.GammaPP = eval(value);
            app.dataSet = dataSet;
        end

        % Value changed function: TempPPMirrorEdit
        function TempPPMirrorEditValueChanged(app, event)
            value = app.TempPPMirrorEdit.Value;
            dataSet = app.dataSet;
            dataSet.tempPP = eval(value);
            tempPP=dataSet.tempPP;
            BrPP=dataSet.BrPP;
            mat=material_properties_layer(dataSet.FluxBarrierMaterial);
            warning('off','backtrace')
            if isfield(mat,'temp')
                if tempPP>max(mat.temp.temp)
                    tempPP=max(mat.temp.temp);
                    warning(['Minimum PMs temperature = ' num2str(tempPP) '°C'])
                elseif tempPP<min(mat.temp.temp)
                    tempPP=min(mat.temp.temp);
                    warning(['Maximum PMs temperature = ' num2str(tempPP) '°C'])
                end
                BrPP=interp1(mat.temp.temp,mat.temp.Br,tempPP);
            else
                warning('This PM material do not have temperature data!!!')
            end
            warning('on','backtrace')
            dataSet.tempPP=tempPP;
            dataSet.BrPP=BrPP;
            set(app.BrPPEdit,'Value',mat2str(dataSet.BrPP));
            set(app.TempPPEdit,'Value',mat2str(dataSet.tempPP));
            app.dataSet = dataSet;
        end

        % Value changed function: EvaluatedSpeedMirrorEdit
        function EvaluatedSpeedMirrorEditValueChanged(app, event)
            value = app.EvaluatedSpeedMirrorEdit.Value;
            dataSet = app.dataSet;
            dataSet.EvalSpeed = eval(value);
            app.dataSet = dataSet;
        end

        % Value changed function: CurrentPPMirror
        function CurrentPPMirrorValueChanged(app, event)
            value = app.CurrentPPMirror.Value;
            dataSet = app.dataSet;
            dataSet.SimulatedCurrent = eval(value);
            app.dataSet = dataSet;
        end

        % Value changed function: RadRibBouEdit
        function RadRibBouEditValueChanged(app, event)
            value = app.RadRibBouEdit.Value;
            dataSet = app.dataSet;
            dataSet.RadRibBou = eval(value);
            [m,n] = size(dataSet.RadRibBou);
            if m*n > 2 || m*n == 1
                disp('Must be vector 2x1')
                return
            end
            [bounds, objs, geo, per, ~] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            
        end

        % Value changed function: RadRibBouCheck
        function RadRibBouCheckValueChanged(app, event)
            value = app.RadRibBouCheck.Value;
            
            dataSet = app.dataSet;
            dataSet.RadRibBouCheck = value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app)
            % guidata(hObject,handles)
        end

        % Value changed function: CentralShrinkBouEdit
        function CentralShrinkBouEditValueChanged(app, event)
            value = app.CentralShrinkBouEdit.Value;
            dataSet = app.dataSet;
            dataSet.CentralShrinkBou = eval(value);
            [m,n] = size(dataSet.CentralShrinkBou);
            if m*n > 2 || m*n == 1
                disp('Must be vector 2x1')
                return
            end
            [bounds, objs, geo, per, ~] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: CentralShrinkBouCheck
        function CentralShrinkBouCheckValueChanged(app, event)
            value = app.CentralShrinkBouCheck.Value;
            dataSet = app.dataSet;
            dataSet.CentralShrinkBouCheck = value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app)
            % guidata(hObject,handles)
        end

        % Value changed function: RadShiftInnerBouCheck
        function RadShiftInnerBouCheckValueChanged(app, event)
            value = app.RadShiftInnerBouCheck.Value;
            dataSet = app.dataSet;
            dataSet.RadShiftInnerBouCheck = value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app)
            % guidata(hObject,handles)
        end

        % Value changed function: RadShiftInnerBouEdit
        function RadShiftInnerBouEditValueChanged(app, event)
            value = app.RadShiftInnerBouEdit.Value;
            dataSet = app.dataSet;
            dataSet.RadShiftInnerBou = eval(value);
            [m,n] = size(dataSet.RadShiftInnerBou);
            if m*n > 2 || m*n == 1
                disp('Must be vector 2x1')
                return
            end
            [bounds, objs, geo, per, ~] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: PowerFactorOptCheck
        function PowerFactorOptCheckValueChanged(app, event)
            value = app.PowerFactorOptCheck.Value;
            dataSet = app.dataSet;
            dataSet.PowerFactorOptCheck = value;
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
        end

        % Value changed function: MinExpPowerFactorEdit
        function MinExpPowerFactorEditValueChanged(app, event)
            value = app.MinExpPowerFactorEdit.Value;
            dataSet = app.dataSet;
            dataSet.MinExpPowerFactor = eval(value);
            if dataSet.MinExpPowerFactor>0
                disp('Power factor limit penalization must be negative for optimization settings!!!')
                beep
                dataSet.MinExpPowerFactor = -dataSet.MinExpPowerFactor;
            end
            app.dataSet = dataSet;
        end

        % Value changed function: MaxExpNoLoadFluxEdit
        function MaxExpNoLoadFluxEditValueChanged(app, event)
            value = app.MaxExpNoLoadFluxEdit.Value;
            dataSet = app.dataSet;
            dataSet.MaxExpNoLoadFlux = eval(value);
            app.dataSet = dataSet;
        end

        % Value changed function: NoLoadFluxOptCheck
        function NoLoadFluxOptCheckValueChanged(app, event)
            value = app.NoLoadFluxOptCheck.Value;
            dataSet = app.dataSet;
            dataSet.NoLoadFluxOptCheck = value;
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
            
        end

        % Value changed function: MechStressOptCheck
        function MechStressOptCheckValueChanged(app, event)
            value = app.MechStressOptCheck.Value;
            warning('Feature still under development!!!')
            GUI_APP_SetParameters(app);
        end

        % Callback function
        function MaxExpMechStressEditValueChanged(app, event)
            value = app.MaxExpMechStressEdit.Value;
            dataSet = app.dataSet;
            dataSet.MaxExpMechStress = eval(value);
            app.dataSet = dataSet;
        end

        % Value changed function: TanRibBouEdit
        function TanRibBouEditValueChanged(app, event)
            value = app.TanRibBouEdit.Value;
            dataSet = app.dataSet;
            dataSet.TanRibBou = eval(value);
            [m,n] = size(dataSet.TanRibBou);
            if m*n > 2 || m*n == 1
                disp('Must be vector 2x1')
                return
            end
            [bounds, objs, geo, per, ~] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: TanRibBouCheck
        function TanRibBouCheckValueChanged(app, event)
            value = app.TanRibBouCheck.Value;
            dataSet = app.dataSet;
            dataSet.TanRibBouCheck = value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
            dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app)
            
        end

        % Button pushed function: CustomLoad
        function CustomLoadButtonPushed(app, event)
            app.StateLamp.Color = [0 1 0];
            app.dataSet.custom = 1;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app)
            
        end

        % Button pushed function: CustomClear
        function CustomClearButtonPushed(app, event)
            
            app.dataSet.pShape.rotor  = [];
            app.dataSet.pShape.stator = [];
            app.dataSet.pShape.magnet = [];
            app.dataSet.pShape.slot   = [];
            app.dataSet.custom        = 0;
            app.dataSet.pShape.flag   = 0;
            
            app.StateLamp.Color = [1 0 0];
            
            pathname  = app.dataSet.currentpathname;
            fileans   = strrep(app.dataSet.currentfilename,'.mat','.ans');
            if isfile([pathname fileans])
                delete ([pathname fileans])
            end
            
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app)
            
        end

        % Value changed function: ConductorSlotGapEdit
        function ConductorSlotGapEditValueChanged(app, event)
            value = app.ConductorSlotGapEdit.Value;
            
            dataSet = app.dataSet;
            dataSet.SlotConductorBottomGap = eval(value);
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
            
        end

        % Value changed function: RotorFilletTan2EditField
        function RotorFilletTan2EditFieldValueChanged(app, event)
            value = app.RotorFilletTan2EditField.Value;
            
            dataSet = app.dataSet;
            dataSet.RotorFilletTan2 = eval(value);
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
            
        end

        % Value changed function: TanRibCheck
        function TanRibCheckValueChanged(app, event)
            value = app.TanRibCheck.Value;
            dataSet = app.dataSet;
            dataSet.TanRibCheck = value;
            
            if ((~strcmp(dataSet.TypeOfRotor,'Seg') && ~strcmp(dataSet.TypeOfRotor,'Circular'))  && dataSet.TanRibCheck ==1)
                dataSet.TanRibCheck = 0;
            end
            
            if dataSet.TanRibCheck ==1
                dataSet.RotorFilletTan1  = 2*dataSet.MinMechTol*ones(1,dataSet.NumOfLayers);
                dataSet.RotorFilletTan2  = 2*dataSet.MinMechTol*ones(1,dataSet.NumOfLayers);
            else
                dataSet.RotorFilletTan1  = nan(1,dataSet.NumOfLayers);
                dataSet.RotorFilletTan2  = nan(1,dataSet.NumOfLayers);
            end
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Callback function
        function ScalePushButtButtonPushed(app, event)
            value = app.HoldCheckBox.Value;
            app.dataSet.ScaleCheck = value;
            GUI_APP_SetParameters(app)
            dataSet = app.dataSet;
            
            dataSet = app.dataSet;
            [dataSet] = scaleMachine(dataSet);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app)
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Callback function
        function ScaleEditValueChanged(app, event)
            value = app.ScaleEdit.Value;
            dataSet = app.dataSet;
            dataSet.ScaleFactor = eval(value);
            app.dataSet = dataSet;
            
        end

        % Callback function
        function ScaleAxialEditValueChanged(app, event)
            value = app.ScaleAxialEdit.Value;
            dataSet = app.dataSet;
            dataSet.ScaleFactorAxial = eval(value);
            app.dataSet = dataSet;
        end

        % Callback function
        function SyReMMMButtonPushed(app, event)
            
            pathname = app.dataSet.currentpathname;
            filename = app.dataSet.currentfilename;
            
            answer = 'Yes';
            answer = questdlg('Do you want to save the motor model?','Save','Yes','No',answer);
            if strcmp(answer,'Yes')
                
                dataSet = DrawAndSaveMachine(dataSet,filename,pathname);
                app.currentMotFileName.Value = dataSet.currentfilename;
                app.dataSet = dataSet;
                disp('Motor model saved!')
            end
            
            GUI_Syre_MMM(pathname,filename)
            
            
        end

        % Callback function
        function HoldCheckBoxValueChanged(app, event)
            value = app.HoldCheckBox.Value;
            app.dataSet.ScaleCheck = value;
            GUI_APP_SetParameters(app)
            dataSet = app.dataSet;
        end

        % Value changed function: ScaleCheck
        function ScaleCheckValueChanged(app, event)
            value = app.ScaleCheck.Value;
            app.dataSet.ScaleCheck = value;
            GUI_APP_SetParameters(app)
            dataSet = app.dataSet;
        end

        % Value changed function: SlotWidthEdit
        function SlotWidthEditValueChanged(app, event)
            value = app.SlotWidthEdit.Value;
            dataSet = app.dataSet;
            dataSet.SlotWidth = eval(value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            
        end

        % Value changed function: MapQuadrantsPopUp
        function MapQuadrantsPopUpValueChanged(app, event)
            value = app.MapQuadrantsPopUp.Value;
            app.dataSet.MapQuadrants = eval(value(1));
            GUI_APP_SetParameters(app);
        end

        % Value changed function: TempCuLimitEdit
        function TempCuLimitEditValueChanged(app, event)
            value = app.TempCuLimitEdit.Value;
            dataSet = app.dataSet;
            dataSet.TempCuLimit = eval(value);
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
        end

        % Callback function
        function InitialTemperatureEditFieldValueChanged(app, event)
            value = app.InitialTemperatureEditField.Value;
            ataSet = app.dataSet;
            dataSet.TempPMLimit = eval(value);
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
        end

        % Value changed function: TransientTimeEditField
        function TransientTimeEditFieldValueChanged(app, event)
            value = app.TransientTimeEditField.Value;
            dataSet = app.dataSet;
            dataSet.TransTime = eval(value);
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
        end

        % Button pushed function: CalculateCont_MCAD
        function CalculateCont_MCADButtonPushed(app, event)
            dataSet = app.dataSet;
            dataSet = Eval_ThermalLimit0rpm(dataSet,'Steady');
            app.dataSet = dataSet;
            
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Button pushed function: CalculateTrans_MCAD
        function CalculateTrans_MCADButtonPushed(app, event)
            dataSet = app.dataSet;
            dataSet = Eval_ThermalLimit0rpm(dataSet,'Transient');
            app.dataSet = dataSet;
            
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: AmbientTemperatureEditField
        function AmbientTemperatureEditFieldValueChanged(app, event)
            value = app.AmbientTemperatureEditField.Value;
            
            dataSet = app.dataSet;
            dataSet.AmbTemp = eval(value);
            app.dataSet = dataSet;
            %GUI_APP_SetParameters(app);
        end

        % Button pushed function: SaveMachineAnsysPush
        function SaveMachineAnsysPushButtonPushed(app, event)
            dataSet = app.dataSet;
            dataSet = DrawAndSaveMachine_AM(dataSet);
            app.dataSet = dataSet;
        end

        % Button pushed function: StartPProAnsysPush
        function StartPProAnsysPushButtonPushed(app, event)

            dataSet = app.dataSet;
            switch dataSet.EvalType
                case {'singt','singtIron'}
                    eval_operatingPointAM(dataSet);
                    if app.dataSet.CustomCurrentEnable
                        app.dataSet.CustomCurrentAnsysCounter = app.dataSet.CustomCurrentAnsysCounter + 1;
                    end
                otherwise
                    error('Simulation type not supported in Ansys Maxwell!')
            end
            GUI_APP_SetParameters(app);
        end

        % Value changed function: ParallelNumberEdit
        function ParallelNumberEditValueChanged(app, event)
            value = app.ParallelNumberEdit.Value;
            
            dataSet = app.dataSet;
            dataSet.SlotConductorParallel = eval(value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: JouleLossesEdit
        function JouleLossesEditValueChanged(app, event)
            value = app.JouleLossesEdit.Value;
            
            dataSet = app.dataSet;
            dataSet.AdmiJouleLosses = eval(value);
            dataSet.ThermalLoadKj = NaN;
            dataSet.CurrentDensity = NaN;
            
            app.dataSet = dataSet;
            
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: CurrentdensityEdit
        function CurrentdensityEditValueChanged(app, event)
            value = app.CurrentdensityEdit.Value;
            
            dataSet = app.dataSet;
            dataSet.CurrentDensity = eval(value);
            dataSet.ThermalLoadKj = NaN;
            dataSet.AdmiJouleLosses = NaN;
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: BarrierDesignDropDow
        function BarrierDesignDropDowValueChanged(app, event)
            value = app.BarrierDesignDropDow.Value;
            f = app.dataSet.syrmDesignFlag;
            switch value
                case 'Constant flux barrier thickness'
                    f.hc = 0;
                case 'Constant flux barrier permeance'
                    f.hc = 1;
                case 'Lfq minimization'
                    f.hc = 2;
            end
            app.dataSet.syrmDesignFlag = f;
            GUI_APP_SetParameters(app);
        end

        % Value changed function: CarrierDesignDropDown
        function CarrierDesignDropDownValueChanged(app, event)
            value = app.CarrierDesignDropDown.Value;
            f = app.dataSet.syrmDesignFlag;
            switch value
                case 'No flux carrier design'
                    f.dx = 0;
                case 'Constant flux carrier thickness'
                    f.dx = 1;
                case 'Flux carrier thickness proportional to d-flux'
                    f.dx = 2;
            end
            app.dataSet.syrmDesignFlag = f;
            GUI_APP_SetParameters(app);
        end

        % Value changed function: SaturationFactorDropDown
        function SaturationFactorDropDownValueChanged(app, event)
            value = app.SaturationFactorDropDown.Value;
            f = app.dataSet.syrmDesignFlag;
            switch value
                case 'Neglect saturation factor'
                    f.ks = 0;
                case 'Compute saturation factor'
                    f.ks = 1;
            end
            app.dataSet.syrmDesignFlag = f;
            GUI_APP_SetParameters(app);
        end

        % Value changed function: syrmDesignThermalInputDropDown
        function syrmDesignThermalInputDropDownValueChanged(app, event)
            value = app.syrmDesignThermalInputDropDown.Value;
            f = app.dataSet.syrmDesignFlag;
            switch value
                case 'Constant thermal loading kj'
                    f.i0 = 0;
                case 'Constant current density J'
                    f.i0 = 1;
            end
            app.dataSet.syrmDesignFlag = f;
            GUI_APP_SetParameters(app);
        end

        % Value changed function: syrmDesignGammaFixDropDown
        function syrmDesignGammaFixDropDownValueChanged(app, event)
            value = app.syrmDesignGammaFixDropDown.Value;
            f = app.dataSet.syrmDesignFlag;
            switch value
                case 'Current angle imposed'
                    f.gf = 0;
                case 'FEAfix MTPA correction'
                    f.gf = 1;
            end
            app.dataSet.syrmDesignFlag = f;
            GUI_APP_SetParameters(app);
        end

        % Button pushed function: dxfButton
        function dxfButtonPushed(app, event)
            syreToDxf([],[],app.dataSet.currentpathname,app.dataSet.currentfilename);
            disp(['File saved: ' app.dataSet.currentpathname app.dataSet.currentfilename(1:end-4) '.dxf']);
        end

        % Button pushed function: AnsysMotorCADButton
        function AnsysMotorCADButtonPushed(app, event)
            dataSet = app.dataSet;
            dataSet = DrawAndSaveMachine_MCAD(dataSet);
            app.dataSet = dataSet;
        end

        % Button pushed function: UsermanualButton
        function UsermanualButtonPushed(app, event)
            syreDirectory = fileparts(which('GUI_Syre.mlapp'));
            open([syreDirectory '\ReadMe\syreManual.pdf']);
        end

        % Button pushed function: ReferencesButton
        function ReferencesButtonPushed(app, event)
            syreDirectory = fileparts(which('GUI_Syre.mlapp'));
            open([syreDirectory '\ReadMe\syreReferences.pdf']);
        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
            ppState = parallelComputingCheck(1);

        end

        % Button pushed function: EnableButton
        function EnableButtonPushed(app, event)
            ppState = parallelComputingCheck(0);
            if ppState<0
                disp('Parallel Computing Toolbox not available!')
                beep;
            elseif ppState==0
                numCores = feature('numcores');
                parpool(numCores);
            else
                disp(['Parallel computing already enabled on ' int2str(ppState) ' workers'])
            end
        end

        % Button pushed function: DisableButton
        function DisableButtonPushed(app, event)
            ppState = parallelComputingCheck(0);
            if ppState<0
                disp('Parallel Computing Toolbox not available!')
                beep;
            elseif ppState==0
                disp('Parallel ccomputing already disabled')
            else
                delete(gcp)
                disp('Parallel computing disabled')
            end
        end

        % Button pushed function: CheckReleaseButton
        function CheckReleaseButtonPushed(app, event)
            checkReleases();
        end

        % Button pushed function: CloseallButton
        function CloseallButtonPushed(app, event)
            close all
            clc
        end

        % Button pushed function: LaunchMMMButton
        function LaunchMMMButtonPushed(app, event)
            GUI_Syre_MMM(app.dataSet.currentpathname,app.dataSet.currentfilename)
        end

        % Button pushed function: LaunchsyrmDesignExplorerButton
        function LaunchsyrmDesignExplorerButtonPushed(app, event)
            if exist('syrmDesignExplorer.mlapp','file')
                syrmDesignExplorer();
            else
                warning('syrmDesignExplorer not included in this release!');
            end
        end

        % Value changed function: RotoryokefactorpuEditField
        function RotoryokefactorpuEditFieldValueChanged(app, event)
            value = app.RotoryokefactorpuEditField.Value;
            app.dataSet.RotorYokeFactor = eval(value);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: CustomCurrentSwitch
        function CustomCurrentSwitchValueChanged(app, event)
            value = app.CustomCurrentSwitch.Value;
            if strcmp(value,'On')
                app.dataSet.CustomCurrentEnable = 1;
            else
                app.dataSet.CustomCurrentEnable = 0;
            end
            GUI_APP_SetParameters(app);
        end

        % Value changed function: CustomCurrentFile
        function CustomCurrentFileValueChanged(app, event)
            value = app.CustomCurrentFile.Value;
            
        end

        % Button pushed function: LoadCustomCurrent
        function LoadCustomCurrentButtonPushed(app, event)
            [FileNameCurr,PathNameCurr] = uigetfile('*.mat');
            app.dataSet.CustomCurrentFilename = [PathNameCurr FileNameCurr];
            
            load(app.dataSet.CustomCurrentFilename)
            app.dataSet.CustomCurrentA = Ia;
            app.dataSet.CustomCurrentB = Ib;
            app.dataSet.CustomCurrentC = Ic;
            app.dataSet.CustomCurrentTime = time;
            
            
            GUI_APP_SetParameters(app);
        end

        % Value changed function: AxistypeDropDown
        function AxistypeDropDownValueChanged(app, event)
            value = app.AxistypeDropDown.Value;  
            app.dataSet.axisType = value;
            
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GUI_Syre';
            app.AxisGeometry = uiaxes(app.figure1);
            app.AxisLogo = uiaxes(app.figure1);
            app.AxisLogo.XTick = [];
            app.AxisLogo.YTick = [];
            app.SaveMachinePush = uibutton(app.figure1, 'push');
            app.SaveMachinePush.ButtonPushedFcn = createCallbackFcn(app, @SaveMachinePush_Callback, true);
            app.SaveMachinePush.Text = 'Save machine';
            app.text95 = uilabel(app.figure1);
            app.text95.Text = 'ver. 3.2';
            app.ClearCachePush = uibutton(app.figure1, 'push');
            app.ClearCachePush.ButtonPushedFcn = createCallbackFcn(app, @ClearCachePush_Callback, true);
            app.ClearCachePush.Text = 'Clear \tmp';
            app.currentMotFileName = uieditfield(app.figure1, 'text');
            app.currentMotFileName.Value = 'Edit Text';
            app.text120 = uilabel(app.figure1);
            app.text120.Text = 'Current mot file is:';
            app.LoadMachinePush = uibutton(app.figure1, 'push');
            app.LoadMachinePush.ButtonPushedFcn = createCallbackFcn(app, @LoadMachinePushButtonPushed, true);
            app.LoadMachinePush.Text = 'Load Machine';
            app.TabGroup = uitabgroup(app.figure1);
            app.MainDataTab = uitab(app.TabGroup);
            app.MainDataTab.Title = 'Main Data';
            app.NumberofpolepairsEditFieldLabel = uilabel(app.MainDataTab);
            app.NumberofpolepairsEditFieldLabel.Text = 'Number of pole pairs';
            app.PolePairsEdit = uieditfield(app.MainDataTab, 'text');
            app.PolePairsEdit.ValueChangedFcn = createCallbackFcn(app, @PolePairsEdit_Callback, true);
            app.NumberofslotspolephaseEditFieldLabel = uilabel(app.MainDataTab);
            app.NumberofslotspolephaseEditFieldLabel.Text = 'Number of slots/(pole*phase)';
            app.NumOfSlotsEdit = uieditfield(app.MainDataTab, 'text');
            app.NumOfSlotsEdit.ValueChangedFcn = createCallbackFcn(app, @NumOfSlotsEdit_Callback, true);
            app.AirgapthicknessmmEditFieldLabel = uilabel(app.MainDataTab);
            app.AirgapthicknessmmEditFieldLabel.Text = 'Airgap thickness [mm]';
            app.GapThiEdit = uieditfield(app.MainDataTab, 'text');
            app.GapThiEdit.ValueChangedFcn = createCallbackFcn(app, @GapThiEdit_Callback, true);
            app.StatorouterradiusmmEditFieldLabel = uilabel(app.MainDataTab);
            app.StatorouterradiusmmEditFieldLabel.Text = 'Stator outer radius [mm]';
            app.StatorOuterRadEdit = uieditfield(app.MainDataTab, 'text');
            app.StatorOuterRadEdit.ValueChangedFcn = createCallbackFcn(app, @StatorOuterRadEdit_Callback, true);
            app.AirgapradiusmmEditFieldLabel = uilabel(app.MainDataTab);
            app.AirgapradiusmmEditFieldLabel.Text = 'Airgap radius [mm]';
            app.AirGapRadiusEdit = uieditfield(app.MainDataTab, 'text');
            app.AirGapRadiusEdit.ValueChangedFcn = createCallbackFcn(app, @AirGapRadiusEdit_Callback, true);
            app.ShaftradiusmmEditFieldLabel = uilabel(app.MainDataTab);
            app.ShaftradiusmmEditFieldLabel.Text = 'Shaft radius [mm]';
            app.ShaftRadEdit = uieditfield(app.MainDataTab, 'text');
            app.ShaftRadEdit.ValueChangedFcn = createCallbackFcn(app, @ShaftRadEdit_Callback, true);
            app.StacklengthmmEditFieldLabel = uilabel(app.MainDataTab);
            app.StacklengthmmEditFieldLabel.Text = 'Stack length [mm]';
            app.StackLenghtEdit = uieditfield(app.MainDataTab, 'text');
            app.StackLenghtEdit.ValueChangedFcn = createCallbackFcn(app, @StackLenghtEdit_Callback, true);
            app.TypeofrotorDropDownLabel = uilabel(app.MainDataTab);
            app.TypeofrotorDropDownLabel.Text = 'Type of rotor';
            app.TypeOfRotorList = uidropdown(app.MainDataTab);
            app.TypeOfRotorList.Items = {'Circular', 'ISeg', 'Seg', 'Fluid', 'SPM', 'Vtype'};
            app.TypeOfRotorList.ValueChangedFcn = createCallbackFcn(app, @TypeOfRotorList_Callback, true);
            app.TypeOfRotorList.Value = 'Circular';
            app.PreliminaryDesignPanel = uipanel(app.MainDataTab);
            app.PreliminaryDesignPanel.Title = 'Preliminary Design';
            app.RangeofxrotorstatorsplitEditFieldLabel = uilabel(app.PreliminaryDesignPanel);
            app.RangeofxrotorstatorsplitEditFieldLabel.Text = 'Range of x (rotor/stator split)';
            app.xRangeEdit = uieditfield(app.PreliminaryDesignPanel, 'text');
            app.xRangeEdit.ValueChangedFcn = createCallbackFcn(app, @xRangeEdit_Callback, true);
            app.RangeofbairgapironsplitEditFieldLabel = uilabel(app.PreliminaryDesignPanel);
            app.RangeofbairgapironsplitEditFieldLabel.Text = 'Range of b (airgap/iron split)';
            app.bRangeEdit = uieditfield(app.PreliminaryDesignPanel, 'text');
            app.bRangeEdit.ValueChangedFcn = createCallbackFcn(app, @bRangeEdit_Callback, true);
            app.CurrentoverloadpuEditFieldLabel = uilabel(app.PreliminaryDesignPanel);
            app.CurrentoverloadpuEditFieldLabel.Text = 'Current overload [p.u.]';
            app.CurrLoXBEdit = uieditfield(app.PreliminaryDesignPanel, 'text');
            app.CurrLoXBEdit.ValueChangedFcn = createCallbackFcn(app, @CurrLoXBEdit_Callback, true);
            app.IronLoadingTEditFieldLabel = uilabel(app.PreliminaryDesignPanel);
            app.IronLoadingTEditFieldLabel.Text = 'Iron Loading [T]';
            app.BfeEdit = uieditfield(app.PreliminaryDesignPanel, 'text');
            app.BfeEdit.ValueChangedFcn = createCallbackFcn(app, @BfeEdit_Callback, true);
            app.ToothsizefactorpuLabel = uilabel(app.PreliminaryDesignPanel);
            app.ToothsizefactorpuLabel.Text = 'Tooth size factor [p.u.]';
            app.ktEdit = uieditfield(app.PreliminaryDesignPanel, 'text');
            app.ktEdit.ValueChangedFcn = createCallbackFcn(app, @ktEdit_Callback, true);
            app.ofFEAfixsimulationsDropDownLabel = uilabel(app.PreliminaryDesignPanel);
            app.ofFEAfixsimulationsDropDownLabel.Text = '# of FEAfix simulations';
            app.FEAfixPopUp = uidropdown(app.PreliminaryDesignPanel);
            app.FEAfixPopUp.Items = {'1', '4', '5', '8', '16', '1000'};
            app.FEAfixPopUp.ValueChangedFcn = createCallbackFcn(app, @FEAfixPopUp_Callback, true);
            app.FEAfixPopUp.Value = '1';
            app.syrmDesignPushButt = uibutton(app.PreliminaryDesignPanel, 'push');
            app.syrmDesignPushButt.ButtonPushedFcn = createCallbackFcn(app, @syrmDesignPushButt_Callback, true);
            app.syrmDesignPushButt.Text = 'syrmDesign(x,b)';
            app.FEAfixPushButt = uibutton(app.PreliminaryDesignPanel, 'push');
            app.FEAfixPushButt.ButtonPushedFcn = createCallbackFcn(app, @FEAfixPushButt_Callback, true);
            app.FEAfixPushButt.Text = 'FEAfix';
            app.BarrierDesignDropDow = uidropdown(app.PreliminaryDesignPanel);
            app.BarrierDesignDropDow.Items = {'Constant flux barrier thickness', 'Constant flux barrier permeance', 'Lfq minimization'};
            app.BarrierDesignDropDow.ValueChangedFcn = createCallbackFcn(app, @BarrierDesignDropDowValueChanged, true);
            app.BarrierDesignDropDow.Value = 'Constant flux barrier permeance';
            app.syrmDesignThermalInputDropDown = uidropdown(app.PreliminaryDesignPanel);
            app.syrmDesignThermalInputDropDown.Items = {'Constant thermal loading kj', 'Constant current density J'};
            app.syrmDesignThermalInputDropDown.ValueChangedFcn = createCallbackFcn(app, @syrmDesignThermalInputDropDownValueChanged, true);
            app.syrmDesignThermalInputDropDown.Value = 'Constant thermal loading kj';
            app.CarrierDesignDropDown = uidropdown(app.PreliminaryDesignPanel);
            app.CarrierDesignDropDown.Items = {'No flux carrier design', 'Constant flux carrier thickness', 'Flux carrier thickness proportional to d-flux'};
            app.CarrierDesignDropDown.ValueChangedFcn = createCallbackFcn(app, @CarrierDesignDropDownValueChanged, true);
            app.CarrierDesignDropDown.Value = 'Constant flux carrier thickness';
            app.syrmDesignGammaFixDropDown = uidropdown(app.PreliminaryDesignPanel);
            app.syrmDesignGammaFixDropDown.Items = {'Current angle imposed', 'FEAfix MTPA correction'};
            app.syrmDesignGammaFixDropDown.ValueChangedFcn = createCallbackFcn(app, @syrmDesignGammaFixDropDownValueChanged, true);
            app.syrmDesignGammaFixDropDown.Value = 'Current angle imposed';
            app.SaturationFactorDropDown = uidropdown(app.PreliminaryDesignPanel);
            app.SaturationFactorDropDown.Items = {'Neglect saturation factor', 'Compute saturation factor'};
            app.SaturationFactorDropDown.ValueChangedFcn = createCallbackFcn(app, @SaturationFactorDropDownValueChanged, true);
            app.SaturationFactorDropDown.Value = 'Neglect saturation factor';
            app.RotoryokefactorpuEditFieldLabel = uilabel(app.PreliminaryDesignPanel);
            app.RotoryokefactorpuEditFieldLabel.Text = 'Rotor yoke factor [p.u.]';
            app.RotoryokefactorpuEditField = uieditfield(app.PreliminaryDesignPanel, 'text');
            app.RotoryokefactorpuEditField.ValueChangedFcn = createCallbackFcn(app, @RotoryokefactorpuEditFieldValueChanged, true);
            app.CustomPanel = uipanel(app.MainDataTab);
            app.CustomPanel.Title = 'Custom Geometry';
            app.StateLamp = uilamp(app.CustomPanel);
            app.CustomLoad = uibutton(app.CustomPanel, 'push');
            app.CustomLoad.ButtonPushedFcn = createCallbackFcn(app, @CustomLoadButtonPushed, true);
            app.CustomLoad.Text = 'Import from FEMM';
            app.CustomClear = uibutton(app.CustomPanel, 'push');
            app.CustomClear.ButtonPushedFcn = createCallbackFcn(app, @CustomClearButtonPushed, true);
            app.CustomClear.Text = 'Clear';
            app.ScaleCheck = uibutton(app.MainDataTab, 'state');
            app.ScaleCheck.ValueChangedFcn = createCallbackFcn(app, @ScaleCheckValueChanged, true);
            app.ScaleCheck.Text = 'Scale';
            app.GeometryTab = uitab(app.TabGroup);
            app.GeometryTab.Title = 'Geometry';
            app.RotorParametersPanel = uipanel(app.GeometryTab);
            app.RotorParametersPanel.Title = 'Rotor Parameters';
            app.NumberofrotorbarriersEditFieldLabel = uilabel(app.RotorParametersPanel);
            app.NumberofrotorbarriersEditFieldLabel.Text = 'Number of rotor barriers';
            app.NumberOfLayersEdit = uieditfield(app.RotorParametersPanel, 'text');
            app.NumberOfLayersEdit.ValueChangedFcn = createCallbackFcn(app, @NumberOfLayersEdit_Callback, true);
            app.BarriersanglesalphapuEditFieldLabel = uilabel(app.RotorParametersPanel);
            app.BarriersanglesalphapuEditFieldLabel.Text = 'Barriers angles alpha [p.u.]';
            app.AlphapuEdit = uieditfield(app.RotorParametersPanel, 'text');
            app.AlphapuEdit.ValueChangedFcn = createCallbackFcn(app, @AlphapuEdit_Callback, true);
            app.BarriersanglesalphaEditFieldLabel = uilabel(app.RotorParametersPanel);
            app.BarriersanglesalphaEditFieldLabel.Text = 'Barriers angles alpha [°]';
            app.AlphadegreeEdit = uieditfield(app.RotorParametersPanel, 'text');
            app.AlphadegreeEdit.ValueChangedFcn = createCallbackFcn(app, @AlphadegreeEdit_Callback, true);
            app.BarrierswidthpuLabel = uilabel(app.RotorParametersPanel);
            app.BarrierswidthpuLabel.Text = 'Barriers width [p.u.]';
            app.hcpuEdit = uieditfield(app.RotorParametersPanel, 'text');
            app.hcpuEdit.ValueChangedFcn = createCallbackFcn(app, @hcpuEdit_Callback, true);
            app.BarrierswidthmmLabel = uilabel(app.RotorParametersPanel);
            app.BarrierswidthmmLabel.Text = 'Barriers width [mm]';
            app.hcmmEdit = uieditfield(app.RotorParametersPanel, 'text');
            app.hcmmEdit.ValueChangedFcn = createCallbackFcn(app, @hcmmEdit_Callback, true);
            app.BarriersoffsetpuLabel = uilabel(app.RotorParametersPanel);
            app.BarriersoffsetpuLabel.Text = 'Barriers offset [p.u.]';
            app.DxEdit = uieditfield(app.RotorParametersPanel, 'text');
            app.DxEdit.ValueChangedFcn = createCallbackFcn(app, @DxEdit_Callback, true);
            app.PMshapefactorbetapuEditFieldLabel = uilabel(app.RotorParametersPanel);
            app.PMshapefactorbetapuEditFieldLabel.Text = 'PM shape factor beta [p.u.]';
            app.BetaEdit = uieditfield(app.RotorParametersPanel, 'text');
            app.BetaEdit.ValueChangedFcn = createCallbackFcn(app, @BetaEdit_Callback, true);
            app.ThetaFBSmechEditFieldLabel = uilabel(app.RotorParametersPanel);
            app.ThetaFBSmechEditFieldLabel.Text = 'Theta FBS [mech °]';
            app.ThetaFBSEdit = uieditfield(app.RotorParametersPanel, 'text');
            app.ThetaFBSEdit.ValueChangedFcn = createCallbackFcn(app, @ThetaFBSEdit_Callback, true);
            app.InnerbranchradialshiftmmLabel = uilabel(app.RotorParametersPanel);
            app.InnerbranchradialshiftmmLabel.Text = 'Inner branch radial shift [mm]';
            app.RadShiftInnerEdit = uieditfield(app.RotorParametersPanel, 'text');
            app.RadShiftInnerEdit.ValueChangedFcn = createCallbackFcn(app, @RadShiftInnerEditValueChanged, true);
            app.OuterbranchnarrowingfactorpuLabel = uilabel(app.RotorParametersPanel);
            app.OuterbranchnarrowingfactorpuLabel.Text = 'Outer branch narrowing factor [p.u.]';
            app.NarrowFactorEdit = uieditfield(app.RotorParametersPanel, 'text');
            app.NarrowFactorEdit.ValueChangedFcn = createCallbackFcn(app, @NarrowFactorEditValueChanged, true);
            app.CentralbarriersshrinkpuLabel = uilabel(app.RotorParametersPanel);
            app.CentralbarriersshrinkpuLabel.Text = 'Central barriers shrink [p.u.]';
            app.CentralBarriersShrinkEdit = uieditfield(app.RotorParametersPanel, 'text');
            app.CentralBarriersShrinkEdit.ValueChangedFcn = createCallbackFcn(app, @CentralBarriersShrinkEditValueChanged, true);
            app.StatorParametersPanel = uipanel(app.GeometryTab);
            app.StatorParametersPanel.Title = 'Stator Parameters';
            app.ToothlengthmmEditFieldLabel = uilabel(app.StatorParametersPanel);
            app.ToothlengthmmEditFieldLabel.Text = 'Tooth length [mm]';
            app.ToothLengEdit = uieditfield(app.StatorParametersPanel, 'text');
            app.ToothLengEdit.ValueChangedFcn = createCallbackFcn(app, @ToothLengEdit_Callback, true);
            app.ToothwidthmmEditFieldLabel = uilabel(app.StatorParametersPanel);
            app.ToothwidthmmEditFieldLabel.Text = 'Tooth width [mm]';
            app.ToothWidthEdit = uieditfield(app.StatorParametersPanel, 'text');
            app.ToothWidthEdit.ValueChangedFcn = createCallbackFcn(app, @ToothWidthEdit_Callback, true);
            app.StatorslotopeningpuEditFieldLabel = uilabel(app.StatorParametersPanel);
            app.StatorslotopeningpuEditFieldLabel.Text = 'Stator slot opening [p.u.]';
            app.StatorSlotOpeEdit = uieditfield(app.StatorParametersPanel, 'text');
            app.StatorSlotOpeEdit.ValueChangedFcn = createCallbackFcn(app, @StatorSlotOpeEdit_Callback, true);
            app.SlotshapeDropDownLabel = uilabel(app.StatorParametersPanel);
            app.SlotshapeDropDownLabel.Text = 'Slot shape';
            app.SlotshapeDropDown = uidropdown(app.StatorParametersPanel);
            app.SlotshapeDropDown.Items = {'Trapezoidal', 'Rectangular'};
            app.SlotshapeDropDown.ValueChangedFcn = createCallbackFcn(app, @SlotshapeDropDownValueChanged, true);
            app.SlotshapeDropDown.Value = 'Trapezoidal';
            app.ToothtangdepthmmEditFieldLabel = uilabel(app.StatorParametersPanel);
            app.ToothtangdepthmmEditFieldLabel.Text = 'Tooth tang. depth [mm]';
            app.ToothTanDepEdit = uieditfield(app.StatorParametersPanel, 'text');
            app.ToothTanDepEdit.ValueChangedFcn = createCallbackFcn(app, @ToothTanDepEdit_Callback, true);
            app.ToothtangangleEditFieldLabel = uilabel(app.StatorParametersPanel);
            app.ToothtangangleEditFieldLabel.Text = 'Tooth tang. angle [°]';
            app.ToothTangAngleEdit = uieditfield(app.StatorParametersPanel, 'text');
            app.ToothTangAngleEdit.ValueChangedFcn = createCallbackFcn(app, @ToothTangAngleEdit_Callback, true);
            app.FilletatslotbottommmEditFieldLabel = uilabel(app.StatorParametersPanel);
            app.FilletatslotbottommmEditFieldLabel.Text = 'Fillet at slot bottom [mm]';
            app.FillCorSlotEdit = uieditfield(app.StatorParametersPanel, 'text');
            app.FillCorSlotEdit.ValueChangedFcn = createCallbackFcn(app, @FillCorSlotEdit_Callback, true);
            app.SlotwidthmmLabel = uilabel(app.StatorParametersPanel);
            app.SlotwidthmmLabel.Text = 'Slot width [mm]';
            app.SlotWidthEdit = uieditfield(app.StatorParametersPanel, 'text');
            app.SlotWidthEdit.ValueChangedFcn = createCallbackFcn(app, @SlotWidthEditValueChanged, true);
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.ThermalParametersPanel = uipanel(app.OptionsTab);
            app.ThermalParametersPanel.Title = 'Thermal Parameters';
            app.ThermalloadingkjWm2Label = uilabel(app.ThermalParametersPanel);
            app.ThermalloadingkjWm2Label.Text = 'Thermal loading kj [W/m2]';
            app.ThermalLoadKj = uieditfield(app.ThermalParametersPanel, 'text');
            app.ThermalLoadKj.ValueChangedFcn = createCallbackFcn(app, @ThermalLoadKj_Callback, true);
            app.TargetcoppertemperatureCLabel = uilabel(app.ThermalParametersPanel);
            app.TargetcoppertemperatureCLabel.Text = 'Target copper temperature [°C]';
            app.CopperTempEdit = uieditfield(app.ThermalParametersPanel, 'text');
            app.CopperTempEdit.ValueChangedFcn = createCallbackFcn(app, @CopperTempEdit_Callback, true);
            app.CalculatedratedcurrentALabel = uilabel(app.ThermalParametersPanel);
            app.CalculatedratedcurrentALabel.Text = 'Calculated rated current [A]';
            app.CalculatedRatedCurrent = uieditfield(app.ThermalParametersPanel, 'text');
            app.HousingtemperatureCEditFieldLabel = uilabel(app.ThermalParametersPanel);
            app.HousingtemperatureCEditFieldLabel.Text = 'Housing temperature [°C]';
            app.HousingTempEdit = uieditfield(app.ThermalParametersPanel, 'text');
            app.HousingTempEdit.ValueChangedFcn = createCallbackFcn(app, @HousingTempEdit_Callback, true);
            app.AdmittedcopperlossWLabel = uilabel(app.ThermalParametersPanel);
            app.AdmittedcopperlossWLabel.Text = 'Admitted copper loss [W]';
            app.JouleLossesEdit = uieditfield(app.ThermalParametersPanel, 'text');
            app.JouleLossesEdit.ValueChangedFcn = createCallbackFcn(app, @JouleLossesEditValueChanged, true);
            app.EstimatedcoppertemperatureCLabel = uilabel(app.ThermalParametersPanel);
            app.EstimatedcoppertemperatureCLabel.Text = 'Estimated copper temperature [°C]';
            app.EstimatedCoppTemp = uieditfield(app.ThermalParametersPanel, 'text');
            app.PhresistancetargettemperatureOhmLabel = uilabel(app.ThermalParametersPanel);
            app.PhresistancetargettemperatureOhmLabel.Text = 'Ph. resistance @target temperature [Ohm]';
            app.Rsedit = uieditfield(app.ThermalParametersPanel, 'text');
            app.RMScurrentdensityAmm2Label = uilabel(app.ThermalParametersPanel);
            app.RMScurrentdensityAmm2Label.Text = 'RMS current density [A/mm^2]';
            app.CurrentdensityEdit = uieditfield(app.ThermalParametersPanel, 'text');
            app.CurrentdensityEdit.ValueChangedFcn = createCallbackFcn(app, @CurrentdensityEditValueChanged, true);
            app.MeshControlPanel = uipanel(app.OptionsTab);
            app.MeshControlPanel.Title = 'Mesh Control';
            app.MeshLabel = uilabel(app.MeshControlPanel);
            app.MeshLabel.Text = 'Mesh';
            app.MeshEdit = uieditfield(app.MeshControlPanel, 'text');
            app.MeshEdit.ValueChangedFcn = createCallbackFcn(app, @MeshEdit_Callback, true);
            app.Mesh_MOOALabel = uilabel(app.MeshControlPanel);
            app.Mesh_MOOALabel.Text = 'Mesh_MOOA';
            app.MeshMOOAEdit = uieditfield(app.MeshControlPanel, 'text');
            app.MeshMOOAEdit.ValueChangedFcn = createCallbackFcn(app, @MeshMOOAEdit_Callback, true);
            app.StructuralParametersPanel = uipanel(app.OptionsTab);
            app.StructuralParametersPanel.Title = 'Structural Parameters';
            app.OverspeedrpmLabel = uilabel(app.StructuralParametersPanel);
            app.OverspeedrpmLabel.Text = 'Overspeed [rpm]';
            app.OverSpeedEdit = uieditfield(app.StructuralParametersPanel, 'text');
            app.OverSpeedEdit.ValueChangedFcn = createCallbackFcn(app, @OverSpeedEdit_Callback, true);
            app.MinimummechtolerancemmLabel = uilabel(app.StructuralParametersPanel);
            app.MinimummechtolerancemmLabel.Text = 'Minimum mech. tolerance [mm]';
            app.MecTolerEdit = uieditfield(app.StructuralParametersPanel, 'text');
            app.MecTolerEdit.ValueChangedFcn = createCallbackFcn(app, @MecTolerEdit_Callback, true);
            app.RibsDesignPanel = uipanel(app.OptionsTab);
            app.RibsDesignPanel.Title = 'Ribs Design';
            app.TangentialribswidthmmEditFieldLabel = uilabel(app.RibsDesignPanel);
            app.TangentialribswidthmmEditFieldLabel.Text = 'Tangential ribs width [mm]';
            app.TanRibEdit = uieditfield(app.RibsDesignPanel, 'text');
            app.TanRibEdit.ValueChangedFcn = createCallbackFcn(app, @TanRibEdit_Callback, true);
            app.RadialribswidthmmEditFieldLabel = uilabel(app.RibsDesignPanel);
            app.RadialribswidthmmEditFieldLabel.Text = 'Radial ribs width [mm]';
            app.RadRibEdit = uieditfield(app.RibsDesignPanel, 'text');
            app.RadRibEdit.ValueChangedFcn = createCallbackFcn(app, @RadRibEdit_Callback, true);
            app.RotorfilletmmEditFieldLabel = uilabel(app.RibsDesignPanel);
            app.RotorfilletmmEditFieldLabel.Text = 'Tangential ribs fillet in [mm]';
            app.RotorFilletTan1EditField = uieditfield(app.RibsDesignPanel, 'text');
            app.RotorFilletTan1EditField.ValueChangedFcn = createCallbackFcn(app, @RotorFilletTan1EditFieldValueChanged, true);
            app.TangentialribswidthmmEditFieldLabel_2 = uilabel(app.RibsDesignPanel);
            app.TangentialribswidthmmEditFieldLabel_2.Text = 'Radial ribs angle [deg] ';
            app.RadialRibsAngleEditField = uieditfield(app.RibsDesignPanel, 'text');
            app.RadialRibsAngleEditField.ValueChangedFcn = createCallbackFcn(app, @RadialRibsAngleEditFieldValueChanged, true);
            app.TangentialribswidthmmEditFieldLabel_3 = uilabel(app.RibsDesignPanel);
            app.TangentialribswidthmmEditFieldLabel_3.Text = 'Radial ribs offset [mm]';
            app.RadialRibsOffsetEditField = uieditfield(app.RibsDesignPanel, 'text');
            app.RadialRibsOffsetEditField.ValueChangedFcn = createCallbackFcn(app, @RadialRibsOffsetEditFieldValueChanged, true);
            app.RotorfilletmmEditFieldLabel_2 = uilabel(app.RibsDesignPanel);
            app.RotorfilletmmEditFieldLabel_2.Text = 'Radial ribs fillet in [mm]';
            app.RotorFilletRadInEditField = uieditfield(app.RibsDesignPanel, 'text');
            app.RotorFilletRadInEditField.ValueChangedFcn = createCallbackFcn(app, @RotorFilletRadInEditFieldValueChanged, true);
            app.RotorfilletmmEditFieldLabel_ = uilabel(app.RibsDesignPanel);
            app.RotorfilletmmEditFieldLabel_.Text = 'Split ribs';
            app.RadRibCheck = uibutton(app.RibsDesignPanel, 'state');
            app.RadRibCheck.ValueChangedFcn = createCallbackFcn(app, @RadRibCheck_Callback, true);
            app.RadRibCheck.Text = 'Manual rad ribs';
            app.RotorfilletmmEditFieldLabel_4 = uilabel(app.RibsDesignPanel);
            app.RotorfilletmmEditFieldLabel_4.Text = 'Radial ribs fillet out [mm]';
            app.RotorFilletRadOutEditField = uieditfield(app.RibsDesignPanel, 'text');
            app.RotorFilletRadOutEditField.ValueChangedFcn = createCallbackFcn(app, @RotorFilletRadOutEditFieldValueChanged, true);
            app.SplitRibsEditField = uieditfield(app.RibsDesignPanel, 'text');
            app.SplitRibsEditField.ValueChangedFcn = createCallbackFcn(app, @SplitRibsEditFieldValueChanged, true);
            app.RotorFilletTan2EditField = uieditfield(app.RibsDesignPanel, 'text');
            app.RotorFilletTan2EditField.ValueChangedFcn = createCallbackFcn(app, @RotorFilletTan2EditFieldValueChanged, true);
            app.RotorfilletmmEditFieldLabel_5 = uilabel(app.RibsDesignPanel);
            app.RotorfilletmmEditFieldLabel_5.Text = 'Tangential ribs fillet out [mm]';
            app.TanRibCheck = uibutton(app.RibsDesignPanel, 'state');
            app.TanRibCheck.ValueChangedFcn = createCallbackFcn(app, @TanRibCheckValueChanged, true);
            app.TanRibCheck.Text = 'Manual tan ribs';
            app.WindingsTab = uitab(app.TabGroup);
            app.WindingsTab.Title = 'Windings';
            app.SlotfillingfactorEditFieldLabel = uilabel(app.WindingsTab);
            app.SlotfillingfactorEditFieldLabel.Text = 'Slot filling factor';
            app.SlotFillFacEdit = uieditfield(app.WindingsTab, 'text');
            app.SlotFillFacEdit.ValueChangedFcn = createCallbackFcn(app, @SlotFillFacEdit_Callback, true);
            app.TurnsinseriesperphaseEditFieldLabel = uilabel(app.WindingsTab);
            app.TurnsinseriesperphaseEditFieldLabel.Text = 'Turns in series per phase';
            app.TurnsSeriesEdit = uieditfield(app.WindingsTab, 'text');
            app.TurnsSeriesEdit.ValueChangedFcn = createCallbackFcn(app, @TurnsSeriesEdit_Callback, true);
            app.PitchshorteningfactorEditFieldLabel = uilabel(app.WindingsTab);
            app.PitchshorteningfactorEditFieldLabel.Text = 'Pitch shortening factor';
            app.PitchWindEdit = uieditfield(app.WindingsTab, 'text');
            app.PitchWindEdit.ValueChangedFcn = createCallbackFcn(app, @PitchWindEdit_Callback, true);
            app.SlotlayerpositionDropDownLabel = uilabel(app.WindingsTab);
            app.SlotlayerpositionDropDownLabel.Text = 'Slot layer position';
            app.SlotlayerposDropDown = uidropdown(app.WindingsTab);
            app.SlotlayerposDropDown.Items = {'Stacked', 'Side-by-side'};
            app.SlotlayerposDropDown.ValueChangedFcn = createCallbackFcn(app, @SlotlayerposDropDownValueChanged, true);
            app.SlotlayerposDropDown.Value = 'Stacked';
            app.NumberofsimulatedslotsEditFieldLabel = uilabel(app.WindingsTab);
            app.NumberofsimulatedslotsEditFieldLabel.Text = 'Number of simulated slots';
            app.SlotSimulEdit = uieditfield(app.WindingsTab, 'text');
            app.SlotSimulEdit.ValueChangedFcn = createCallbackFcn(app, @SlotSimulEdit_Callback, true);
            app.Numberof3phasesetsEditFieldLabel = uilabel(app.WindingsTab);
            app.Numberof3phasesetsEditFieldLabel.Text = 'Number of 3-phase sets';
            app.Num3PhaseCircuitEdit = uieditfield(app.WindingsTab, 'text');
            app.Num3PhaseCircuitEdit.ValueChangedFcn = createCallbackFcn(app, @Num3PhaseCircuitEdit_Callback, true);
            app.WinTable = uitable(app.WindingsTab);
            app.WinTable.ColumnName = {'1'; '2'};
            app.WinTable.ColumnEditable = [true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true];
            app.SaveConfPush = uibutton(app.WindingsTab, 'push');
            app.SaveConfPush.ButtonPushedFcn = createCallbackFcn(app, @SaveConfPush_Callback, true);
            app.SaveConfPush.Text = 'Save Configuration';
            app.SetDefaultWinding = uibutton(app.WindingsTab, 'push');
            app.SetDefaultWinding.ButtonPushedFcn = createCallbackFcn(app, @SetDefaultWinding_Callback, true);
            app.SetDefaultWinding.Text = 'Default';
            app.SlotModelPanel = uipanel(app.WindingsTab);
            app.SlotModelPanel.Title = 'Slot Model';
            app.ConductortypeDropDownLabel = uilabel(app.SlotModelPanel);
            app.ConductortypeDropDownLabel.Text = 'Conductor type';
            app.ConductorTypeEdit = uidropdown(app.SlotModelPanel);
            app.ConductorTypeEdit.Items = {'Round', 'Square'};
            app.ConductorTypeEdit.ValueChangedFcn = createCallbackFcn(app, @ConductorTypeEdit_Callback, true);
            app.ConductorTypeEdit.Value = 'Round';
            app.InsulationthicknessmmEditFieldLabel = uilabel(app.SlotModelPanel);
            app.InsulationthicknessmmEditFieldLabel.Text = 'Insulation thickness [mm]';
            app.ConductorInsulationEdit = uieditfield(app.SlotModelPanel, 'text');
            app.ConductorInsulationEdit.ValueChangedFcn = createCallbackFcn(app, @ConductorInsulationEdit_Callback, true);
            app.NumberofconductorsEditFieldLabel = uilabel(app.SlotModelPanel);
            app.NumberofconductorsEditFieldLabel.Text = 'Number of conductors';
            app.ConductorNumberEdit = uieditfield(app.SlotModelPanel, 'text');
            app.ConductorNumberEdit.ValueChangedFcn = createCallbackFcn(app, @ConductorNumberEdit_Callback, true);
            app.ConductorradiusmmEditFieldLabel = uilabel(app.SlotModelPanel);
            app.ConductorradiusmmEditFieldLabel.Text = 'Conductor radius [mm]';
            app.ConductorRadiusEdit = uieditfield(app.SlotModelPanel, 'text');
            app.ConductorRadiusEdit.ValueChangedFcn = createCallbackFcn(app, @ConductorRadiusEdit_Callback, true);
            app.ConductorwidthmmEditFieldLabel = uilabel(app.SlotModelPanel);
            app.ConductorwidthmmEditFieldLabel.Text = 'Conductor width [mm]';
            app.ConductorWidthEdit = uieditfield(app.SlotModelPanel, 'text');
            app.ConductorWidthEdit.ValueChangedFcn = createCallbackFcn(app, @ConductorWidthEdit_Callback, true);
            app.ConductorheightmmEditFieldLabel = uilabel(app.SlotModelPanel);
            app.ConductorheightmmEditFieldLabel.Text = 'Conductor height [mm]';
            app.ConductorHeightEdit = uieditfield(app.SlotModelPanel, 'text');
            app.ConductorHeightEdit.ValueChangedFcn = createCallbackFcn(app, @ConductorHeightEdit_Callback, true);
            app.FrequencyvectorHzEditFieldLabel = uilabel(app.SlotModelPanel);
            app.FrequencyvectorHzEditFieldLabel.Text = 'Frequency vector [Hz]';
            app.SlotModelFrequencyEdit = uieditfield(app.SlotModelPanel, 'text');
            app.SlotModelFrequencyEdit.ValueChangedFcn = createCallbackFcn(app, @SlotModelFrequencyEdit_Callback, true);
            app.DrawSlotModelPush = uibutton(app.SlotModelPanel, 'push');
            app.DrawSlotModelPush.ButtonPushedFcn = createCallbackFcn(app, @DrawSlotModelPush_Callback, true);
            app.DrawSlotModelPush.Text = 'Draw slot model';
            app.EvalSlotModelPush = uibutton(app.SlotModelPanel, 'push');
            app.EvalSlotModelPush.ButtonPushedFcn = createCallbackFcn(app, @EvalSlotModelPush_Callback, true);
            app.EvalSlotModelPush.Text = 'Evaluate slot model';
            app.TemperaturevectorCLabel = uilabel(app.SlotModelPanel);
            app.TemperaturevectorCLabel.Text = 'Temperature vector [°C]';
            app.SlotModelTemperatureEdit = uieditfield(app.SlotModelPanel, 'text');
            app.SlotModelTemperatureEdit.ValueChangedFcn = createCallbackFcn(app, @SlotModelTemperatureEditValueChanged, true);
            app.ConductorslotgapmmLabel = uilabel(app.SlotModelPanel);
            app.ConductorslotgapmmLabel.Text = 'Conductor-slot gap [mm]';
            app.ConductorSlotGapEdit = uieditfield(app.SlotModelPanel, 'text');
            app.ConductorSlotGapEdit.ValueChangedFcn = createCallbackFcn(app, @ConductorSlotGapEditValueChanged, true);
            app.NumberofparallelsLabel = uilabel(app.SlotModelPanel);
            app.NumberofparallelsLabel.Text = 'Number of parallels';
            app.ParallelNumberEdit = uieditfield(app.SlotModelPanel, 'text');
            app.ParallelNumberEdit.ValueChangedFcn = createCallbackFcn(app, @ParallelNumberEditValueChanged, true);
            app.MaterialsTab_2 = uitab(app.TabGroup);
            app.MaterialsTab_2.Title = 'Materials';
            app.IronLibraryPush = uibutton(app.MaterialsTab_2, 'push');
            app.IronLibraryPush.ButtonPushedFcn = createCallbackFcn(app, @IronLibraryPush_Callback, true);
            app.IronLibraryPush.Text = 'Iron';
            app.ConductorLibraryPush = uibutton(app.MaterialsTab_2, 'push');
            app.ConductorLibraryPush.ButtonPushedFcn = createCallbackFcn(app, @ConductorLibraryPush_Callback, true);
            app.ConductorLibraryPush.Text = 'Conductor';
            app.BarrierLibraryPush = uibutton(app.MaterialsTab_2, 'push');
            app.BarrierLibraryPush.ButtonPushedFcn = createCallbackFcn(app, @BarrierLibraryPush_Callback, true);
            app.BarrierLibraryPush.Text = 'Magnet';
            app.ViewPropPush = uibutton(app.MaterialsTab_2, 'push');
            app.ViewPropPush.ButtonPushedFcn = createCallbackFcn(app, @ViewPropPush_Callback, true);
            app.ViewPropPush.Text = 'View material properties';
            app.AddIronPush = uibutton(app.MaterialsTab_2, 'push');
            app.AddIronPush.ButtonPushedFcn = createCallbackFcn(app, @AddIronPush_Callback, true);
            app.AddIronPush.Text = 'Add';
            app.AddConductorPush = uibutton(app.MaterialsTab_2, 'push');
            app.AddConductorPush.ButtonPushedFcn = createCallbackFcn(app, @AddConductorPush_Callback, true);
            app.AddConductorPush.Text = 'Add';
            app.AddMagnetPush = uibutton(app.MaterialsTab_2, 'push');
            app.AddMagnetPush.ButtonPushedFcn = createCallbackFcn(app, @AddMagnetPush_Callback, true);
            app.AddMagnetPush.Text = 'Add';
            app.RmvIronPush = uibutton(app.MaterialsTab_2, 'push');
            app.RmvIronPush.ButtonPushedFcn = createCallbackFcn(app, @RmvIronPush_Callback, true);
            app.RmvIronPush.Text = 'Remove';
            app.RmvConductorPush = uibutton(app.MaterialsTab_2, 'push');
            app.RmvConductorPush.ButtonPushedFcn = createCallbackFcn(app, @RmvConductorPush_Callback, true);
            app.RmvConductorPush.Text = 'Remove';
            app.RmvMagnetPush = uibutton(app.MaterialsTab_2, 'push');
            app.RmvMagnetPush.ButtonPushedFcn = createCallbackFcn(app, @RmvMagnetPush_Callback, true);
            app.RmvMagnetPush.Text = 'Remove';
            app.MaterialListBox = uitextarea(app.MaterialsTab_2);
            app.StatorslotmaterialDropDownLabel = uilabel(app.MaterialsTab_2);
            app.StatorslotmaterialDropDownLabel.Text = 'Stator slot material';
            app.StatorslotmaterialDropDown = uidropdown(app.MaterialsTab_2);
            app.StatorslotmaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @StatorslotmaterialDropDownValueChanged, true);
            app.StatorcorematerialDropDownLabel = uilabel(app.MaterialsTab_2);
            app.StatorcorematerialDropDownLabel.Text = 'Stator core material';
            app.StatorcorematerialDropDown = uidropdown(app.MaterialsTab_2);
            app.StatorcorematerialDropDown.ValueChangedFcn = createCallbackFcn(app, @StatorcorematerialDropDownValueChanged, true);
            app.RotorcorematerialDropDownLabel = uilabel(app.MaterialsTab_2);
            app.RotorcorematerialDropDownLabel.Text = 'Rotor core material';
            app.RotorcorematerialDropDown = uidropdown(app.MaterialsTab_2);
            app.RotorcorematerialDropDown.ValueChangedFcn = createCallbackFcn(app, @RotorcorematerialDropDownValueChanged, true);
            app.FluxbarriermaterialDropDownLabel = uilabel(app.MaterialsTab_2);
            app.FluxbarriermaterialDropDownLabel.Text = 'Flux barrier material';
            app.FluxbarriermaterialDropDown = uidropdown(app.MaterialsTab_2);
            app.FluxbarriermaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @FluxbarriermaterialDropDownValueChanged, true);
            app.ShaftmaterialDropDownLabel = uilabel(app.MaterialsTab_2);
            app.ShaftmaterialDropDownLabel.Text = 'Shaft material';
            app.ShaftmaterialDropDown = uidropdown(app.MaterialsTab_2);
            app.ShaftmaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @ShaftmaterialDropDownValueChanged, true);
            app.MassWindingEditField = uieditfield(app.MaterialsTab_2, 'text');
            app.kgLabel = uilabel(app.MaterialsTab_2);
            app.kgLabel.Text = '[kg]';
            app.MassStatorIronEditField = uieditfield(app.MaterialsTab_2, 'text');
            app.kgLabel_2 = uilabel(app.MaterialsTab_2);
            app.kgLabel_2.Text = '[kg]';
            app.MassRotorIronEditField = uieditfield(app.MaterialsTab_2, 'text');
            app.kgLabel_3 = uilabel(app.MaterialsTab_2);
            app.kgLabel_3.Text = '[kg]';
            app.MassFluxBarrierEditField = uieditfield(app.MaterialsTab_2, 'text');
            app.kgLabel_4 = uilabel(app.MaterialsTab_2);
            app.kgLabel_4.Text = '[kg]';
            app.MassTotalEditField = uieditfield(app.MaterialsTab_2, 'text');
            app.kgLabel_6 = uilabel(app.MaterialsTab_2);
            app.kgLabel_6.Text = '[kg]';
            app.RotorInertiaEditField = uieditfield(app.MaterialsTab_2, 'text');
            app.kgm2Label = uilabel(app.MaterialsTab_2);
            app.kgm2Label.Text = '[kg*m^2]';
            app.TotalmotormassLabel = uilabel(app.MaterialsTab_2);
            app.TotalmotormassLabel.Text = 'Total motor mass';
            app.RotorInertiaLabel = uilabel(app.MaterialsTab_2);
            app.RotorInertiaLabel.Text = 'Rotor Inertia';
            app.MagnetPanel = uipanel(app.MaterialsTab_2);
            app.MagnetPanel.Title = 'Permanent Magnet';
            app.PMDesignPush = uibutton(app.MagnetPanel, 'push');
            app.PMDesignPush.ButtonPushedFcn = createCallbackFcn(app, @PMDesignPush_Callback, true);
            app.PMDesignPush.Text = 'PM Design';
            app.PMdimTable = uitable(app.MagnetPanel);
            app.PMdimTable.ColumnName = {'1'; '2'};
            app.PMdimTable.RowName = {'Central'; 'External'};
            app.PMdimTable.ColumnEditable = [true true true true true];
            app.PMdimTable.CellEditCallback = createCallbackFcn(app, @PMdimTable_CellEditCallback, true);
            app.PMremanenceTEditFieldLabel_3 = uilabel(app.MagnetPanel);
            app.PMremanenceTEditFieldLabel_3.Text = 'PM remanence [T]';
            app.BrPMEdit = uieditfield(app.MagnetPanel, 'text');
            app.BrPMEdit.ValueChangedFcn = createCallbackFcn(app, @BrPMEdit_Callback, true);
            app.PMtemperatureCEditFieldLabel_3 = uilabel(app.MagnetPanel);
            app.PMtemperatureCEditFieldLabel_3.Text = 'PM temperature [°C]';
            app.PMtemperatureEdit = uieditfield(app.MagnetPanel, 'text');
            app.PMtemperatureEdit.ValueChangedFcn = createCallbackFcn(app, @PMtemperatureEdit_Callback, true);
            app.TargetcharacteristiccurrentpuEditFieldLabel = uilabel(app.MagnetPanel);
            app.TargetcharacteristiccurrentpuEditFieldLabel.Text = 'Target characteristic current [p.u.]';
            app.CarCurEdit = uieditfield(app.MagnetPanel, 'text');
            app.CarCurEdit.ValueChangedFcn = createCallbackFcn(app, @CarCurEdit_Callback, true);
            app.PMclearanceTable = uitable(app.MagnetPanel);
            app.PMclearanceTable.ColumnName = {'1'; '2'};
            app.PMclearanceTable.RowName = {'Central'; 'External'};
            app.PMclearanceTable.ColumnEditable = [true true true true true];
            app.PMclearanceTable.CellEditCallback = createCallbackFcn(app, @PMclearanceTableCellEdit, true);
            app.OptimizationTab_2 = uitab(app.TabGroup);
            app.OptimizationTab_2.Title = 'Optimization';
            app.OptimizationParametersPanel = uipanel(app.OptimizationTab_2);
            app.OptimizationParametersPanel.Title = 'Optimization Parameters';
            app.ofgenerationsEditFieldLabel = uilabel(app.OptimizationParametersPanel);
            app.ofgenerationsEditFieldLabel.Text = '# of generations';
            app.MaxGenEdit = uieditfield(app.OptimizationParametersPanel, 'text');
            app.MaxGenEdit.ValueChangedFcn = createCallbackFcn(app, @MaxGenEdit_Callback, true);
            app.PopulationsizeEditFieldLabel = uilabel(app.OptimizationParametersPanel);
            app.PopulationsizeEditFieldLabel.Text = 'Population size';
            app.XPopEdit = uieditfield(app.OptimizationParametersPanel, 'text');
            app.XPopEdit.ValueChangedFcn = createCallbackFcn(app, @XPopEdit_Callback, true);
            app.TimesteppingduringMODEPanel = uipanel(app.OptimizationTab_2);
            app.TimesteppingduringMODEPanel.Title = 'Time stepping during MODE';
            app.RotorangularexcursionEditFieldLabel = uilabel(app.TimesteppingduringMODEPanel);
            app.RotorangularexcursionEditFieldLabel.Text = 'Rotor angular excursion';
            app.RotorPosiMOEdit = uieditfield(app.TimesteppingduringMODEPanel, 'text');
            app.RotorPosiMOEdit.ValueChangedFcn = createCallbackFcn(app, @RotorPosiMOEdit_Callback, true);
            app.ofrotorpositionsEditFieldLabel = uilabel(app.TimesteppingduringMODEPanel);
            app.ofrotorpositionsEditFieldLabel.Text = '# of rotor positions';
            app.SimPosMOEdit = uieditfield(app.TimesteppingduringMODEPanel, 'text');
            app.SimPosMOEdit.ValueChangedFcn = createCallbackFcn(app, @SimPosMOEdit_Callback, true);
            app.TimesteppingforParetofrontreevaluationPanel = uipanel(app.OptimizationTab_2);
            app.TimesteppingforParetofrontreevaluationPanel.Title = 'Time stepping for Pareto front re-evaluation';
            app.RotorangularexcursionEditField_2Label = uilabel(app.TimesteppingforParetofrontreevaluationPanel);
            app.RotorangularexcursionEditField_2Label.Text = 'Rotor angular excursion';
            app.RotorPosFinerEdit = uieditfield(app.TimesteppingforParetofrontreevaluationPanel, 'text');
            app.RotorPosFinerEdit.ValueChangedFcn = createCallbackFcn(app, @RotorPosFinerEdit_Callback, true);
            app.ofrotorpositionsEditField_2Label = uilabel(app.TimesteppingforParetofrontreevaluationPanel);
            app.ofrotorpositionsEditField_2Label.Text = '# of rotor positions';
            app.SimPosFinerEdit = uieditfield(app.TimesteppingforParetofrontreevaluationPanel, 'text');
            app.SimPosFinerEdit.ValueChangedFcn = createCallbackFcn(app, @SimPosFinerEdit_Callback, true);
            app.VariablesandBoundsPanel = uipanel(app.OptimizationTab_2);
            app.VariablesandBoundsPanel.Title = 'Variables and Bounds';
            app.GridLayout = uigridlayout(app.VariablesandBoundsPanel);
            app.AirgapRadiusBouEdit = uieditfield(app.GridLayout, 'text');
            app.AirgapRadiusBouEdit.ValueChangedFcn = createCallbackFcn(app, @AirgapRadiusBouEdit_Callback, true);
            app.AirgapRadiusBouCheck = uibutton(app.GridLayout, 'state');
            app.AirgapRadiusBouCheck.ValueChangedFcn = createCallbackFcn(app, @AirgapRadiusBouCheck_Callback, true);
            app.AirgapRadiusBouCheck.Text = 'Airgap radius [mm]';
            app.ToothWidthBouEdit = uieditfield(app.GridLayout, 'text');
            app.ToothWidthBouEdit.ValueChangedFcn = createCallbackFcn(app, @ToothWidthBouEdit_Callback, true);
            app.ToothWidthBouCheck = uibutton(app.GridLayout, 'state');
            app.ToothWidthBouCheck.ValueChangedFcn = createCallbackFcn(app, @ToothWidthBouCheck_Callback, true);
            app.ToothWidthBouCheck.Text = 'Tooth width [mm]';
            app.ToothLenBouEdit = uieditfield(app.GridLayout, 'text');
            app.ToothLenBouEdit.ValueChangedFcn = createCallbackFcn(app, @ToothLenBouEdit_Callback, true);
            app.ToothLengthBouCheck = uibutton(app.GridLayout, 'state');
            app.ToothLengthBouCheck.ValueChangedFcn = createCallbackFcn(app, @ToothLengthBouCheck_Callback, true);
            app.ToothLengthBouCheck.Text = 'Tooth lenght [mm]';
            app.ToothTangDepthBouEdit = uieditfield(app.GridLayout, 'text');
            app.ToothTangDepthBouEdit.ValueChangedFcn = createCallbackFcn(app, @ToothTangDepthBouEdit_Callback, true);
            app.ToothTangDepthBouCheck = uibutton(app.GridLayout, 'state');
            app.ToothTangDepthBouCheck.ValueChangedFcn = createCallbackFcn(app, @ToothTangDepthBouCheck_Callback, true);
            app.ToothTangDepthBouCheck.Text = 'Tooth tan. depth [mm]';
            app.StatorSlotOpenBouEdit = uieditfield(app.GridLayout, 'text');
            app.StatorSlotOpenBouEdit.ValueChangedFcn = createCallbackFcn(app, @StatorSlotOpenBouEdit_Callback, true);
            app.StatorSlotOpenBouCheck = uibutton(app.GridLayout, 'state');
            app.StatorSlotOpenBouCheck.ValueChangedFcn = createCallbackFcn(app, @StatorSlotOpenBouCheck_Callback, true);
            app.StatorSlotOpenBouCheck.Text = 'Stator slot open [p.u.]';
            app.GapBouEdit = uieditfield(app.GridLayout, 'text');
            app.GapBouEdit.ValueChangedFcn = createCallbackFcn(app, @GapBouEdit_Callback, true);
            app.GapBouCheck = uibutton(app.GridLayout, 'state');
            app.GapBouCheck.ValueChangedFcn = createCallbackFcn(app, @GapBouCheck_Callback, true);
            app.GapBouCheck.Text = 'Airgap thickness [mm]';
            app.Dalpha1BouCheck = uibutton(app.GridLayout, 'state');
            app.Dalpha1BouCheck.ValueChangedFcn = createCallbackFcn(app, @Dalpha1BouCheck_Callback, true);
            app.Dalpha1BouCheck.Text = '1st barrier pos. [p.u.]';
            app.Alpha1BouEdit = uieditfield(app.GridLayout, 'text');
            app.Alpha1BouEdit.ValueChangedFcn = createCallbackFcn(app, @Alpha1BouEdit_Callback, true);
            app.DeltaAlphaBouEdit = uieditfield(app.GridLayout, 'text');
            app.DeltaAlphaBouEdit.ValueChangedFcn = createCallbackFcn(app, @DeltaAlphaBouEdit_Callback, true);
            app.DalphaBouCheck = uibutton(app.GridLayout, 'state');
            app.DalphaBouCheck.ValueChangedFcn = createCallbackFcn(app, @DalphaBouCheck_Callback, true);
            app.DalphaBouCheck.Text = 'Barriers positions [p.u.]';
            app.hcBouEdit = uieditfield(app.GridLayout, 'text');
            app.hcBouEdit.ValueChangedFcn = createCallbackFcn(app, @hcBouEdit_Callback, true);
            app.hcBouCheck = uibutton(app.GridLayout, 'state');
            app.hcBouCheck.ValueChangedFcn = createCallbackFcn(app, @hcBouCheck_Callback, true);
            app.hcBouCheck.Text = 'Barrier width [p.u.]';
            app.DfeBouEdit = uieditfield(app.GridLayout, 'text');
            app.DfeBouEdit.ValueChangedFcn = createCallbackFcn(app, @DfeBouEdit_Callback, true);
            app.DxBouCheck = uibutton(app.GridLayout, 'state');
            app.DxBouCheck.ValueChangedFcn = createCallbackFcn(app, @DxBouCheck_Callback, true);
            app.DxBouCheck.Text = 'Barrier offset [p.u.]';
            app.CentralShrinkBouEdit = uieditfield(app.GridLayout, 'text');
            app.CentralShrinkBouEdit.ValueChangedFcn = createCallbackFcn(app, @CentralShrinkBouEditValueChanged, true);
            app.CentralShrinkBouCheck = uibutton(app.GridLayout, 'state');
            app.CentralShrinkBouCheck.ValueChangedFcn = createCallbackFcn(app, @CentralShrinkBouCheckValueChanged, true);
            app.CentralShrinkBouCheck.Text = 'Barriers shrink [p.u.]';
            app.RadShiftInnerBouEdit = uieditfield(app.GridLayout, 'text');
            app.RadShiftInnerBouEdit.ValueChangedFcn = createCallbackFcn(app, @RadShiftInnerBouEditValueChanged, true);
            app.RadShiftInnerBouCheck = uibutton(app.GridLayout, 'state');
            app.RadShiftInnerBouCheck.ValueChangedFcn = createCallbackFcn(app, @RadShiftInnerBouCheckValueChanged, true);
            app.RadShiftInnerBouCheck.Text = 'Barrier shift [mm]';
            app.ThetaFBSBouEdit = uieditfield(app.GridLayout, 'text');
            app.ThetaFBSBouEdit.ValueChangedFcn = createCallbackFcn(app, @ThetaFBSBouEdit_Callback, true);
            app.ThetaFBSBouCheck = uibutton(app.GridLayout, 'state');
            app.ThetaFBSBouCheck.ValueChangedFcn = createCallbackFcn(app, @ThetaFBSBouCheck_Callback, true);
            app.ThetaFBSBouCheck.Text = 'Theta FBS [mech °]';
            app.PMdimBouEdit = uieditfield(app.GridLayout, 'text');
            app.PMdimBouEdit.ValueChangedFcn = createCallbackFcn(app, @PMdimBouEdit_Callback, true);
            app.PMdimBouCheck = uibutton(app.GridLayout, 'state');
            app.PMdimBouCheck.ValueChangedFcn = createCallbackFcn(app, @PMdimBouCheck_Callback, true);
            app.PMdimBouCheck.Text = 'PM dimension [p.u.]';
            app.BrBouEdit = uieditfield(app.GridLayout, 'text');
            app.BrBouEdit.ValueChangedFcn = createCallbackFcn(app, @BrBouEdit_Callback, true);
            app.BrBouCheck = uibutton(app.GridLayout, 'state');
            app.BrBouCheck.ValueChangedFcn = createCallbackFcn(app, @BrBouCheck_Callback, true);
            app.BrBouCheck.Text = 'PM remanence [T]';
            app.TanRibBouEdit = uieditfield(app.GridLayout, 'text');
            app.TanRibBouEdit.ValueChangedFcn = createCallbackFcn(app, @TanRibBouEditValueChanged, true);
            app.TanRibBouCheck = uibutton(app.GridLayout, 'state');
            app.TanRibBouCheck.ValueChangedFcn = createCallbackFcn(app, @TanRibBouCheckValueChanged, true);
            app.TanRibBouCheck.Text = 'Tangential ribs [mm]';
            app.RadRibBouEdit = uieditfield(app.GridLayout, 'text');
            app.RadRibBouEdit.ValueChangedFcn = createCallbackFcn(app, @RadRibBouEditValueChanged, true);
            app.RadRibBouCheck = uibutton(app.GridLayout, 'state');
            app.RadRibBouCheck.ValueChangedFcn = createCallbackFcn(app, @RadRibBouCheckValueChanged, true);
            app.RadRibBouCheck.Text = 'Radial ribs [mm]';
            app.BetaPMshapeBouEdit = uieditfield(app.GridLayout, 'text');
            app.BetaPMshapeBouEdit.ValueChangedFcn = createCallbackFcn(app, @BetaPMshapeBouEdit_Callback, true);
            app.BetaPMshapeBouCheck = uibutton(app.GridLayout, 'state');
            app.BetaPMshapeBouCheck.ValueChangedFcn = createCallbackFcn(app, @BetaPMshapeBouCheck_Callback, true);
            app.BetaPMshapeBouCheck.Text = 'PM shape factor [p.u.]';
            app.PhaseAngleCurrBouEdit = uieditfield(app.GridLayout, 'text');
            app.PhaseAngleCurrBouEdit.ValueChangedFcn = createCallbackFcn(app, @PhaseAngleCurrBouEdit_Callback, true);
            app.GammaBouCheck = uibutton(app.GridLayout, 'state');
            app.GammaBouCheck.ValueChangedFcn = createCallbackFcn(app, @GammaBouCheck_Callback, true);
            app.GammaBouCheck.Text = 'Gamma [°]';
            app.ObjectivesandPenalizationLimitsPanel = uipanel(app.OptimizationTab_2);
            app.ObjectivesandPenalizationLimitsPanel.Title = 'Objectives and Penalization Limits';
            app.GridLayout2 = uigridlayout(app.ObjectivesandPenalizationLimitsPanel);
            app.MinExpTorEdit = uieditfield(app.GridLayout2, 'text');
            app.MinExpTorEdit.ValueChangedFcn = createCallbackFcn(app, @MinExpTorEdit_Callback, true);
            app.TorqueOptCheck = uibutton(app.GridLayout2, 'state');
            app.TorqueOptCheck.ValueChangedFcn = createCallbackFcn(app, @TorqueOptCheck_Callback, true);
            app.TorqueOptCheck.Text = 'Torque [Nm]';
            app.TorRipOptCheck = uibutton(app.GridLayout2, 'state');
            app.TorRipOptCheck.ValueChangedFcn = createCallbackFcn(app, @TorRipOptCheck_Callback, true);
            app.TorRipOptCheck.Text = 'Torque ripple (pp) [Nm]';
            app.MaxExpeRippleTorEdit = uieditfield(app.GridLayout2, 'text');
            app.MaxExpeRippleTorEdit.ValueChangedFcn = createCallbackFcn(app, @MaxExpeRippleTorEdit_Callback, true);
            app.MassCuOptCheck = uibutton(app.GridLayout2, 'state');
            app.MassCuOptCheck.ValueChangedFcn = createCallbackFcn(app, @MassCuOptCheck_Callback, true);
            app.MassCuOptCheck.Text = 'Copper mass [kg]';
            app.MassCuEdit = uieditfield(app.GridLayout2, 'text');
            app.MassCuEdit.ValueChangedFcn = createCallbackFcn(app, @MassCuEdit_Callback, true);
            app.MassPMOptCheck = uibutton(app.GridLayout2, 'state');
            app.MassPMOptCheck.ValueChangedFcn = createCallbackFcn(app, @MassPMOptCheck_Callback, true);
            app.MassPMOptCheck.Text = 'PM mass [kg]';
            app.MassPMEdit = uieditfield(app.GridLayout2, 'text');
            app.MassPMEdit.ValueChangedFcn = createCallbackFcn(app, @MassPMEdit_Callback, true);
            app.MinExpPowerFactorEdit = uieditfield(app.GridLayout2, 'text');
            app.MinExpPowerFactorEdit.ValueChangedFcn = createCallbackFcn(app, @MinExpPowerFactorEditValueChanged, true);
            app.PowerFactorOptCheck = uibutton(app.GridLayout2, 'state');
            app.PowerFactorOptCheck.ValueChangedFcn = createCallbackFcn(app, @PowerFactorOptCheckValueChanged, true);
            app.PowerFactorOptCheck.Text = 'Power factor';
            app.MaxExpNoLoadFluxEdit = uieditfield(app.GridLayout2, 'text');
            app.MaxExpNoLoadFluxEdit.ValueChangedFcn = createCallbackFcn(app, @MaxExpNoLoadFluxEditValueChanged, true);
            app.NoLoadFluxOptCheck = uibutton(app.GridLayout2, 'state');
            app.NoLoadFluxOptCheck.ValueChangedFcn = createCallbackFcn(app, @NoLoadFluxOptCheckValueChanged, true);
            app.NoLoadFluxOptCheck.Text = 'No load flux [Vs]';
            app.OptimizePush = uibutton(app.OptimizationTab_2, 'push');
            app.OptimizePush.ButtonPushedFcn = createCallbackFcn(app, @OptimizePush_Callback, true);
            app.OptimizePush.Text = 'Optimize';
            app.CurrentoverloadpuEditFieldLabel_2 = uilabel(app.OptimizationTab_2);
            app.CurrentoverloadpuEditFieldLabel_2.Text = 'Current overload [p.u.]';
            app.CurrentOverLoadEdit = uieditfield(app.OptimizationTab_2, 'text');
            app.CurrentOverLoadEdit.ValueChangedFcn = createCallbackFcn(app, @CurrentOverLoadEdit_Callback, true);
            app.MechStressOptCheck = uicheckbox(app.OptimizationTab_2);
            app.MechStressOptCheck.ValueChangedFcn = createCallbackFcn(app, @MechStressOptCheckValueChanged, true);
            app.MechStressOptCheck.Text = 'Mechanical Stress Control';
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.RotorangularexcursioneltEditFieldLabel = uilabel(app.SimulationTab);
            app.RotorangularexcursioneltEditFieldLabel.Text = 'Rotor angular excursion [elt °]';
            app.SpanEltPPEdit = uieditfield(app.SimulationTab, 'text');
            app.SpanEltPPEdit.ValueChangedFcn = createCallbackFcn(app, @SpanEltPPEdit_Callback, true);
            app.NumberofrotorpositionsEditFieldLabel = uilabel(app.SimulationTab);
            app.NumberofrotorpositionsEditFieldLabel.Text = 'Number of rotor positions';
            app.NumOfRotorPosiPPEdit = uieditfield(app.SimulationTab, 'text');
            app.NumOfRotorPosiPPEdit.ValueChangedFcn = createCallbackFcn(app, @NumOfRotorPosiPPEdit_Callback, true);
            app.CurrentphaseangleeltEditFieldLabel = uilabel(app.SimulationTab);
            app.CurrentphaseangleeltEditFieldLabel.Text = 'Current phase angle [elt °]';
            app.GammaPPEdit = uieditfield(app.SimulationTab, 'text');
            app.GammaPPEdit.ValueChangedFcn = createCallbackFcn(app, @GammaPPEdit_Callback, true);
            app.CurrentloadpuEditFieldLabel = uilabel(app.SimulationTab);
            app.CurrentloadpuEditFieldLabel.Text = 'Current load [p.u.]';
            app.CurrLoPPEdit = uieditfield(app.SimulationTab, 'text');
            app.CurrLoPPEdit.ValueChangedFcn = createCallbackFcn(app, @CurrLoPPEdit_Callback, true);
            app.PhasecurrentAEditFieldLabel = uilabel(app.SimulationTab);
            app.PhasecurrentAEditFieldLabel.Text = 'Phase current [A]';
            app.CurrentPP = uieditfield(app.SimulationTab, 'text');
            app.PMremanenceTEditFieldLabel_2 = uilabel(app.SimulationTab);
            app.PMremanenceTEditFieldLabel_2.Text = 'PM remanence [T]';
            app.BrPPEdit = uieditfield(app.SimulationTab, 'text');
            app.BrPPEdit.ValueChangedFcn = createCallbackFcn(app, @BrPPEdit_Callback, true);
            app.PMtemperatureCEditFieldLabel_2 = uilabel(app.SimulationTab);
            app.PMtemperatureCEditFieldLabel_2.Text = 'PM temperature [°C]';
            app.TempPPEdit = uieditfield(app.SimulationTab, 'text');
            app.TempPPEdit.ValueChangedFcn = createCallbackFcn(app, @TempPPEdit_Callback, true);
            app.Numberofpointsin0ImaxLabel = uilabel(app.SimulationTab);
            app.Numberofpointsin0ImaxLabel.Text = 'Number of points in [0, Imax]';
            app.NGridPPEdit = uieditfield(app.SimulationTab, 'text');
            app.NGridPPEdit.ValueChangedFcn = createCallbackFcn(app, @NGridPPEdit_Callback, true);
            app.RotorspeedrpmEditFieldLabel = uilabel(app.SimulationTab);
            app.RotorspeedrpmEditFieldLabel.Text = 'Rotor speed [rpm]';
            app.EvaluatedSpeedEdit = uieditfield(app.SimulationTab, 'text');
            app.EvaluatedSpeedEdit.ValueChangedFcn = createCallbackFcn(app, @EvaluatedSpeedEdit_Callback, true);
            app.EvaluationtypeDropDownLabel = uilabel(app.SimulationTab);
            app.EvaluationtypeDropDownLabel.Text = 'Evaluation type';
            app.EvalTypePopUp = uidropdown(app.SimulationTab);
            app.EvalTypePopUp.Items = {'Single Point', 'Flux Map', 'Characteristic Current', 'HWC Short-Circuit Current', 'Demagnetization Curve', 'Demagnetization Analysis', 'Flux Density Analysis', 'Current Offset', 'Airgap Force', 'Iron Loss - Single Point', 'Iron Loss - Flux Map', 'Structural Analysis'};
            app.EvalTypePopUp.ValueChangedFcn = createCallbackFcn(app, @EvalTypePopUp_Callback, true);
            app.EvalTypePopUp.Value = 'Single Point';
            app.StartPProPush = uibutton(app.SimulationTab, 'push');
            app.StartPProPush.ButtonPushedFcn = createCallbackFcn(app, @StartPProPush_Callback, true);
            app.StartPProPush.Text = 'Start';
            app.StartPProMagnetPush = uibutton(app.SimulationTab, 'push');
            app.StartPProMagnetPush.ButtonPushedFcn = createCallbackFcn(app, @StartPProMagnetPush_Callback, true);
            app.StartPProMagnetPush.Text = 'MAGNET';
            app.NumberofmapquadrantsLabel = uilabel(app.SimulationTab);
            app.NumberofmapquadrantsLabel.Text = 'Number of map quadrants';
            app.MapQuadrantsPopUp = uidropdown(app.SimulationTab);
            app.MapQuadrantsPopUp.Items = {'1 quadrant', '2 quadrants', '4 quadrants'};
            app.MapQuadrantsPopUp.ValueChangedFcn = createCallbackFcn(app, @MapQuadrantsPopUpValueChanged, true);
            app.MapQuadrantsPopUp.Value = '1 quadrant';
            app.StartPProAnsysPush = uibutton(app.SimulationTab, 'push');
            app.StartPProAnsysPush.ButtonPushedFcn = createCallbackFcn(app, @StartPProAnsysPushButtonPushed, true);
            app.StartPProAnsysPush.Text = 'ANSYS';
            app.CustomCurrentPanel = uipanel(app.SimulationTab);
            app.CustomCurrentPanel.Title = 'Custom Current';
            app.text120_2 = uilabel(app.CustomCurrentPanel);
            app.text120_2.Text = 'Custom Current file is:';
            app.CustomCurrentFile = uieditfield(app.CustomCurrentPanel, 'text');
            app.CustomCurrentFile.ValueChangedFcn = createCallbackFcn(app, @CustomCurrentFileValueChanged, true);
            app.CustomCurrentFile.Value = 'Edit Text';
            app.CustomCurrentSwitch = uiswitch(app.CustomCurrentPanel, 'slider');
            app.CustomCurrentSwitch.ValueChangedFcn = createCallbackFcn(app, @CustomCurrentSwitchValueChanged, true);
            app.LoadCustomCurrent = uibutton(app.CustomCurrentPanel, 'push');
            app.LoadCustomCurrent.ButtonPushedFcn = createCallbackFcn(app, @LoadCustomCurrentButtonPushed, true);
            app.LoadCustomCurrent.Text = 'Load Custom Current';
            app.AxistypeDropDownLabel = uilabel(app.SimulationTab);
            app.AxistypeDropDownLabel.Text = 'Axis type';
            app.AxistypeDropDown = uidropdown(app.SimulationTab);
            app.AxistypeDropDown.Items = {'SR', 'PM'};
            app.AxistypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AxistypeDropDownValueChanged, true);
            app.AxistypeDropDown.Value = 'SR';
            app.MotorCADTab = uitab(app.TabGroup);
            app.MotorCADTab.Title = 'Motor-CAD';
            app.AxisLogoMCAD = uiaxes(app.MotorCADTab);
            app.AxisLogoMCAD.XTick = [];
            app.AxisLogoMCAD.YTick = [];
            app.ExportMCadPush = uibutton(app.MotorCADTab, 'push');
            app.ExportMCadPush.ButtonPushedFcn = createCallbackFcn(app, @ExportMCadPushButtonPushed, true);
            app.ExportMCadPush.Text = 'Model export';
            app.ThermalSimulationPanel = uipanel(app.MotorCADTab);
            app.ThermalSimulationPanel.Title = 'Thermal Simulation';
            app.TransientperiodsEditFieldLabel = uilabel(app.ThermalSimulationPanel);
            app.TransientperiodsEditFieldLabel.Text = 'Transient period [s]';
            app.TransientperiodEditField = uieditfield(app.ThermalSimulationPanel, 'text');
            app.TransientperiodEditField.ValueChangedFcn = createCallbackFcn(app, @TransientperiodEditFieldValueChanged, true);
            app.NumberofpointsLabel = uilabel(app.ThermalSimulationPanel);
            app.NumberofpointsLabel.Text = 'Number of points';
            app.TimestepEditField = uieditfield(app.ThermalSimulationPanel, 'text');
            app.TimestepEditField.ValueChangedFcn = createCallbackFcn(app, @TimestepEditFieldValueChanged, true);
            app.ThermalSimulation_MCAD = uibutton(app.ThermalSimulationPanel, 'push');
            app.ThermalSimulation_MCAD.ButtonPushedFcn = createCallbackFcn(app, @ThermalSimulation_MCADButtonPushed, true);
            app.ThermalSimulation_MCAD.Text = 'Therm Sim';
            app.InitialtemperatureCLabel_2 = uilabel(app.ThermalSimulationPanel);
            app.InitialtemperatureCLabel_2.Text = 'Initial temperature [°C]';
            app.InitialTemperatureEditField = uieditfield(app.ThermalSimulationPanel, 'text');
            app.InitialTemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @InitialTemperatureEditFieldValueChanged2, true);
            app.ThermalevaluationtypeDropDownLabel = uilabel(app.ThermalSimulationPanel);
            app.ThermalevaluationtypeDropDownLabel.Text = 'Thermal evaluation type';
            app.ThermalevaluationtypeDropDown = uidropdown(app.ThermalSimulationPanel);
            app.ThermalevaluationtypeDropDown.Items = {'Steady State', 'Transient'};
            app.ThermalevaluationtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ThermalevaluationtypeDropDownValueChanged, true);
            app.ThermalevaluationtypeDropDown.Value = 'Steady State';
            app.CurrentphaseangleeltEditFieldLabel_2 = uilabel(app.ThermalSimulationPanel);
            app.CurrentphaseangleeltEditFieldLabel_2.Text = 'Current phase angle [elt °]';
            app.GammaPPMirrorEdit = uieditfield(app.ThermalSimulationPanel, 'text');
            app.GammaPPMirrorEdit.ValueChangedFcn = createCallbackFcn(app, @GammaPPMirrorEditValueChanged, true);
            app.PMtemperatureCEditFieldLabel_4 = uilabel(app.ThermalSimulationPanel);
            app.PMtemperatureCEditFieldLabel_4.Text = 'PM temperature [°C]';
            app.TempPPMirrorEdit = uieditfield(app.ThermalSimulationPanel, 'text');
            app.TempPPMirrorEdit.ValueChangedFcn = createCallbackFcn(app, @TempPPMirrorEditValueChanged, true);
            app.RotorspeedrpmEditFieldLabel_2 = uilabel(app.ThermalSimulationPanel);
            app.RotorspeedrpmEditFieldLabel_2.Text = 'Rotor speed [rpm]';
            app.EvaluatedSpeedMirrorEdit = uieditfield(app.ThermalSimulationPanel, 'text');
            app.EvaluatedSpeedMirrorEdit.ValueChangedFcn = createCallbackFcn(app, @EvaluatedSpeedMirrorEditValueChanged, true);
            app.PhasecurrentAEditFieldLabel_2 = uilabel(app.ThermalSimulationPanel);
            app.PhasecurrentAEditFieldLabel_2.Text = 'Phase current [A]';
            app.CurrentPPMirror = uieditfield(app.ThermalSimulationPanel, 'text');
            app.CurrentPPMirror.ValueChangedFcn = createCallbackFcn(app, @CurrentPPMirrorValueChanged, true);
            app.AmbienttemperatureCLabel = uilabel(app.ThermalSimulationPanel);
            app.AmbienttemperatureCLabel.Text = 'Ambient temperature [°C]';
            app.AmbientTemperatureEditField = uieditfield(app.ThermalSimulationPanel, 'text');
            app.AmbientTemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @AmbientTemperatureEditFieldValueChanged, true);
            app.ElectromagneticModulePanel = uipanel(app.MotorCADTab);
            app.ElectromagneticModulePanel.Title = 'Electromagnetic Module';
            app.ExportFluxMapsPush = uibutton(app.ElectromagneticModulePanel, 'push');
            app.ExportFluxMapsPush.ButtonPushedFcn = createCallbackFcn(app, @ExportFluxMapsPushButtonPushed, true);
            app.ExportFluxMapsPush.Text = 'Export flux maps';
            app.StartMCadSimPush = uibutton(app.ElectromagneticModulePanel, 'push');
            app.StartMCadSimPush.ButtonPushedFcn = createCallbackFcn(app, @StartMCadSimPushButtonPushed, true);
            app.StartMCadSimPush.Text = 'EMag Sim';
            app.BuildThermalModelPanel = uipanel(app.MotorCADTab);
            app.BuildThermalModelPanel.Title = 'Build Thermal Model';
            app.HousingTypeDropDownLabel = uilabel(app.BuildThermalModelPanel);
            app.HousingTypeDropDownLabel.Text = 'Housing Type';
            app.HousingTypeDropDown = uidropdown(app.BuildThermalModelPanel);
            app.HousingTypeDropDown.Items = {'Water Jacket (Spiral)', 'Water Jacket (Axial)', 'Axial fins (Servo)', 'None'};
            app.HousingTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @HousingTypeDropDownValueChanged, true);
            app.HousingTypeDropDown.Value = 'Water Jacket (Spiral)';
            app.FluidflowratelminLabel = uilabel(app.BuildThermalModelPanel);
            app.FluidflowratelminLabel.Text = 'Fluid flow rate [l/min]';
            app.FluidFlowRateEditField = uieditfield(app.BuildThermalModelPanel, 'text');
            app.FluidFlowRateEditField.ValueChangedFcn = createCallbackFcn(app, @FluidFlowRateEditFieldValueChanged, true);
            app.ExportThermalParameters_MCADPush = uibutton(app.BuildThermalModelPanel, 'push');
            app.ExportThermalParameters_MCADPush.ButtonPushedFcn = createCallbackFcn(app, @ExportThermalParameters_MCADPushButtonPushed, true);
            app.ExportThermalParameters_MCADPush.Text = 'Build Thermal';
            app.FluidDropDownLabel = uilabel(app.BuildThermalModelPanel);
            app.FluidDropDownLabel.Text = 'Fluid ';
            app.FluidDropDown = uidropdown(app.BuildThermalModelPanel);
            app.FluidDropDown.Items = {'W/G 50/50', 'W/G 60/40', 'Water'};
            app.FluidDropDown.ValueChangedFcn = createCallbackFcn(app, @FluidDropDownValueChanged, true);
            app.FluidDropDown.Value = 'W/G 50/50';
            app.InlettemperatureCEditFieldLabel = uilabel(app.BuildThermalModelPanel);
            app.InlettemperatureCEditFieldLabel.Text = 'Inlet temperature [°C]';
            app.InlettemperatureEditField = uieditfield(app.BuildThermalModelPanel, 'text');
            app.InlettemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @InlettemperatureEditFieldValueChanged, true);
            app.ThermalLimitsatZeroSpeedPanel = uipanel(app.MotorCADTab);
            app.ThermalLimitsatZeroSpeedPanel.Title = 'Thermal Limits at Zero Speed';
            app.CalculateCont_MCAD = uibutton(app.ThermalLimitsatZeroSpeedPanel, 'push');
            app.CalculateCont_MCAD.ButtonPushedFcn = createCallbackFcn(app, @CalculateCont_MCADButtonPushed, true);
            app.CalculateCont_MCAD.Text = 'Calculate Steady';
            app.CalculateTrans_MCAD = uibutton(app.ThermalLimitsatZeroSpeedPanel, 'push');
            app.CalculateTrans_MCAD.ButtonPushedFcn = createCallbackFcn(app, @CalculateTrans_MCADButtonPushed, true);
            app.CalculateTrans_MCAD.Text = 'Calculate Transient';
            app.CopperlimitCLabel = uilabel(app.ThermalLimitsatZeroSpeedPanel);
            app.CopperlimitCLabel.Text = 'Copper limit [°C]';
            app.TempCuLimitEdit = uieditfield(app.ThermalLimitsatZeroSpeedPanel, 'text');
            app.TempCuLimitEdit.ValueChangedFcn = createCallbackFcn(app, @TempCuLimitEditValueChanged, true);
            app.TransienttimesLabel = uilabel(app.ThermalLimitsatZeroSpeedPanel);
            app.TransienttimesLabel.Text = 'Transient time [s]';
            app.TransientTimeEditField = uieditfield(app.ThermalLimitsatZeroSpeedPanel, 'text');

            app.TransientTimeEditField.ValueChangedFcn = createCallbackFcn(app, @TransientTimeEditFieldValueChanged, true);
            app.OutputALabel = uilabel(app.ThermalLimitsatZeroSpeedPanel);
            app.OutputALabel.Text = 'Output [A]';
            app.SimulatedTransientCurrent = uieditfield(app.ThermalLimitsatZeroSpeedPanel, 'text');
            app.OutputALabel_2 = uilabel(app.ThermalLimitsatZeroSpeedPanel);
            app.OutputALabel_2.Text = 'Output [A]';
            app.SimulatedRatedCurrent = uieditfield(app.ThermalLimitsatZeroSpeedPanel, 'text');
            app.UtilitiesTab = uitab(app.TabGroup);
            app.UtilitiesTab.Title = 'Utilities';
            app.GridLayout3 = uigridlayout(app.UtilitiesTab);
            app.ExportPanel = uipanel(app.GridLayout3);
            app.ExportPanel.Title = 'Export';
            app.dxfButton = uibutton(app.ExportPanel, 'push');
            app.dxfButton.ButtonPushedFcn = createCallbackFcn(app, @dxfButtonPushed, true);
            app.dxfButton.Text = 'dxf';
            app.SaveMachineMagnetPush = uibutton(app.ExportPanel, 'push');
            app.SaveMachineMagnetPush.ButtonPushedFcn = createCallbackFcn(app, @SaveMachineMagnetPush_Callback, true);
            app.SaveMachineMagnetPush.Text = 'Simcenter MagNet';
            app.SaveMachineAnsysPush = uibutton(app.ExportPanel, 'push');
            app.SaveMachineAnsysPush.ButtonPushedFcn = createCallbackFcn(app, @SaveMachineAnsysPushButtonPushed, true);
            app.SaveMachineAnsysPush.Text = 'Ansys Maxwell';
            app.AnsysMotorCADButton = uibutton(app.ExportPanel, 'push');
            app.AnsysMotorCADButton.ButtonPushedFcn = createCallbackFcn(app, @AnsysMotorCADButtonPushed, true);
            app.AnsysMotorCADButton.Text = 'Ansys Motor-CAD';
            app.DocumentationPanel = uipanel(app.GridLayout3);
            app.DocumentationPanel.Title = 'Documentation';
            app.UsermanualButton = uibutton(app.DocumentationPanel, 'push');
            app.UsermanualButton.ButtonPushedFcn = createCallbackFcn(app, @UsermanualButtonPushed, true);
            app.UsermanualButton.Text = 'User manual';
            app.ReferencesButton = uibutton(app.DocumentationPanel, 'push');
            app.ReferencesButton.ButtonPushedFcn = createCallbackFcn(app, @ReferencesButtonPushed, true);
            app.ReferencesButton.Text = 'References';
            app.ParallelcomputingPanel = uipanel(app.GridLayout3);
            app.ParallelcomputingPanel.Title = 'Parallel computing';
            app.CheckButton = uibutton(app.ParallelcomputingPanel, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = 'Check';
            app.EnableButton = uibutton(app.ParallelcomputingPanel, 'push');
            app.EnableButton.ButtonPushedFcn = createCallbackFcn(app, @EnableButtonPushed, true);
            app.EnableButton.Text = 'Enable';
            app.DisableButton = uibutton(app.ParallelcomputingPanel, 'push');
            app.DisableButton.ButtonPushedFcn = createCallbackFcn(app, @DisableButtonPushed, true);
            app.DisableButton.Text = 'Disable';
            app.ReleaseandToolboxesPanel = uipanel(app.GridLayout3);
            app.ReleaseandToolboxesPanel.Title = 'Release and Toolboxes';
            app.CheckReleaseButton = uibutton(app.ReleaseandToolboxesPanel, 'push');
            app.CheckReleaseButton.ButtonPushedFcn = createCallbackFcn(app, @CheckReleaseButtonPushed, true);
            app.CheckReleaseButton.Text = 'Check Release';
            app.LaunchMMMButton = uibutton(app.GridLayout3, 'push');
            app.LaunchMMMButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchMMMButtonPushed, true);
            app.LaunchMMMButton.Text = 'Launch MMM';
            app.LaunchsyrmDesignExplorerButton = uibutton(app.GridLayout3, 'push');
            app.LaunchsyrmDesignExplorerButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchsyrmDesignExplorerButtonPushed, true);
            app.LaunchsyrmDesignExplorerButton.Text = {'Launch'; 'syrmDesign Explorer'};
            app.CloseallButton = uibutton(app.figure1, 'push');
            app.CloseallButton.ButtonPushedFcn = createCallbackFcn(app, @CloseallButtonPushed, true);
            app.CloseallButton.Text = 'Close all';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Syre

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @GUI_Syre_OpeningFcn)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end