classdef GUI_Syre_MMM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        ModelsLoadedPanel               matlab.ui.container.Panel
        PlotSkinEffectButton            matlab.ui.control.Button
        SaveSkinEffectButton            matlab.ui.control.Button
        PlotIronLossModelButton         matlab.ui.control.Button
        SaveIronLossModelButton         matlab.ui.control.Button
        SaveDQTmodelButton              matlab.ui.control.Button
        SaveDQmodelButton               matlab.ui.control.Button
        PlotDQTmodelButton              matlab.ui.control.Button
        PlotDQmodelButton               matlab.ui.control.Button
        LoadSkinEffectButton            matlab.ui.control.Button
        LoadIronLossModelButton         matlab.ui.control.Button
        LoadDQTmodelButton              matlab.ui.control.Button
        LoadDQmodelButton               matlab.ui.control.Button
        SkinEffectModelCheckBox         matlab.ui.control.CheckBox
        IronLossModelCheckBox           matlab.ui.control.CheckBox
        dqtMapModelCheckBox             matlab.ui.control.CheckBox
        dqModelCheckBox                 matlab.ui.control.CheckBox
        PrintDQmodelButton              matlab.ui.control.Button
        MTPAMTPVPanel                   matlab.ui.container.Panel
        AOACheckBox                     matlab.ui.control.CheckBox
        EvaluateMTPAButton              matlab.ui.control.Button
        MTPAmethodDropDown              matlab.ui.control.DropDown
        LoadMTPAButton                  matlab.ui.control.Button
        PlotMTPAButton                  matlab.ui.control.Button
        SaveMTPAButton                  matlab.ui.control.Button
        PrintMTPAButton                 matlab.ui.control.Button
        InductanceandAnisotropyMapsPanel  matlab.ui.container.Panel
        InductanceMapsCheckBox          matlab.ui.control.CheckBox
        EvalInductanceButton            matlab.ui.control.Button
        PlotInductanceButton            matlab.ui.control.Button
        SaveInductanceButton            matlab.ui.control.Button
        CurrentAngleCurvesPanel         matlab.ui.container.Panel
        ofcurrentlevelsEditField_2Label  matlab.ui.control.Label
        NumCurrLevelTgammaEditField     matlab.ui.control.EditField
        PlotTgammaButton                matlab.ui.control.Button
        InverseModelPanel               matlab.ui.container.Panel
        InversedqCheckBox               matlab.ui.control.CheckBox
        InversedqtMapCheckBox           matlab.ui.control.CheckBox
        EvalInverseDQTButton            matlab.ui.control.Button
        PlotInverseDQTButton            matlab.ui.control.Button
        SaveInverseDQTButton            matlab.ui.control.Button
        EvalInverseDQButton             matlab.ui.control.Button
        PlotInverseDQButton             matlab.ui.control.Button
        SaveInverseDQButton             matlab.ui.control.Button
        ShortCircuitTorquePanel         matlab.ui.container.Panel
        EvaluateShortCircuitTorqueButton  matlab.ui.control.Button
        ScalingSkewingTab               matlab.ui.container.Tab
        ModelScalingPanel               matlab.ui.container.Panel
        TurnsinseriesperphaseEditField_2Label  matlab.ui.control.Label
        NewTurnsEditField               matlab.ui.control.EditField
        ActivelengthmmEditFieldLabel    matlab.ui.control.Label
        NewLengthEditField              matlab.ui.control.EditField
        AdditionaldaxisinductanceHEditFieldLabel  matlab.ui.control.Label
        NewLldEditField                 matlab.ui.control.EditField
        AdditionalqaxisinductanceHEditFieldLabel  matlab.ui.control.Label
        NewLlqEditField                 matlab.ui.control.EditField
        ScaleModelPush                  matlab.ui.control.Button
        UnscaleModelPush                matlab.ui.control.Button
        LoadRefModelPush                matlab.ui.control.Button
        ModelSkewingPanel               matlab.ui.container.Panel
        SkewanglemechdegEditFieldLabel_2  matlab.ui.control.Label
        SkewAngleEditField              matlab.ui.control.EditField
        NumberofaxialslicesEditFieldLabel_2  matlab.ui.control.Label
        SkewSliceEditField              matlab.ui.control.EditField
        ofpointsalongoneaxisLabel       matlab.ui.control.Label
        SkewPointsEditField             matlab.ui.control.EditField
        SkewModelPush                   matlab.ui.control.Button
        UnskewModelPush                 matlab.ui.control.Button
        dqtMapskewingevaluationEditFieldLabel  matlab.ui.control.Label
        dqtMapskewingevaluationEditField  matlab.ui.control.EditField
        ClearScaleAxisPush              matlab.ui.control.Button
        NewModelAxis                    matlab.ui.control.UIAxes
        TorqueSpeedTab                  matlab.ui.container.Tab
        OperatingLimitsPanel            matlab.ui.container.Panel
        ofcurrentlevelsEditFieldLabel   matlab.ui.control.Label
        OpLimLevelsEditField            matlab.ui.control.EditField
        OpLimPush                       matlab.ui.control.Button
        RatingsEvalPush                 matlab.ui.control.Button
        EfficiencyMapPanel              matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        SpeedlimitsrpmEditFieldLabel    matlab.ui.control.Label
        TwMinSpeedEditField             matlab.ui.control.EditField
        MinLabel                        matlab.ui.control.Label
        MaxLabel                        matlab.ui.control.Label
        ofpointsLabel                   matlab.ui.control.Label
        TwMaxSpeedEditField             matlab.ui.control.EditField
        TwNumSpeedEditField             matlab.ui.control.EditField
        TwMaxTorqueEditField            matlab.ui.control.EditField
        TwNumTorqueEditField            matlab.ui.control.EditField
        SpeedlimitsrpmEditFieldLabel_2  matlab.ui.control.Label
        TwMinTorqueEditField            matlab.ui.control.EditField
        WindingtemperatureCLabel        matlab.ui.control.Label
        TwTemperatureEditField          matlab.ui.control.EditField
        IronlossDropDownLabel           matlab.ui.control.Label
        TwIronLossDropDown              matlab.ui.control.DropDown
        SkineffectDropDownLabel         matlab.ui.control.Label
        TwSkinEffectDropDown            matlab.ui.control.DropDown
        MechlosspolyLabel               matlab.ui.control.Label
        TwMechLossEditField             matlab.ui.control.EditField
        ControlstrategyDropDownLabel    matlab.ui.control.Label
        TwControlDropDown               matlab.ui.control.DropDown
        IronlossfactorLabel             matlab.ui.control.Label
        TwIronLossFactorEditField       matlab.ui.control.EditField
        MethodDropDownLabel             matlab.ui.control.Label
        TwSkinEffectMethodDropDown      matlab.ui.control.DropDown
        MaxTwPush                       matlab.ui.control.Button
        PMlossDropDownLabel             matlab.ui.control.Label
        TwPMlossDropDown                matlab.ui.control.DropDown
        PMlossfactorEditFieldLabel      matlab.ui.control.Label
        TwPMlossFactorEditField         matlab.ui.control.EditField
        TwAxis                          matlab.ui.control.UIAxes
        syreDriveTab                    matlab.ui.container.Tab
        ConverterdataPanel              matlab.ui.container.Panel
        ONthreasholdEditFieldLabel      matlab.ui.control.Label
        ONthreasholdEditField           matlab.ui.control.EditField
        InternalresistanceEditFieldLabel  matlab.ui.control.Label
        InternalresistanceEditField     matlab.ui.control.EditField
        DeadtimeEditFieldLabel          matlab.ui.control.Label
        DeadtimeEditField               matlab.ui.control.EditField
        VLabel_2                        matlab.ui.control.Label
        OhmLabel_2                      matlab.ui.control.Label
        nsLabel                         matlab.ui.control.Label
        ControlsetupsPanel              matlab.ui.container.Panel
        ControltypeDropDownLabel        matlab.ui.control.Label
        ControltypeDropDown             matlab.ui.control.DropDown
        FluxmapsmodelDropDownLabel      matlab.ui.control.Label
        FluxmapsmodelDropDown           matlab.ui.control.DropDown
        RUNButton                       matlab.ui.control.Button
        SensorlesscontrolPanel          matlab.ui.container.Panel
        LowspeedregionPanel             matlab.ui.container.Panel
        InjectedsignalDropDownLabel     matlab.ui.control.Label
        InjectedsignalDropDown          matlab.ui.control.DropDown
        DemodulationDropDownLabel       matlab.ui.control.Label
        DemodulationDropDown            matlab.ui.control.DropDown
        HFvoltageinjectionLabel         matlab.ui.control.Label
        HighspeedregionPanel            matlab.ui.container.Panel
        PositionerrorestimationDropDownLabel  matlab.ui.control.Label
        PositionerrorestimationDropDown  matlab.ui.control.DropDown
        SensorlessSwitch                matlab.ui.control.Switch
        WaveformTab                     matlab.ui.container.Tab
        SetupPanel                      matlab.ui.container.Panel
        CurrentphaseangleeltdegEditFieldLabel_2  matlab.ui.control.Label
        WaveformGammaEditField          matlab.ui.control.EditField
        CurrentLoadpuEditFieldLabel_2   matlab.ui.control.Label
        WaveformCurrentPUEditField      matlab.ui.control.EditField
        PhaseCurrentAEditFieldLabel_2   matlab.ui.control.Label
        WaveformCurrentEditField        matlab.ui.control.EditField
        RotorspeedrpmLabel              matlab.ui.control.Label
        WaveformSpeedEditField          matlab.ui.control.EditField
        ofperiodsLabel                  matlab.ui.control.Label
        WaveformPeriodsEditField        matlab.ui.control.EditField
        EvaluationsPanel                matlab.ui.container.Panel
        WaveformSingPointButton         matlab.ui.control.Button
        WaveformShortCircuitButton      matlab.ui.control.Button
        SyReMagneticModelManipulationLabel  matlab.ui.control.Label
        MotorRatingsPanel               matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        MotornameEditFieldLabel         matlab.ui.control.Label
        MotornameEditField              matlab.ui.control.EditField
        MotortypeEditFieldLabel         matlab.ui.control.Label
        MotortypeEditField              matlab.ui.control.EditField
        RatedcurrentEditFieldLabel      matlab.ui.control.Label
        RatedcurrentEditField           matlab.ui.control.EditField
        MaximumcurrentEditFieldLabel    matlab.ui.control.Label
        MaximumcurrentEditField         matlab.ui.control.EditField
        DClinkvoltageEditFieldLabel     matlab.ui.control.Label
        DClinkvoltageEditField          matlab.ui.control.EditField
        PhaseresistanceEditFieldLabel   matlab.ui.control.Label
        PhaseresistanceEditField        matlab.ui.control.EditField
        WindingtemperatureEditFieldLabel  matlab.ui.control.Label
        WindingtemperatureEditField     matlab.ui.control.EditField
        ActivelengthEditFieldLabel      matlab.ui.control.Label
        ActivelengthEditField           matlab.ui.control.EditField
        TurnsinseriesperphaseEditFieldLabel  matlab.ui.control.Label
        TurnsinseriesperphaseEditField  matlab.ui.control.EditField
        Numberof3phasesetsEditFieldLabel  matlab.ui.control.Label
        Numberof3phasesetsEditField     matlab.ui.control.EditField
        ApkLabel                        matlab.ui.control.Label
        ApkLabel_2                      matlab.ui.control.Label
        VLabel                          matlab.ui.control.Label
        OhmLabel                        matlab.ui.control.Label
        mmLabel                         matlab.ui.control.Label
        MaximumspeedEditFieldLabel      matlab.ui.control.Label
        MaximumspeedEditField           matlab.ui.control.EditField
        rpmLabel                        matlab.ui.control.Label
        CLabel                          matlab.ui.control.Label
        RatedpowerEditFieldLabel        matlab.ui.control.Label
        RatedpowerEditField             matlab.ui.control.EditField
        WLabel                          matlab.ui.control.Label
        RatedspeedEditFieldLabel        matlab.ui.control.Label
        RatedspeedEditField             matlab.ui.control.EditField
        rpmLabel_2                      matlab.ui.control.Label
        CLabel_2                        matlab.ui.control.Label
        InertiaEditFieldLabel           matlab.ui.control.Label
        InertiaEditField                matlab.ui.control.EditField
        kgm2Label                       matlab.ui.control.Label
        PMtemperatureDropDownLabel      matlab.ui.control.Label
        PMtemperatureDropDown           matlab.ui.control.DropDown
        AxistypeEditField               matlab.ui.control.EditField
        AxistypeLabel                   matlab.ui.control.Label
        FileCheckPush                   matlab.ui.control.Button
        LoadPush                        matlab.ui.control.Button
        SavePush                        matlab.ui.control.Button
        CurrentpathEditFieldLabel       matlab.ui.control.Label
        CurrentPathEditField            matlab.ui.control.EditField
        SaveAsPush                      matlab.ui.control.Button
        NewButton                       matlab.ui.control.Button
        CloseallButton                  matlab.ui.control.Button
        AxisLogo                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        motorModel        % Motor model
        motorModelUnScale % Scaled motor model
        motorModelUnSkew  % Skewed motor model
        motorModelRef     % Reference motor model (for scale)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUI_Syre_MMM_StartUp(app, varargin)
            
            % Set figure at the center of the screen
            app.UIFigure.Units = 'pixels';
            screenPos = get(groot,'ScreenSize');
            figPos = app.UIFigure.Position;
            figPos(1:2)=screenPos(3:4)/2-figPos(3:4)/2;
            app.UIFigure.Position = figPos;
            app.UIFigure.Icon = 'icon.png';
            
            % set the first tab
            app.TabGroup.SelectedTab = app.TabGroup.Children(1);
            
            % Axes update
            
            im = imread('syre.png');
            image(app.AxisLogo,im);
            set(app.AxisLogo,'dataAspectRatio',[1 1 1],'Visible','off')
            
            vMatlab = ver('matlab');
            vMatlab = eval(vMatlab.Version);
            if vMatlab>=9.7
                app.AxisLogo.Interactions = [];
                set(app.AxisLogo.Toolbar,'Visible','off')
                
                app.NewModelAxis.Interactions = [];
                set(app.NewModelAxis.Toolbar,'Visible','off')
                
                app.TwAxis.Interactions = [];
                set(app.TwAxis.Toolbar,'Visible','off')
            end
            
            if length(varargin)==1
                motorModel = varargin{1};
            elseif length(varargin)==2
                motorModel = MMM_load(varargin{1},varargin{2});
            else
                motorModel = MMM_defaultMotorModel();
            end
            motorModel = MMM_back_compatibility(motorModel,0);
            
            app.motorModel = motorModel;
            app.motorModelUnScale = [];
            app.motorModelUnSkew  = [];
            app.motorModelRef     = [];
            MMM_GUI_SetParameters(app)
            
            
        end

        % Button pushed function: LoadPush
        function LoadPushButtonPushed(app, event)
            [filename,pathname] = uigetfile('*.mat');
            if filename
                motorModel = MMM_load(pathname,filename);
                app.motorModelUnScale = [];
                app.motorModelUnSkew  = [];
                app.motorModelRef     = [];
            else
                motorModel = app.motorModel;
            end
            motorModel = MMM_back_compatibility(motorModel,0);
            
            if filename
                motorModel.data.pathname  = pathname;
                motorModel.data.motorName = filename(1:end-4);
            end
            
            app.motorModel = motorModel;
            MMM_GUI_SetParameters(app);
            
        end

        % Button pushed function: SavePush
        function SavePushButtonPushed(app, event)
            if (isempty(app.motorModelUnScale)&&isempty(app.motorModelUnSkew))
                motorModel = app.motorModel;
                save([app.motorModel.data.pathname app.motorModel.data.motorName '.mat'],'motorModel','-append')
                pathname = app.motorModel.data.pathname;
                resFolder = [app.motorModel.data.motorName '_results\MMM results\'];
                if ~exist([pathname resFolder],'dir')
                    mkdir([pathname resFolder]);
                end
                tempFolder = [pathname resFolder 'tempModels\'];
                if ~exist(tempFolder,'dir')
                    mkdir(tempFolder);
                end
                
                save([pathname resFolder 'tempModels\motorModel_' int2str(motorModel.data.tempPM) 'deg.mat'],'motorModel');
            else
                hfig = uifigure;
                uialert(hfig,'There are some critical modifications! Save the model with name.','Scale or skew modifications unsaved','Error')
            end
        end

        % Button pushed function: SaveAsPush
        function SaveAsPushButtonPushed(app, event)
            motorModel = app.motorModel;
            [filename,pathname] = uiputfile([motorModel.data.pathname motorModel.data.motorName '.mat'],'Save Motor Model as');
            if filename
                fileMotOld  = [motorModel.data.motorName '.fem'];
                pathnameOld = motorModel.data.pathname; 
                motorModel.data.pathname  = pathname;
                motorModel.data.motorName = filename(1:end-4);
                save([pathname filename],'motorModel');
                if isfield(motorModel,'dataSet')
                    dataSet = app.motorModel.dataSet;
                    geo     = app.motorModel.geo;
                    per     = app.motorModel.per;
                    mat     = app.motorModel.mat;
                    save([pathname filename],'dataSet','geo','mat','per','-append');
                    copyfile([pathnameOld fileMotOld],[pathname filename(1:end-4) '.fem']);
                    openfemm(0);
                    opendocument([pathname filename(1:end-4) '.fem'])
                    mi_probdef(0,'millimeters','planar',1e-8,geo.l,15);
                    mi_saveas([pathname filename(1:end-4) '.fem'])
                    mi_close;
                    closefemm();
                end
                
                %pathname = app.motorModel.data.pathname;
                resFolder = [motorModel.data.motorName '_results\MMM results\'];
                if ~exist([pathname resFolder],'dir')
                    mkdir([pathname resFolder]);
                end
                tempFolder = [pathname resFolder 'tempModels\'];
                if ~exist(tempFolder,'dir')
                    mkdir(tempFolder);
                end
                save([pathname resFolder 'tempModels\motorModel_' int2str(motorModel.data.tempPM) 'deg.mat'],'motorModel');
                
                
                if ~isempty(app.motorModelUnScale)
                    MMM_scale_allTemperatures(motorModel,app.motorModelUnScale)
                elseif ~isempty(app.motorModelUnSkew)
                    MMM_skew_allTemperatures(motorModel,app.motorModelUnSkew)
                else
                    MMM_saveAs_allTemperatures(motorModel.data.pathname, motorModel.data.motorName,pathnameOld, fileMotOld(1:end-4))
                end
                
                
                app.motorModel = motorModel;
                app.motorModelUnSkew = [];
                app.motorModelUnScale = [];
                app.motorModelRef = [];
            end
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: FileCheckPush
        function FileCheckPushButtonPushed(app, event)
            [filename,pathname] = uigetfile([app.motorModel.data.pathname app.motorModel.data.motorName '*.mat'],'Select a file to check');
            MMM_checkFile(pathname,filename);
        end

        % Value changed function: dqModelCheckBox
        function dqModelCheckBoxValueChanged(app, event)
            value = app.dqModelCheckBox.Value;
            if ~value
                app.motorModel.fdfq       = [];
                app.motorModel.ironLoss   = [];
                app.motorModel.dqtMap     = [];
                app.motorModel.skinEffect = [];
                app.motorModel.AOA        = [];
                app.motorModel.Inductance = [];
                app.motorModel.idiq       = [];
                app.motorModel.dqtMapF    = [];
                app.motorModelUnScale = [];
                app.motorModelUnSkew  = [];
            end
            
            MMM_GUI_SetParameters(app)
        end

        % Button pushed function: LoadDQmodelButton
        function LoadDQmodelButtonPushed(app, event)
            FEAfolder = [app.motorModel.data.pathname app.motorModel.data.motorName '_results\FEA results\'];
            if ~exist(FEAfolder,'dir')
                FEAfolder = app.motorModel.data.pathname;
            end
            [filename,pathname] = uigetfile([FEAfolder '\*.mat'],'Load fdfq_idiq model');
            if filename
                data = load([pathname filename]);
                [fdfq,tempPM] = MMM_load_fdfq(data,app.motorModel.data.p);
                
                if isnan(tempPM)
                    tempPM = app.motorModel.data.tempPM;
                end
                
                % check for dqtMap
                if isfield(data,'dataSet')
                    if data.dataSet.NumOfRotPosPP>=20
                        if exist([pathname 'F_map.mat'],'file')
                            dqtMap = MMM_eval_dqtMap(pathname,'F_map.mat');
                        else
                            dqtMap = [];
                        end
                    else
                        dqtMap = [];
                    end
                else
                    dqtMap = [];
                end
                
                ironLoss = loadIronLossModel([pathname filename]);
                
                if tempPM==app.motorModel.data.tempPM
                    app.motorModel.fdfq       = fdfq;
                    app.motorModel.dqtMap     = dqtMap;
                    app.motorModel.ironLoss   = ironLoss;
                    app.motorModel.AOA        = [];
                    app.motorModel.Inductance = [];
                    app.motorModel.idiq       = [];
                    app.motorModel.dqtMapF    = [];
                    app.motorModel.data.tempPM = tempPM;
                    
                    app.motorModelUnScale = [];
                    app.motorModelUnSkew  = [];
                else
                    error('The selected map is done at a different temperature! Map not loaded');
                end
                
            end
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: LoadDQTmodelButton
        function LoadDQTmodelButtonPushed(app, event)
            FEAfolder = [app.motorModel.data.pathname app.motorModel.data.motorName '_results\FEA results\'];
            if ~exist(FEAfolder,'dir')
                FEAfolder = app.motorModel.data.pathname;
            end
            [filename,pathname] = uigetfile([FEAfolder '\*.mat'],'Load dqtMap model');
            if filename
                data = load([pathname filename]);
                if isfield(data,'dqtMap')
                    app.motorModel.dqtMap = data.dqtMap;
                elseif isfield(data,'F_map')
                    [dqtMap] = MMM_eval_dqtMap(pathname,filename);
                    app.motorModel.dqtMap = dqtMap;
                end
                app.motorModel.dqtMapF = [];
                
                app.motorModelUnScale = [];
                app.motorModelUnSkew  = [];
            end
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: dqtMapModelCheckBox
        function dqtMapModelCheckBoxValueChanged(app, event)
            value = app.dqtMapModelCheckBox.Value;
            if ~value
                app.motorModel.dqtMap = [];
                app.motorModel.dqtMapF = [];
            end
            MMM_GUI_SetParameters(app)
        end

        % Value changed function: IronLossModelCheckBox
        function IronLossModelCheckBoxValueChanged(app, event)
            value = app.IronLossModelCheckBox.Value;
            if ~value
                app.motorModel.ironLoss = [];
            end
            MMM_GUI_SetParameters(app)
        end

        % Value changed function: SkinEffectModelCheckBox
        function SkinEffectModelCheckBoxValueChanged(app, event)
            value = app.SkinEffectModelCheckBox.Value;
            if ~value
                app.motorModel.skinEffect = [];
            end
            MMM_GUI_SetParameters(app)
        end

        % Value changed function: AOACheckBox
        function AOACheckBoxValueChanged(app, event)
            value = app.AOACheckBox.Value;
            if ~value
                app.motorModel.AOA = [];
            end
            MMM_GUI_SetParameters(app)
        end

        % Value changed function: InductanceMapsCheckBox
        function InductanceMapsCheckBoxValueChanged(app, event)
            value = app.InductanceMapsCheckBox.Value;
            if ~value
                app.motorModel.Inductance = [];
            end
            MMM_GUI_SetParameters(app)
        end

        % Value changed function: InversedqCheckBox
        function InversedqCheckBoxValueChanged(app, event)
            value = app.InversedqCheckBox.Value;
            if ~value
                app.motorModel.idiq = [];
            end
            MMM_GUI_SetParameters(app)
        end

        % Value changed function: InversedqtMapCheckBox
        function InversedqtMapCheckBoxValueChanged(app, event)
            value = app.InversedqtMapCheckBox.Value;
            if ~value
                app.motorModel.dqtMapF = [];
            end
            MMM_GUI_SetParameters(app)
        end

        % Button pushed function: LoadIronLossModelButton
        function LoadIronLossModelButtonPushed(app, event)
            FEAfolder = [app.motorModel.data.pathname app.motorModel.data.motorName '_results\FEA results\'];
            if ~exist(FEAfolder,'dir')
                FEAfolder = app.motorModel.data.pathname;
            end
            [filename,pathname] = uigetfile([FEAfolder '\*.mat'],'Load iron loss model');
            if filename
                app.motorModel.ironLoss = loadIronLossModel([pathname filename]);
            end
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: LoadSkinEffectButton
        function LoadSkinEffectButtonPushed(app, event)
            FEAfolder = [app.motorModel.data.pathname app.motorModel.data.motorName '_results\FEA results\'];
            if ~exist(FEAfolder,'dir')
                FEAfolder = app.motorModel.data.pathname;
            end
            [filename,pathname] = uigetfile([FEAfolder '\*.mat'],'Load skin effect model');
            if filename
                app.motorModel.skinEffect = loadSkinEffectModel([pathname filename]);
            end
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: LoadMTPAButton
        function LoadMTPAButtonPushed(app, event)
            [AOA] = MMM_MTPAload(app.motorModel.data.pathname);
            app.motorModel.AOA = AOA;
            
            MMM_GUI_SetParameters(app)
        end

        % Button pushed function: PlotDQmodelButton
        function PlotDQmodelButtonPushed(app, event)
            MMM_plot_fdfq(app.motorModel);
        end

        % Button pushed function: PlotDQTmodelButton
        function PlotDQTmodelButtonPushed(app, event)
            MMM_plot_dqtMap(app.motorModel);
        end

        % Button pushed function: PlotIronLossModelButton
        function PlotIronLossModelButtonPushed(app, event)
            MMM_plot_ironLoss(app.motorModel);
        end

        % Button pushed function: PlotSkinEffectButton
        function PlotSkinEffectButtonPushed(app, event)
            MMM_plot_skinEffect(app.motorModel);
        end

        % Button pushed function: PlotMTPAButton
        function PlotMTPAButtonPushed(app, event)
            MMM_plot_MTPA(app.motorModel);
        end

        % Button pushed function: EvaluateMTPAButton
        function EvaluateMTPAButtonPushed(app, event)
            method = app.MTPAmethodDropDown.Value;
            [AOA] = MMM_eval_AOA(app.motorModel,method);
            app.motorModel.AOA = AOA;
            
            iamp = abs(app.motorModel.AOA.MTPA.id + j*app.motorModel.AOA.MTPA.iq);
            famp = abs(app.motorModel.AOA.MTPA.fd + j*app.motorModel.AOA.MTPA.fq);
            id0 = interp1(iamp,app.motorModel.AOA.MTPA.id,app.motorModel.data.i0);
            iq0 = interp1(iamp,app.motorModel.AOA.MTPA.iq,app.motorModel.data.i0);
            f0 = interp1(iamp,famp,app.motorModel.data.i0);
            T0 = interp1(iamp,app.motorModel.AOA.MTPA.T,app.motorModel.data.i0);
            
            app.motorModel.data.n0 = 30/pi/app.motorModel.data.p*(app.motorModel.data.Vdc/sqrt(3)/f0);
            app.motorModel.data.P0 = T0 * pi/30 * app.motorModel.data.n0;
            
            app.motorModel.dqtElab.CurrAngle = 180/pi * angle(id0 + j*iq0);
            
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: SaveDQmodelButton
        function SaveDQmodelButtonPushed(app, event)
            MMM_save_fdfq(app.motorModel)
        end

        % Button pushed function: SaveDQTmodelButton
        function SaveDQTmodelButtonPushed(app, event)
            MMM_save_dqtMap(app.motorModel)
        end

        % Button pushed function: SaveIronLossModelButton
        function SaveIronLossModelButtonPushed(app, event)
            MMM_save_ironLoss(app.motorModel);
        end

        % Button pushed function: SaveSkinEffectButton
        function SaveSkinEffectButtonPushed(app, event)
            MMM_save_skinEffect(app.motorModel);
        end

        % Button pushed function: SaveMTPAButton
        function SaveMTPAButtonPushed(app, event)
            MMM_save_AOA(app.motorModel);
        end

        % Button pushed function: PrintDQmodelButton
        function PrintDQmodelButtonPushed(app, event)
            MMM_print_fdfq(app.motorModel);
        end

        % Button pushed function: PrintMTPAButton
        function PrintMTPAButtonPushed(app, event)
            MMM_print_AOA(app.motorModel);
        end

        % Value changed function: MotornameEditField
        function MotornameEditFieldValueChanged(app, event)
            value = app.MotornameEditField.Value;
            app.motorModel.data.motorName = value;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: Numberof3phasesetsEditField
        function Numberof3phasesetsEditFieldValueChanged(app, event)
            value = app.Numberof3phasesetsEditField.Value;
            app.motorModel.data.n3phase = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: MotortypeEditField
        function MotortypeEditFieldValueChanged(app, event)
            value = app.MotortypeEditField.Value;
            app.motorModel.data.motorType = value;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: AxistypeEditField
        function AxistypeEditFieldValueChanged(app, event)
            value = app.AxistypeEditField.Value;
            app.motorModel.data.axisType = value;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: RatedpowerEditField
        function RatedpowerEditFieldValueChanged(app, event)
            value = app.RatedpowerEditField.Value;
            app.motorModel.data.P0 = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: RatedcurrentEditField
        function RatedcurrentEditFieldValueChanged(app, event)
            value = app.RatedcurrentEditField.Value;
            app.motorModel.data.i0 = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: MaximumcurrentEditField
        function MaximumcurrentEditFieldValueChanged(app, event)
            value = app.MaximumcurrentEditField.Value;
            value = eval(value);
            if ~isempty(app.motorModel.fdfq)
                value = min([value max(abs(app.motorModel.fdfq.Id),[],'all') max(abs(app.motorModel.fdfq.Iq),[],'all')]);
            end
            app.motorModel.data.Imax = value;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: DClinkvoltageEditField
        function DClinkvoltageEditFieldValueChanged(app, event)
            value = app.DClinkvoltageEditField.Value;
            app.motorModel.data.Vdc = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: RatedspeedEditField
        function RatedspeedEditFieldValueChanged(app, event)
            value = app.RatedspeedEditField.Value;
            app.motorModel.data.n0 = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: MaximumspeedEditField
        function MaximumspeedEditFieldValueChanged(app, event)
            value = app.MaximumspeedEditField.Value;
            app.motorModel.data.nmax = eval(value);
            app.motorModel.Tw.nmax = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: PhaseresistanceEditField
        function PhaseresistanceEditFieldValueChanged(app, event)
            value = app.PhaseresistanceEditField.Value;
            app.motorModel.data.Rs = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: WindingtemperatureEditField
        function WindingtemperatureEditFieldValueChanged(app, event)
            value = app.WindingtemperatureEditField.Value;
            tempNew = eval(value);
            app.motorModel.data.Rs = app.motorModel.data.Rs*(235+tempNew)/(235+app.motorModel.data.tempCu); % adapt the phase resistance to the new temperature
            app.motorModel.data.tempCu = tempNew;
            MMM_GUI_SetParameters(app);
        end

        % Callback function
        function PMtemperatureEditFieldValueChanged(app, event)
            value = app.PMtemperatureEditField.Value;
            app.motorModel.data.tempPM = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: ActivelengthEditField
        function ActivelengthEditFieldValueChanged(app, event)
            value = app.ActivelengthEditField.Value;
            app.motorModel.data.l = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: TurnsinseriesperphaseEditField
        function TurnsinseriesperphaseEditFieldValueChanged(app, event)
            value = app.TurnsinseriesperphaseEditField.Value;
            app.motorModel.data.Ns = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: EvalInverseDQButton
        function EvalInverseDQButtonPushed(app, event)
            [idiq] = MMM_eval_inverseModel_dq(app.motorModel);
            app.motorModel.idiq = idiq;
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: EvalInverseDQTButton
        function EvalInverseDQTButtonPushed(app, event)
            [dqtMapF] = MMM_eval_inverse_dqtMap(app.motorModel);
            app.motorModel.dqtMapF = dqtMapF;
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: SaveInverseDQButton
        function SaveInverseDQButtonPushed(app, event)
            MMM_save_idiq(app.motorModel);
        end

        % Button pushed function: SaveInverseDQTButton
        function SaveInverseDQTButtonPushed(app, event)
            MMM_save_dqtMapF(app.motorModel);
        end

        % Button pushed function: PlotInverseDQButton
        function PlotInverseDQButtonPushed(app, event)
            MMM_plot_idiq(app.motorModel);
        end

        % Button pushed function: PlotInverseDQTButton
        function PlotInverseDQTButtonPushed(app, event)
            MMM_plot_dqtMapF(app.motorModel);
        end

        % Value changed function: NumCurrLevelTgammaEditField
        function NumCurrLevelTgammaEditFieldValueChanged(app, event)
            value = app.NumCurrLevelTgammaEditField.Value;
            app.motorModel.data.nCurr = eval(value);
            MMM_GUI_SetParameters(app)
        end

        % Button pushed function: PlotTgammaButton
        function PlotTgammaButtonPushed(app, event)
            MMM_plot_VSgamma(app.motorModel);
        end

        % Callback function
        function DQTmodelCheckBoxValueChanged(app, event)
            value = app.DQTmodelCheckBox.Value;
            if ~value
                app.motorModel.dqtMap = [];
                app.motorModel.dqtMapF = [];
            end
            MMM_GUI_SetParameters(app)
        end

        % Callback function
        function DQTplotPushButtonPushed(app, event)
            MMM_plot_dqtMap(app.motorModel);
        end

        % Callback function
        function DQTsavePushButtonPushed(app, event)
            MMM_save_dqtMap(app.motorModel);
        end

        % Callback function
        function DQTevalPushButtonPushed(app, event)
            [dqtMap] = MMM_eval_dqtMap(app.motorModel.data.pathname);
            app.motorModel.dqtMap = dqtMap;
            
            MMM_GUI_SetParameters(app);
        end

        % Callback function
        function DQTgammaEditFieldValueChanged(app, event)
            
            value = app.WaveformGammaEditField.Value;
            app.motorModel.dqtElab.CurrAngle = eval(value);
            
            MMM_GUI_SetParameters(app);
        end

        % Callback function
        function DQTcurrentPUEditFieldValueChanged(app, event)
            value = app.WaveformCurrentPUEditField.Value;
            app.motorModel.dqtElab.CurrLoad = eval(value);
            app.motorModel.dqtElab.CurrAmpl = eval(value)*app.motorModel.data.i0;
            
            iamp = abs(app.motorModel.AOA.MTPA.id + j*app.motorModel.AOA.MTPA.iq);
            idref = interp1(iamp,app.motorModel.AOA.MTPA.id,eval(value)*app.motorModel.data.i0);
            iqref = interp1(iamp,app.motorModel.AOA.MTPA.iq,eval(value)*app.motorModel.data.i0);
            
            app.motorModel.dqtElab.CurrAngle = 180/pi * angle(idref + j*iqref);
            
            MMM_GUI_SetParameters(app);
        end

        % Callback function
        function DQTharmEditFieldValueChanged(app, event)
            value = app.DQTharmEditField.Value;
            app.motorModel.dqtElab.harmonic = eval(value);
            
            MMM_GUI_SetParameters(app);
        end

        % Callback function
        function DQTsingtButtonPushed(app, event)
            MMM_eval_OperatingPoint_dqtMap(app.motorModel);
        end

        % Callback function
        function DQTharmButtonPushed(app, event)
            MMM_plot_FFTdqtMap(app.motorModel);
        end

        % Value changed function: NewTurnsEditField
        function NewTurnsEditFieldValueChanged(app, event)
            value = app.NewTurnsEditField.Value;
            if isempty(app.motorModelUnScale)
                app.motorModelUnScale = app.motorModel;
            end
            app.motorModel.scale.Ns = eval(value);
            
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: NewLengthEditField
        function NewLengthEditFieldValueChanged(app, event)
            value = app.NewLengthEditField.Value;
            if isempty(app.motorModelUnScale)
                app.motorModelUnScale = app.motorModel;
            end
            app.motorModel.scale.l = eval(value);
            
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: NewLldEditField
        function NewLldEditFieldValueChanged(app, event)
            value = app.NewLldEditField.Value;
            if isempty(app.motorModelUnScale)
                app.motorModelUnScale = app.motorModel;
            end
            app.motorModel.scale.Lld = eval(value);
            
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: NewLlqEditField
        function NewLlqEditFieldValueChanged(app, event)
            value = app.NewLlqEditField.Value;
            if isempty(app.motorModelUnScale)
                app.motorModelUnScale = app.motorModel;
            end
            app.motorModel.scale.Llq = eval(value);
            
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: UnscaleModelPush
        function UnscaleModelPushButtonPushed(app, event)
            app.motorModel = app.motorModelUnScale;
            app.motorModelUnScale = [];
            
            cla(app.NewModelAxis);
            
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: ScaleModelPush
        function ScaleModelPushButtonPushed(app, event)
            app.motorModel = MMM_scale(app.motorModelUnScale,app.motorModel.scale);
            
            cla(app.NewModelAxis);
            MMM_plot_scale(app.NewModelAxis,app.motorModelUnScale,'-b','Original');
            MMM_plot_scale(app.NewModelAxis,app.motorModel,'--r','Scaled');
            if ~isempty(app.motorModelRef)
                MMM_plot_scale(app.NewModelAxis,app.motorModelRef,':k','Reference');
            end
        end

        % Button pushed function: ClearScaleAxisPush
        function ClearScaleAxisPushButtonPushed(app, event)
            cla(app.NewModelAxis);
            app.motorModelRef = [];
        end

        % Button pushed function: UnskewModelPush
        function UnskewModelPushButtonPushed(app, event)
            app.motorModel = app.motorModelUnSkew;
            app.motorModelUnSkew = [];
            
            cla(app.NewModelAxis);
            
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: SkewAngleEditField
        function SkewAngleEditFieldValueChanged(app, event)
            value = app.SkewAngleEditField.Value;
            if isempty(app.motorModelUnSkew)
                app.motorModelUnSkew = app.motorModel;
            end
            app.motorModel.skew.thSkw = eval(value);
            
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: SkewSliceEditField
        function SkewSliceEditFieldValueChanged(app, event)
            value = app.SkewSliceEditField.Value;
            if isempty(app.motorModelUnSkew)
                app.motorModelUnSkew = app.motorModel;
            end
            app.motorModel.skew.nSlice = eval(value);
            
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: SkewPointsEditField
        function SkewPointsEditFieldValueChanged(app, event)
            value = app.SkewPointsEditField.Value;
            if isempty(app.motorModelUnSkew)
                app.motorModelUnSkew = app.motorModel;
            end
            app.motorModel.skew.nPoints = eval(value);
            
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: SkewModelPush
        function SkewModelPushButtonPushed(app, event)
            app.motorModel = MMM_skew(app.motorModelUnSkew,app.motorModel.skew,app.dqtMapskewingevaluationEditField);
            
            cla(app.NewModelAxis);
            MMM_plot_scale(app.NewModelAxis,app.motorModelUnSkew,'-b','Straight');
            MMM_plot_scale(app.NewModelAxis,app.motorModel,'--r','Skewed');
            if ~isempty(app.motorModelRef)
                MMM_plot_scale(app.NewModelAxis,app.motorModelRef,':k','Reference');
            end
        end

        % Button pushed function: LoadRefModelPush
        function LoadRefModelPushButtonPushed(app, event)
            [filename,pathname] = uigetfile('*.mat');
            if filename
                data = load([pathname filename]);
                fdfq = MMM_load_fdfq(data,0);
                app.motorModelRef.fdfq     = fdfq;
                app.motorModelRef.dqtMap   = [];
                app.motorModelRef.ironLoss = [];
            else
                app.motorModelRef = app.motorModel;
            end
            MMM_GUI_SetParameters(app);
            MMM_plot_scale(app.NewModelAxis,app.motorModelRef,':k','Refefence');
        end

        % Callback function
        function ClearRefModelPushButtonPushed(app, event)
            app.motorModelRef = [];
            cla(app.NewModelAxis);
        end

        % Value changed function: OpLimLevelsEditField
        function OpLimLevelsEditFieldValueChanged(app, event)
            value = app.OpLimLevelsEditField.Value;
            app.motorModel.data.nCurr = eval(value);
            MMM_GUI_SetParameters(app)
        end

        % Button pushed function: OpLimPush
        function OpLimPushButtonPushed(app, event)
            MMM_eval_OpLim(app.motorModel);
        end

        % Button pushed function: RatingsEvalPush
        function RatingsEvalPushButtonPushed(app, event)
            app.motorModel = MMM_eval_Ratings(app.motorModel);
            
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: TwMinSpeedEditField
        function TwMinSpeedEditFieldValueChanged(app, event)
            value = app.TwMinSpeedEditField.Value;
            app.motorModel.Tw.nmin = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: TwMaxSpeedEditField
        function TwMaxSpeedEditFieldValueChanged(app, event)
            value = app.TwMaxSpeedEditField.Value;
            app.motorModel.Tw.nmax = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: TwNumSpeedEditField
        function TwNumSpeedEditFieldValueChanged(app, event)
            value = app.TwNumSpeedEditField.Value;
            app.motorModel.Tw.nstep = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: TwMinTorqueEditField
        function TwMinTorqueEditFieldValueChanged(app, event)
            value = app.TwMinTorqueEditField.Value;
            app.motorModel.Tw.Tmin = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: TwMaxTorqueEditField
        function TwMaxTorqueEditFieldValueChanged(app, event)
            value = app.TwMaxTorqueEditField.Value;
            app.motorModel.Tw.Tmax = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: TwNumTorqueEditField
        function TwNumTorqueEditFieldValueChanged(app, event)
            value = app.TwNumTorqueEditField.Value;
            app.motorModel.Tw.Tstep = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: TwTemperatureEditField
        function TwTemperatureEditFieldValueChanged(app, event)
            value = app.TwTemperatureEditField.Value;
            app.motorModel.Tw.temperature = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: TwMechLossEditField
        function TwMechLossEditFieldValueChanged(app, event)
            value = app.TwMechLossEditField.Value;
            app.motorModel.Tw.MechLoss = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: TwIronLossDropDown
        function TwIronLossDropDownValueChanged(app, event)
            value = app.TwIronLossDropDown.Value;
            if isempty(app.motorModel.ironLoss)
                value = 'No';
            end
            app.motorModel.Tw.IronLossFlag = value;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: TwIronLossFactorEditField
        function TwIronLossFactorEditFieldValueChanged(app, event)
            value = app.TwIronLossFactorEditField.Value;
            app.motorModel.Tw.IronLossFactor = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: TwSkinEffectDropDown
        function TwSkinEffectDropDownValueChanged(app, event)
            value = app.TwSkinEffectDropDown.Value;
            if isempty(app.motorModel.skinEffect)
                value = 'No';
            end
            app.motorModel.Tw.SkinEffectFlag = value;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: TwSkinEffectMethodDropDown
        function TwSkinEffectMethodDropDownValueChanged(app, event)
            value = app.TwSkinEffectMethodDropDown.Value;
            app.motorModel.Tw.SkinEffectMethod = value;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: TwControlDropDown
        function TwControlDropDownValueChanged(app, event)
            value = app.TwControlDropDown.Value;
            app.motorModel.Tw.Control = value;
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: MaxTwPush
        function MaxTwPushButtonPushed(app, event)
            MMM_MaxTw(app.motorModel,app.TwAxis);
            cla(app.TwAxis);
        end

        % Button pushed function: EvalInductanceButton
        function EvalInductanceButtonPushed(app, event)
            app.motorModel.Inductance = MMM_eval_inductanceMap(app.motorModel);
            
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: PlotInductanceButton
        function PlotInductanceButtonPushed(app, event)
            MMM_plot_InductanceMap(app.motorModel);
        end

        % Button pushed function: SaveInductanceButton
        function SaveInductanceButtonPushed(app, event)
            MMM_save_inductanceMap(app.motorModel);
        end

        % Value changed function: TwPMlossDropDown
        function TwPMlossDropDownValueChanged(app, event)
            value = app.TwPMlossDropDown.Value;
            app.motorModel.Tw.PMLossFlag = value;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: TwPMlossFactorEditField
        function TwPMlossFactorEditFieldValueChanged(app, event)
            value = app.TwPMlossFactorEditField.Value;
            app.motorModel.Tw.PMLossFactor = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            close_system('Syr_ctrl_SFun',0)
            app.motorModel = MMM_CtrlSIM(app.motorModel);
            MMM_GUI_SetParameters(app);          
            MMM_SIMplot(app.motorModel);
        end

        % Value changed function: InertiaEditField
        function InertiaEditFieldValueChanged(app, event)
            value = app.InertiaEditField.Value;
            app.motorModel.data.J = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: ControltypeDropDown
        function ControltypeDropDownValueChanged(app, event)
            value = app.ControltypeDropDown.Value;
            app.motorModel.SyreDrive.Ctrl_type = value;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: FluxmapsmodelDropDown
        function FluxmapsmodelDropDownValueChanged(app, event)
            value = app.FluxmapsmodelDropDown.Value;
            app.motorModel.SyreDrive.FMapsModel = value;
            MMM_GUI_SetParameters(app);
        end

        % Callback function
        function ONthreasholdEditFieldValueChanged(app, event)
            value = app.ONthreasholdEditField.Value;
            app.motorModel.SyreDrive.Converter.V0 = value;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: InternalresistanceEditField
        function InternalresistanceEditFieldValueChanged(app, event)
            value = app.InternalresistanceEditField.Value;
            app.motorModel.SyreDrive.Converter.Rd = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: DeadtimeEditField
        function DeadtimeEditFieldValueChanged(app, event)
            value = app.DeadtimeEditField.Value;
            app.motorModel.SyreDrive.Converter.dT = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: ONthreasholdEditField
        function ONthreasholdEditFieldValueChanged2(app, event)
            value = app.ONthreasholdEditField.Value;
            app.motorModel.SyreDrive.Converter.V0 = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: EvaluateShortCircuitTorqueButton
        function EvaluateShortCircuitTorqueButtonPushed(app, event)
            MMM_eval_shortCircuit(app.motorModel);
        end

        % Value changed function: PMtemperatureDropDown
        function PMtemperatureDropDownValueChanged(app, event)
            value = app.PMtemperatureDropDown.Value;
            if strcmp(value,'Add')
                % add new PM temperature
                FEAfolder = [app.motorModel.data.pathname app.motorModel.data.motorName '_results\FEA results\'];
                if ~exist(FEAfolder,'dir')
                    FEAfolder = app.motorModel.data.pathname;
                end
                [filename,pathname] = uigetfile([FEAfolder '\*.mat'],'Load new fdfq_idiq model');
                if filename
                    data = load([pathname filename]);
                    [fdfq,tempPM] = MMM_load_fdfq(data,app.motorModel.data.p);
                    tempVect = app.motorModel.data.tempVectPM;
                    if sum(tempVect==tempPM)
                        % the selected PM temperature is already loaded
                        msg = ['The maps at ' int2str(tempPM) ' deg are already loaded!'];
                        f = warndlg(msg,'WARNING','modal');
                    else
                        % loaded new PM temperature
                        motorModel = app.motorModel;
                        save([motorModel.data.pathname motorModel.data.motorName '_results\MMM results\tempModels\motorModel_' int2str(motorModel.data.tempPM) 'deg.mat'],'motorModel');
                        tempVect = sort([tempVect tempPM]);
                        
                        % check for dqtMap
                        if isfield(data,'dataSet')
                            if data.dataSet.NumOfRotPosPP>=20
                                dqtMap = MMM_eval_dqtMap(pathname,'F_map.mat');
                            else
                                dqtMap = [];
                            end
                        end
                        
                        % check for iron loss
                        ironLoss = loadIronLossModel([pathname filename]);
                        
                        motorModel.fdfq       = fdfq;
                        motorModel.dqtMap     = dqtMap;
                        motorModel.ironLoss   = ironLoss;
                        motorModel.AOA        = [];
                        motorModel.Inductance = [];
                        motorModel.idiq       = [];
                        motorModel.dqtMapF    = [];
                        
                        motorModel.data.tempPM     = tempPM;
                        motorModel.data.tempVectPM = tempVect;
                        
                        app.motorModel = motorModel;
                        
                    end
                end
            else
                % change motorModel according to PM temperature
                motorModel = app.motorModel;
                save([motorModel.data.pathname motorModel.data.motorName '_results\MMM results\tempModels\motorModel_' int2str(motorModel.data.tempPM) 'deg.mat'],'motorModel');
                
                tempPM = eval(value);
                tempVect = app.motorModel.data.tempVectPM;
                sourceFile = [app.motorModel.data.pathname app.motorModel.data.motorName '_results\MMM results\tempModels\motorModel_' value 'deg.mat'];
                if exist(sourceFile,'file')
                    tmp = load(sourceFile);
                    app.motorModel = tmp.motorModel;
                    app.motorModel.data.tempVectPM = tempVect;
                    app.motorModel = MMM_back_compatibility(app.motorModel,0);
                else
                    warndlg('Error in the file storage!!! Data not found!!!','Data Corrupted','modal');
                end
            end
            
            MMM_GUI_SetParameters(app);
            
        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
            app.motorModel        = [];
            app.motorModelRef     = [];
            app.motorModelUnScale = [];
            app.motorModelUnSkew  = [];
            
            [motorModel] = MMM_newModel();
            motorModel = MMM_back_compatibility(motorModel,0);
            
            save([motorModel.data.pathname motorModel.data.motorName '.mat'],'motorModel');
            
            app.motorModel = motorModel;
            
            cla(app.NewModelAxis);
            cla(app.TwAxis);
            
            MMM_GUI_SetParameters(app);
            
        end

        % Button pushed function: CloseallButton
        function CloseallButtonPushed(app, event)
            close all
            clc
            cla(app.NewModelAxis);
            cla(app.TwAxis);
        end

        % Value changed function: SensorlessSwitch
        function SensorlessSwitchValueChanged(app, event)
            value = app.SensorlessSwitch.Value;
            app.motorModel.SyreDrive.SS_on = value;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: DemodulationDropDown
        function DemodulationDropDownValueChanged(app, event)
            value = app.DemodulationDropDown.Value;
            app.motorModel.SyreDrive.SS_settings.dem = value;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: InjectedsignalDropDown
        function InjectedsignalDropDownValueChanged(app, event)
            value = app.InjectedsignalDropDown.Value;
            app.motorModel.SyreDrive.SS_settings.inj_waveform = value;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: PositionerrorestimationDropDown
        function PositionerrorestimationDropDownValueChanged(app, event)
            value = app.PositionerrorestimationDropDown.Value;
            app.motorModel.SyreDrive.SS_settings.HS_ctrl = value;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: WaveformGammaEditField
        function WaveformGammaEditFieldValueChanged(app, event)
            value = app.WaveformGammaEditField.Value;
            app.motorModel.WaveformSetup.CurrAngle = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: WaveformCurrentPUEditField
        function WaveformCurrentPUEditFieldValueChanged(app, event)
            value = app.WaveformCurrentPUEditField.Value;
            app.motorModel.WaveformSetup.CurrLoad = eval(value);
            app.motorModel.WaveformSetup.CurrAmpl = eval(value)*app.motorModel.data.i0;
            if ~isempty(app.motorModel.AOA)
                app.motorModel.WaveformSetup.CurrAngle = interp1(...
                    abs(app.motorModel.AOA.MTPA.id+j*app.motorModel.AOA.MTPA.iq),...
                    angle(app.motorModel.AOA.MTPA.id+j*app.motorModel.AOA.MTPA.iq)*180/pi,...
                    app.motorModel.WaveformSetup.CurrAmpl);
            end
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: WaveformSpeedEditField
        function WaveformSpeedEditFieldValueChanged(app, event)
            value = app.WaveformSpeedEditField.Value;
            app.motorModel.WaveformSetup.EvalSpeed = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: WaveformPeriodsEditField
        function WaveformPeriodsEditFieldValueChanged(app, event)
            value = app.WaveformPeriodsEditField.Value;
            value = round(eval(value));
            app.motorModel.WaveformSetup.nCycle = value;
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: WaveformSingPointButton
        function WaveformSingPointButtonPushed(app, event)
            MMM_eval_OperatingPoint_dqtMap(app.motorModel);
        end

        % Button pushed function: WaveformShortCircuitButton
        function WaveformShortCircuitButtonPushed(app, event)
            if ((length(app.motorModel.WaveformSetup.CurrLoad)>1)||(length(app.motorModel.WaveformSetup.CurrAmpl)>1)||(length(app.motorModel.WaveformSetup.CurrAngle)>1))
                app.motorModel.WaveformSetup.CurrLoad  = app.motorModel.WaveformSetup.CurrLoad(1);
                app.motorModel.WaveformSetup.CurrAmpl  = app.motorModel.WaveformSetup.CurrAmpl(1);
                app.motorModel.WaveformSetup.CurrAngle = app.motorModel.WaveformSetup.CurrAngle(1);
                MMM_GUI_SetParameters(app);
            end
            MMM_eval_shortCircuitTransient(app.motorModel);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'GUI_Syre_MMM';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.ModelsLoadedPanel = uipanel(app.MainTab);
            app.ModelsLoadedPanel.Title = 'Models Loaded';
            app.PlotSkinEffectButton = uibutton(app.ModelsLoadedPanel, 'push');
            app.PlotSkinEffectButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSkinEffectButtonPushed, true);
            app.PlotSkinEffectButton.Text = 'Plot';
            app.SaveSkinEffectButton = uibutton(app.ModelsLoadedPanel, 'push');
            app.SaveSkinEffectButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSkinEffectButtonPushed, true);
            app.SaveSkinEffectButton.Text = 'Save';
            app.PlotIronLossModelButton = uibutton(app.ModelsLoadedPanel, 'push');
            app.PlotIronLossModelButton.ButtonPushedFcn = createCallbackFcn(app, @PlotIronLossModelButtonPushed, true);
            app.PlotIronLossModelButton.Text = 'Plot';
            app.SaveIronLossModelButton = uibutton(app.ModelsLoadedPanel, 'push');
            app.SaveIronLossModelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveIronLossModelButtonPushed, true);
            app.SaveIronLossModelButton.Text = 'Save';
            app.SaveDQTmodelButton = uibutton(app.ModelsLoadedPanel, 'push');
            app.SaveDQTmodelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDQTmodelButtonPushed, true);
            app.SaveDQTmodelButton.Text = 'Save';
            app.SaveDQmodelButton = uibutton(app.ModelsLoadedPanel, 'push');
            app.SaveDQmodelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDQmodelButtonPushed, true);
            app.SaveDQmodelButton.Text = 'Save';
            app.PlotDQTmodelButton = uibutton(app.ModelsLoadedPanel, 'push');
            app.PlotDQTmodelButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDQTmodelButtonPushed, true);
            app.PlotDQTmodelButton.Text = 'Plot';
            app.PlotDQmodelButton = uibutton(app.ModelsLoadedPanel, 'push');
            app.PlotDQmodelButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDQmodelButtonPushed, true);
            app.PlotDQmodelButton.Text = 'Plot';
            app.LoadSkinEffectButton = uibutton(app.ModelsLoadedPanel, 'push');
            app.LoadSkinEffectButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSkinEffectButtonPushed, true);
            app.LoadSkinEffectButton.Text = 'Load';
            app.LoadIronLossModelButton = uibutton(app.ModelsLoadedPanel, 'push');
            app.LoadIronLossModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadIronLossModelButtonPushed, true);
            app.LoadIronLossModelButton.Text = 'Load';
            app.LoadDQTmodelButton = uibutton(app.ModelsLoadedPanel, 'push');
            app.LoadDQTmodelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDQTmodelButtonPushed, true);
            app.LoadDQTmodelButton.Text = 'Load';
            app.LoadDQmodelButton = uibutton(app.ModelsLoadedPanel, 'push');
            app.LoadDQmodelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDQmodelButtonPushed, true);
            app.LoadDQmodelButton.Text = 'Load';
            app.SkinEffectModelCheckBox = uicheckbox(app.ModelsLoadedPanel);
            app.SkinEffectModelCheckBox.ValueChangedFcn = createCallbackFcn(app, @SkinEffectModelCheckBoxValueChanged, true);
            app.SkinEffectModelCheckBox.Text = 'Skin Effect Model';
            app.IronLossModelCheckBox = uicheckbox(app.ModelsLoadedPanel);
            app.IronLossModelCheckBox.ValueChangedFcn = createCallbackFcn(app, @IronLossModelCheckBoxValueChanged, true);
            app.IronLossModelCheckBox.Text = 'Iron Loss Model';
            app.dqtMapModelCheckBox = uicheckbox(app.ModelsLoadedPanel);
            app.dqtMapModelCheckBox.ValueChangedFcn = createCallbackFcn(app, @dqtMapModelCheckBoxValueChanged, true);
            app.dqtMapModelCheckBox.Text = 'dqtMap Model';
            app.dqModelCheckBox = uicheckbox(app.ModelsLoadedPanel);
            app.dqModelCheckBox.ValueChangedFcn = createCallbackFcn(app, @dqModelCheckBoxValueChanged, true);
            app.dqModelCheckBox.Text = 'dq Model';
            app.PrintDQmodelButton = uibutton(app.ModelsLoadedPanel, 'push');
            app.PrintDQmodelButton.ButtonPushedFcn = createCallbackFcn(app, @PrintDQmodelButtonPushed, true);
            app.PrintDQmodelButton.Text = 'Print';
            app.MTPAMTPVPanel = uipanel(app.MainTab);
            app.MTPAMTPVPanel.Title = 'MTPA-MTPV';
            app.AOACheckBox = uicheckbox(app.MTPAMTPVPanel);
            app.AOACheckBox.ValueChangedFcn = createCallbackFcn(app, @AOACheckBoxValueChanged, true);
            app.AOACheckBox.Text = 'MTPA/MTPV';
            app.EvaluateMTPAButton = uibutton(app.MTPAMTPVPanel, 'push');
            app.EvaluateMTPAButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateMTPAButtonPushed, true);
            app.EvaluateMTPAButton.Text = 'Evaluate';
            app.MTPAmethodDropDown = uidropdown(app.MTPAMTPVPanel);
            app.MTPAmethodDropDown.Items = {'LUT', 'Fit'};
            app.MTPAmethodDropDown.Value = 'LUT';
            app.LoadMTPAButton = uibutton(app.MTPAMTPVPanel, 'push');
            app.LoadMTPAButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMTPAButtonPushed, true);
            app.LoadMTPAButton.Text = 'Load';
            app.PlotMTPAButton = uibutton(app.MTPAMTPVPanel, 'push');
            app.PlotMTPAButton.ButtonPushedFcn = createCallbackFcn(app, @PlotMTPAButtonPushed, true);
            app.PlotMTPAButton.Text = 'Plot';
            app.SaveMTPAButton = uibutton(app.MTPAMTPVPanel, 'push');
            app.SaveMTPAButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMTPAButtonPushed, true);
            app.SaveMTPAButton.Text = 'Save';
            app.PrintMTPAButton = uibutton(app.MTPAMTPVPanel, 'push');
            app.PrintMTPAButton.ButtonPushedFcn = createCallbackFcn(app, @PrintMTPAButtonPushed, true);
            app.PrintMTPAButton.Text = 'Print';
            app.InductanceandAnisotropyMapsPanel = uipanel(app.MainTab);
            app.InductanceandAnisotropyMapsPanel.Title = 'Inductance and Anisotropy Maps';
            app.InductanceMapsCheckBox = uicheckbox(app.InductanceandAnisotropyMapsPanel);
            app.InductanceMapsCheckBox.ValueChangedFcn = createCallbackFcn(app, @InductanceMapsCheckBoxValueChanged, true);
            app.InductanceMapsCheckBox.Text = 'Inductance Maps';
            app.EvalInductanceButton = uibutton(app.InductanceandAnisotropyMapsPanel, 'push');
            app.EvalInductanceButton.ButtonPushedFcn = createCallbackFcn(app, @EvalInductanceButtonPushed, true);
            app.EvalInductanceButton.Text = 'Eval';
            app.PlotInductanceButton = uibutton(app.InductanceandAnisotropyMapsPanel, 'push');
            app.PlotInductanceButton.ButtonPushedFcn = createCallbackFcn(app, @PlotInductanceButtonPushed, true);
            app.PlotInductanceButton.Text = 'Plot';
            app.SaveInductanceButton = uibutton(app.InductanceandAnisotropyMapsPanel, 'push');
            app.SaveInductanceButton.ButtonPushedFcn = createCallbackFcn(app, @SaveInductanceButtonPushed, true);
            app.SaveInductanceButton.Text = 'Save';
            app.CurrentAngleCurvesPanel = uipanel(app.MainTab);
            app.CurrentAngleCurvesPanel.Title = 'Current Angle Curves';
            app.ofcurrentlevelsEditField_2Label = uilabel(app.CurrentAngleCurvesPanel);
            app.ofcurrentlevelsEditField_2Label.Text = '# of current levels';
            app.NumCurrLevelTgammaEditField = uieditfield(app.CurrentAngleCurvesPanel, 'text');
            app.NumCurrLevelTgammaEditField.ValueChangedFcn = createCallbackFcn(app, @NumCurrLevelTgammaEditFieldValueChanged, true);
            app.PlotTgammaButton = uibutton(app.CurrentAngleCurvesPanel, 'push');
            app.PlotTgammaButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTgammaButtonPushed, true);
            app.PlotTgammaButton.Text = 'Plot';
            app.InverseModelPanel = uipanel(app.MainTab);
            app.InverseModelPanel.Title = 'Inverse Model';
            app.InversedqCheckBox = uicheckbox(app.InverseModelPanel);
            app.InversedqCheckBox.ValueChangedFcn = createCallbackFcn(app, @InversedqCheckBoxValueChanged, true);
            app.InversedqCheckBox.Text = 'Inverse dq';
            app.InversedqtMapCheckBox = uicheckbox(app.InverseModelPanel);
            app.InversedqtMapCheckBox.ValueChangedFcn = createCallbackFcn(app, @InversedqtMapCheckBoxValueChanged, true);
            app.InversedqtMapCheckBox.Text = 'Inverse dqtMap';
            app.EvalInverseDQTButton = uibutton(app.InverseModelPanel, 'push');
            app.EvalInverseDQTButton.ButtonPushedFcn = createCallbackFcn(app, @EvalInverseDQTButtonPushed, true);
            app.EvalInverseDQTButton.Text = 'Eval';
            app.PlotInverseDQTButton = uibutton(app.InverseModelPanel, 'push');
            app.PlotInverseDQTButton.ButtonPushedFcn = createCallbackFcn(app, @PlotInverseDQTButtonPushed, true);
            app.PlotInverseDQTButton.Text = 'Plot';
            app.SaveInverseDQTButton = uibutton(app.InverseModelPanel, 'push');
            app.SaveInverseDQTButton.ButtonPushedFcn = createCallbackFcn(app, @SaveInverseDQTButtonPushed, true);
            app.SaveInverseDQTButton.Text = 'Save';
            app.EvalInverseDQButton = uibutton(app.InverseModelPanel, 'push');
            app.EvalInverseDQButton.ButtonPushedFcn = createCallbackFcn(app, @EvalInverseDQButtonPushed, true);
            app.EvalInverseDQButton.Text = 'Eval';
            app.PlotInverseDQButton = uibutton(app.InverseModelPanel, 'push');
            app.PlotInverseDQButton.ButtonPushedFcn = createCallbackFcn(app, @PlotInverseDQButtonPushed, true);
            app.PlotInverseDQButton.Text = 'Plot';
            app.SaveInverseDQButton = uibutton(app.InverseModelPanel, 'push');
            app.SaveInverseDQButton.ButtonPushedFcn = createCallbackFcn(app, @SaveInverseDQButtonPushed, true);
            app.SaveInverseDQButton.Text = 'Save';
            app.ShortCircuitTorquePanel = uipanel(app.MainTab);
            app.ShortCircuitTorquePanel.Title = 'Short Circuit Torque';
            app.EvaluateShortCircuitTorqueButton = uibutton(app.ShortCircuitTorquePanel, 'push');
            app.EvaluateShortCircuitTorqueButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateShortCircuitTorqueButtonPushed, true);
            app.EvaluateShortCircuitTorqueButton.Text = 'Evaluate';
            app.ScalingSkewingTab = uitab(app.TabGroup);
            app.ScalingSkewingTab.Title = 'Scaling & Skewing';
            app.ModelScalingPanel = uipanel(app.ScalingSkewingTab);
            app.ModelScalingPanel.Title = 'Model Scaling';
            app.TurnsinseriesperphaseEditField_2Label = uilabel(app.ModelScalingPanel);
            app.TurnsinseriesperphaseEditField_2Label.Text = 'Turns in series per phase';
            app.NewTurnsEditField = uieditfield(app.ModelScalingPanel, 'text');
            app.NewTurnsEditField.ValueChangedFcn = createCallbackFcn(app, @NewTurnsEditFieldValueChanged, true);
            app.ActivelengthmmEditFieldLabel = uilabel(app.ModelScalingPanel);
            app.ActivelengthmmEditFieldLabel.Text = 'Active length [mm]';
            app.NewLengthEditField = uieditfield(app.ModelScalingPanel, 'text');
            app.NewLengthEditField.ValueChangedFcn = createCallbackFcn(app, @NewLengthEditFieldValueChanged, true);
            app.AdditionaldaxisinductanceHEditFieldLabel = uilabel(app.ModelScalingPanel);
            app.AdditionaldaxisinductanceHEditFieldLabel.Text = 'Additional d-axis inductance [H]';
            app.NewLldEditField = uieditfield(app.ModelScalingPanel, 'text');
            app.NewLldEditField.ValueChangedFcn = createCallbackFcn(app, @NewLldEditFieldValueChanged, true);
            app.AdditionalqaxisinductanceHEditFieldLabel = uilabel(app.ModelScalingPanel);
            app.AdditionalqaxisinductanceHEditFieldLabel.Text = 'Additional q-axis inductance [H]';
            app.NewLlqEditField = uieditfield(app.ModelScalingPanel, 'text');
            app.NewLlqEditField.ValueChangedFcn = createCallbackFcn(app, @NewLlqEditFieldValueChanged, true);
            app.ScaleModelPush = uibutton(app.ModelScalingPanel, 'push');
            app.ScaleModelPush.ButtonPushedFcn = createCallbackFcn(app, @ScaleModelPushButtonPushed, true);
            app.ScaleModelPush.Text = 'Scale Model';
            app.UnscaleModelPush = uibutton(app.ModelScalingPanel, 'push');
            app.UnscaleModelPush.ButtonPushedFcn = createCallbackFcn(app, @UnscaleModelPushButtonPushed, true);
            app.UnscaleModelPush.Text = 'Cancel';
            app.LoadRefModelPush = uibutton(app.ScalingSkewingTab, 'push');
            app.LoadRefModelPush.ButtonPushedFcn = createCallbackFcn(app, @LoadRefModelPushButtonPushed, true);
            app.LoadRefModelPush.Text = 'Load Reference Model';
            app.ModelSkewingPanel = uipanel(app.ScalingSkewingTab);
            app.ModelSkewingPanel.Title = 'Model Skewing';
            app.SkewanglemechdegEditFieldLabel_2 = uilabel(app.ModelSkewingPanel);
            app.SkewanglemechdegEditFieldLabel_2.Text = 'Skew angle [mech deg]';
            app.SkewAngleEditField = uieditfield(app.ModelSkewingPanel, 'text');
            app.SkewAngleEditField.ValueChangedFcn = createCallbackFcn(app, @SkewAngleEditFieldValueChanged, true);
            app.NumberofaxialslicesEditFieldLabel_2 = uilabel(app.ModelSkewingPanel);
            app.NumberofaxialslicesEditFieldLabel_2.Text = 'Number of axial slices';
            app.SkewSliceEditField = uieditfield(app.ModelSkewingPanel, 'text');
            app.SkewSliceEditField.ValueChangedFcn = createCallbackFcn(app, @SkewSliceEditFieldValueChanged, true);
            app.ofpointsalongoneaxisLabel = uilabel(app.ModelSkewingPanel);
            app.ofpointsalongoneaxisLabel.Text = '# of points along one axis';
            app.SkewPointsEditField = uieditfield(app.ModelSkewingPanel, 'text');
            app.SkewPointsEditField.ValueChangedFcn = createCallbackFcn(app, @SkewPointsEditFieldValueChanged, true);
            app.SkewModelPush = uibutton(app.ModelSkewingPanel, 'push');
            app.SkewModelPush.ButtonPushedFcn = createCallbackFcn(app, @SkewModelPushButtonPushed, true);
            app.SkewModelPush.Text = 'Skew Model';
            app.UnskewModelPush = uibutton(app.ModelSkewingPanel, 'push');
            app.UnskewModelPush.ButtonPushedFcn = createCallbackFcn(app, @UnskewModelPushButtonPushed, true);
            app.UnskewModelPush.Text = 'Cancel';
            app.dqtMapskewingevaluationEditFieldLabel = uilabel(app.ModelSkewingPanel);
            app.dqtMapskewingevaluationEditFieldLabel.Text = 'dqtMap skewing evaluation';
            app.dqtMapskewingevaluationEditField = uieditfield(app.ModelSkewingPanel, 'text');
            app.ClearScaleAxisPush = uibutton(app.ScalingSkewingTab, 'push');
            app.ClearScaleAxisPush.ButtonPushedFcn = createCallbackFcn(app, @ClearScaleAxisPushButtonPushed, true);
            app.ClearScaleAxisPush.Text = 'Clear Axis';
            app.NewModelAxis = uiaxes(app.ScalingSkewingTab);
            title(app.NewModelAxis, 'Magnetic Model Comparison')
            xlabel(app.NewModelAxis, 'i_{dq} [A]')
            ylabel(app.NewModelAxis, '\lambda_{dq} [Vs]')
            app.TorqueSpeedTab = uitab(app.TabGroup);
            app.TorqueSpeedTab.Title = 'Torque-Speed';
            app.OperatingLimitsPanel = uipanel(app.TorqueSpeedTab);
            app.OperatingLimitsPanel.Title = 'Operating Limits';
            app.ofcurrentlevelsEditFieldLabel = uilabel(app.OperatingLimitsPanel);
            app.ofcurrentlevelsEditFieldLabel.Text = '# of current levels';
            app.OpLimLevelsEditField = uieditfield(app.OperatingLimitsPanel, 'text');
            app.OpLimLevelsEditField.ValueChangedFcn = createCallbackFcn(app, @OpLimLevelsEditFieldValueChanged, true);
            app.OpLimPush = uibutton(app.OperatingLimitsPanel, 'push');
            app.OpLimPush.ButtonPushedFcn = createCallbackFcn(app, @OpLimPushButtonPushed, true);
            app.OpLimPush.Text = 'Evaluate';
            app.RatingsEvalPush = uibutton(app.OperatingLimitsPanel, 'push');
            app.RatingsEvalPush.ButtonPushedFcn = createCallbackFcn(app, @RatingsEvalPushButtonPushed, true);
            app.RatingsEvalPush.Text = 'Ratings evaluation';
            app.EfficiencyMapPanel = uipanel(app.TorqueSpeedTab);
            app.EfficiencyMapPanel.Title = 'Efficiency Map';
            app.GridLayout2 = uigridlayout(app.EfficiencyMapPanel);
            app.SpeedlimitsrpmEditFieldLabel = uilabel(app.GridLayout2);
            app.SpeedlimitsrpmEditFieldLabel.Text = 'Speed limits [rpm]';
            app.TwMinSpeedEditField = uieditfield(app.GridLayout2, 'text');
            app.TwMinSpeedEditField.ValueChangedFcn = createCallbackFcn(app, @TwMinSpeedEditFieldValueChanged, true);
            app.MinLabel = uilabel(app.GridLayout2);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.GridLayout2);
            app.MaxLabel.Text = 'Max';
            app.ofpointsLabel = uilabel(app.GridLayout2);
            app.ofpointsLabel.Text = '# of points';
            app.TwMaxSpeedEditField = uieditfield(app.GridLayout2, 'text');
            app.TwMaxSpeedEditField.ValueChangedFcn = createCallbackFcn(app, @TwMaxSpeedEditFieldValueChanged, true);
            app.TwNumSpeedEditField = uieditfield(app.GridLayout2, 'text');
            app.TwNumSpeedEditField.ValueChangedFcn = createCallbackFcn(app, @TwNumSpeedEditFieldValueChanged, true);
            app.TwMaxTorqueEditField = uieditfield(app.GridLayout2, 'text');
            app.TwMaxTorqueEditField.ValueChangedFcn = createCallbackFcn(app, @TwMaxTorqueEditFieldValueChanged, true);
            app.TwNumTorqueEditField = uieditfield(app.GridLayout2, 'text');
            app.TwNumTorqueEditField.ValueChangedFcn = createCallbackFcn(app, @TwNumTorqueEditFieldValueChanged, true);
            app.SpeedlimitsrpmEditFieldLabel_2 = uilabel(app.GridLayout2);
            app.SpeedlimitsrpmEditFieldLabel_2.Text = 'Torque limits [Nm]';
            app.TwMinTorqueEditField = uieditfield(app.GridLayout2, 'text');
            app.TwMinTorqueEditField.ValueChangedFcn = createCallbackFcn(app, @TwMinTorqueEditFieldValueChanged, true);
            app.WindingtemperatureCLabel = uilabel(app.GridLayout2);
            app.WindingtemperatureCLabel.Text = 'Winding temperature [°C]';
            app.TwTemperatureEditField = uieditfield(app.GridLayout2, 'text');
            app.TwTemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @TwTemperatureEditFieldValueChanged, true);
            app.IronlossDropDownLabel = uilabel(app.GridLayout2);
            app.IronlossDropDownLabel.Text = 'Iron loss';
            app.TwIronLossDropDown = uidropdown(app.GridLayout2);
            app.TwIronLossDropDown.Items = {'Yes', 'No'};
            app.TwIronLossDropDown.ValueChangedFcn = createCallbackFcn(app, @TwIronLossDropDownValueChanged, true);
            app.TwIronLossDropDown.Value = 'Yes';
            app.SkineffectDropDownLabel = uilabel(app.GridLayout2);
            app.SkineffectDropDownLabel.Text = 'Skin effect';
            app.TwSkinEffectDropDown = uidropdown(app.GridLayout2);
            app.TwSkinEffectDropDown.Items = {'Yes', 'No'};
            app.TwSkinEffectDropDown.ValueChangedFcn = createCallbackFcn(app, @TwSkinEffectDropDownValueChanged, true);
            app.TwSkinEffectDropDown.Value = 'Yes';
            app.MechlosspolyLabel = uilabel(app.GridLayout2);
            app.MechlosspolyLabel.Text = 'Mech. loss poly';
            app.TwMechLossEditField = uieditfield(app.GridLayout2, 'text');
            app.TwMechLossEditField.ValueChangedFcn = createCallbackFcn(app, @TwMechLossEditFieldValueChanged, true);
            app.ControlstrategyDropDownLabel = uilabel(app.GridLayout2);
            app.ControlstrategyDropDownLabel.Text = 'Control strategy';
            app.TwControlDropDown = uidropdown(app.GridLayout2);
            app.TwControlDropDown.Items = {'Maximum efficiency', 'MTPA'};
            app.TwControlDropDown.ValueChangedFcn = createCallbackFcn(app, @TwControlDropDownValueChanged, true);
            app.TwControlDropDown.Value = 'Maximum efficiency';
            app.IronlossfactorLabel = uilabel(app.GridLayout2);
            app.IronlossfactorLabel.Text = 'Iron loss factor';
            app.TwIronLossFactorEditField = uieditfield(app.GridLayout2, 'text');
            app.TwIronLossFactorEditField.ValueChangedFcn = createCallbackFcn(app, @TwIronLossFactorEditFieldValueChanged, true);
            app.MethodDropDownLabel = uilabel(app.GridLayout2);
            app.MethodDropDownLabel.Text = 'Method';
            app.TwSkinEffectMethodDropDown = uidropdown(app.GridLayout2);
            app.TwSkinEffectMethodDropDown.Items = {'LUT', 'Fit'};
            app.TwSkinEffectMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @TwSkinEffectMethodDropDownValueChanged, true);
            app.TwSkinEffectMethodDropDown.Value = 'LUT';
            app.MaxTwPush = uibutton(app.GridLayout2, 'push');
            app.MaxTwPush.ButtonPushedFcn = createCallbackFcn(app, @MaxTwPushButtonPushed, true);
            app.MaxTwPush.Text = 'Evaluate';
            app.PMlossDropDownLabel = uilabel(app.GridLayout2);
            app.PMlossDropDownLabel.Text = 'PM loss';
            app.TwPMlossDropDown = uidropdown(app.GridLayout2);
            app.TwPMlossDropDown.Items = {'Yes', 'No'};
            app.TwPMlossDropDown.ValueChangedFcn = createCallbackFcn(app, @TwPMlossDropDownValueChanged, true);
            app.TwPMlossDropDown.Value = 'Yes';
            app.PMlossfactorEditFieldLabel = uilabel(app.GridLayout2);
            app.PMlossfactorEditFieldLabel.Text = 'PM loss factor';
            app.TwPMlossFactorEditField = uieditfield(app.GridLayout2, 'text');
            app.TwPMlossFactorEditField.ValueChangedFcn = createCallbackFcn(app, @TwPMlossFactorEditFieldValueChanged, true);
            app.TwAxis = uiaxes(app.GridLayout2);
            title(app.TwAxis, 'T-n feasible points')
            xlabel(app.TwAxis, 'n [rpm]')
            ylabel(app.TwAxis, 'T [Nm]')
            app.syreDriveTab = uitab(app.TabGroup);
            app.syreDriveTab.Title = 'syreDrive';
            app.ConverterdataPanel = uipanel(app.syreDriveTab);
            app.ConverterdataPanel.Title = 'Converter data';
            app.ONthreasholdEditFieldLabel = uilabel(app.ConverterdataPanel);
            app.ONthreasholdEditFieldLabel.Text = 'ON threashold';
            app.ONthreasholdEditField = uieditfield(app.ConverterdataPanel, 'text');
            app.ONthreasholdEditField.ValueChangedFcn = createCallbackFcn(app, @ONthreasholdEditFieldValueChanged2, true);
            app.InternalresistanceEditFieldLabel = uilabel(app.ConverterdataPanel);
            app.InternalresistanceEditFieldLabel.Text = 'Internal resistance';
            app.InternalresistanceEditField = uieditfield(app.ConverterdataPanel, 'text');
            app.InternalresistanceEditField.ValueChangedFcn = createCallbackFcn(app, @InternalresistanceEditFieldValueChanged, true);
            app.DeadtimeEditFieldLabel = uilabel(app.ConverterdataPanel);
            app.DeadtimeEditFieldLabel.Text = 'Dead time';
            app.DeadtimeEditField = uieditfield(app.ConverterdataPanel, 'text');
            app.DeadtimeEditField.ValueChangedFcn = createCallbackFcn(app, @DeadtimeEditFieldValueChanged, true);
            app.VLabel_2 = uilabel(app.ConverterdataPanel);
            app.VLabel_2.Text = '[V]';
            app.OhmLabel_2 = uilabel(app.ConverterdataPanel);
            app.OhmLabel_2.Text = '[Ohm]';
            app.nsLabel = uilabel(app.ConverterdataPanel);
            app.nsLabel.Text = '[ns]';
            app.ControlsetupsPanel = uipanel(app.syreDriveTab);
            app.ControlsetupsPanel.Title = 'Control setups';
            app.ControltypeDropDownLabel = uilabel(app.ControlsetupsPanel);
            app.ControltypeDropDownLabel.Text = 'Control type';
            app.ControltypeDropDown = uidropdown(app.ControlsetupsPanel);
            app.ControltypeDropDown.Items = {'Current control', 'Torque control', 'Speed control'};
            app.ControltypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ControltypeDropDownValueChanged, true);
            app.ControltypeDropDown.Value = 'Current control';
            app.FluxmapsmodelDropDownLabel = uilabel(app.ControlsetupsPanel);
            app.FluxmapsmodelDropDownLabel.Text = 'Flux maps model';
            app.FluxmapsmodelDropDown = uidropdown(app.ControlsetupsPanel);
            app.FluxmapsmodelDropDown.Items = {'dq Model', 'dqt Model'};
            app.FluxmapsmodelDropDown.ValueChangedFcn = createCallbackFcn(app, @FluxmapsmodelDropDownValueChanged, true);
            app.FluxmapsmodelDropDown.Value = 'dq Model';
            app.RUNButton = uibutton(app.syreDriveTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.SensorlesscontrolPanel = uipanel(app.syreDriveTab);
            app.SensorlesscontrolPanel.Title = 'Sensorless control';
            app.LowspeedregionPanel = uipanel(app.SensorlesscontrolPanel);
            app.LowspeedregionPanel.Title = 'Low speed region';
            app.InjectedsignalDropDownLabel = uilabel(app.LowspeedregionPanel);
            app.InjectedsignalDropDownLabel.Text = 'Injected signal';
            app.InjectedsignalDropDown = uidropdown(app.LowspeedregionPanel);
            app.InjectedsignalDropDown.Items = {'Sinusoidal', 'Squarewave'};
            app.InjectedsignalDropDown.ValueChangedFcn = createCallbackFcn(app, @InjectedsignalDropDownValueChanged, true);
            app.InjectedsignalDropDown.Value = 'Sinusoidal';
            app.DemodulationDropDownLabel = uilabel(app.LowspeedregionPanel);
            app.DemodulationDropDownLabel.Text = 'Demodulation';
            app.DemodulationDropDown = uidropdown(app.LowspeedregionPanel);
            app.DemodulationDropDown.Items = {'Current', 'Flux'};
            app.DemodulationDropDown.ValueChangedFcn = createCallbackFcn(app, @DemodulationDropDownValueChanged, true);
            app.DemodulationDropDown.Value = 'Current';
            app.HFvoltageinjectionLabel = uilabel(app.LowspeedregionPanel);
            app.HFvoltageinjectionLabel.Text = 'HF voltage injection';
            app.HighspeedregionPanel = uipanel(app.SensorlesscontrolPanel);
            app.HighspeedregionPanel.Title = 'High speed region';
            app.PositionerrorestimationDropDownLabel = uilabel(app.HighspeedregionPanel);
            app.PositionerrorestimationDropDownLabel.Text = 'Position error estimation';
            app.PositionerrorestimationDropDown = uidropdown(app.HighspeedregionPanel);
            app.PositionerrorestimationDropDown.Items = {'Active Flux', 'APP'};
            app.PositionerrorestimationDropDown.ValueChangedFcn = createCallbackFcn(app, @PositionerrorestimationDropDownValueChanged, true);
            app.PositionerrorestimationDropDown.Value = 'Active Flux';
            app.SensorlessSwitch = uiswitch(app.SensorlesscontrolPanel, 'slider');
            app.SensorlessSwitch.ValueChangedFcn = createCallbackFcn(app, @SensorlessSwitchValueChanged, true);
            app.WaveformTab = uitab(app.TabGroup);
            app.WaveformTab.Title = 'Waveform';
            app.SetupPanel = uipanel(app.WaveformTab);
            app.SetupPanel.Title = 'Setup';
            app.CurrentphaseangleeltdegEditFieldLabel_2 = uilabel(app.SetupPanel);
            app.CurrentphaseangleeltdegEditFieldLabel_2.Text = 'Current phase angle [elt deg]';
            app.WaveformGammaEditField = uieditfield(app.SetupPanel, 'text');
            app.WaveformGammaEditField.ValueChangedFcn = createCallbackFcn(app, @WaveformGammaEditFieldValueChanged, true);
            app.CurrentLoadpuEditFieldLabel_2 = uilabel(app.SetupPanel);
            app.CurrentLoadpuEditFieldLabel_2.Text = 'Current Load [p.u.]';
            app.WaveformCurrentPUEditField = uieditfield(app.SetupPanel, 'text');
            app.WaveformCurrentPUEditField.ValueChangedFcn = createCallbackFcn(app, @WaveformCurrentPUEditFieldValueChanged, true);
            app.PhaseCurrentAEditFieldLabel_2 = uilabel(app.SetupPanel);
            app.PhaseCurrentAEditFieldLabel_2.Text = 'Phase Current [A]';
            app.WaveformCurrentEditField = uieditfield(app.SetupPanel, 'text');
            app.RotorspeedrpmLabel = uilabel(app.SetupPanel);
            app.RotorspeedrpmLabel.Text = 'Rotor speed [rpm]';
            app.WaveformSpeedEditField = uieditfield(app.SetupPanel, 'text');
            app.WaveformSpeedEditField.ValueChangedFcn = createCallbackFcn(app, @WaveformSpeedEditFieldValueChanged, true);
            app.ofperiodsLabel = uilabel(app.SetupPanel);
            app.ofperiodsLabel.Text = '# of periods';
            app.WaveformPeriodsEditField = uieditfield(app.SetupPanel, 'text');
            app.WaveformPeriodsEditField.ValueChangedFcn = createCallbackFcn(app, @WaveformPeriodsEditFieldValueChanged, true);
            app.EvaluationsPanel = uipanel(app.WaveformTab);
            app.EvaluationsPanel.Title = 'Evaluations';
            app.WaveformSingPointButton = uibutton(app.EvaluationsPanel, 'push');
            app.WaveformSingPointButton.ButtonPushedFcn = createCallbackFcn(app, @WaveformSingPointButtonPushed, true);
            app.WaveformSingPointButton.Text = 'Single Point (dqtMap)';
            app.WaveformShortCircuitButton = uibutton(app.EvaluationsPanel, 'push');
            app.WaveformShortCircuitButton.ButtonPushedFcn = createCallbackFcn(app, @WaveformShortCircuitButtonPushed, true);
            app.WaveformShortCircuitButton.Text = 'Transient Short-Circuit';
            app.SyReMagneticModelManipulationLabel = uilabel(app.UIFigure);
            app.SyReMagneticModelManipulationLabel.Text = {'SyR-e'; 'Magnetic Model Manipulation'};
            app.MotorRatingsPanel = uipanel(app.UIFigure);
            app.MotorRatingsPanel.Title = 'Motor Ratings';
            app.GridLayout = uigridlayout(app.MotorRatingsPanel);
            app.MotornameEditFieldLabel = uilabel(app.GridLayout);
            app.MotornameEditFieldLabel.Text = 'Motor name';
            app.MotornameEditField = uieditfield(app.GridLayout, 'text');
            app.MotornameEditField.ValueChangedFcn = createCallbackFcn(app, @MotornameEditFieldValueChanged, true);
            app.MotortypeEditFieldLabel = uilabel(app.GridLayout);
            app.MotortypeEditFieldLabel.Text = 'Motor type';
            app.MotortypeEditField = uieditfield(app.GridLayout, 'text');
            app.MotortypeEditField.ValueChangedFcn = createCallbackFcn(app, @MotortypeEditFieldValueChanged, true);
            app.RatedcurrentEditFieldLabel = uilabel(app.GridLayout);
            app.RatedcurrentEditFieldLabel.Text = 'Rated current';
            app.RatedcurrentEditField = uieditfield(app.GridLayout, 'text');
            app.RatedcurrentEditField.ValueChangedFcn = createCallbackFcn(app, @RatedcurrentEditFieldValueChanged, true);
            app.MaximumcurrentEditFieldLabel = uilabel(app.GridLayout);
            app.MaximumcurrentEditFieldLabel.Text = 'Maximum current';
            app.MaximumcurrentEditField = uieditfield(app.GridLayout, 'text');
            app.MaximumcurrentEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumcurrentEditFieldValueChanged, true);
            app.DClinkvoltageEditFieldLabel = uilabel(app.GridLayout);
            app.DClinkvoltageEditFieldLabel.Text = 'DC link voltage';
            app.DClinkvoltageEditField = uieditfield(app.GridLayout, 'text');
            app.DClinkvoltageEditField.ValueChangedFcn = createCallbackFcn(app, @DClinkvoltageEditFieldValueChanged, true);
            app.PhaseresistanceEditFieldLabel = uilabel(app.GridLayout);
            app.PhaseresistanceEditFieldLabel.Text = 'Phase resistance';
            app.PhaseresistanceEditField = uieditfield(app.GridLayout, 'text');
            app.PhaseresistanceEditField.ValueChangedFcn = createCallbackFcn(app, @PhaseresistanceEditFieldValueChanged, true);
            app.WindingtemperatureEditFieldLabel = uilabel(app.GridLayout);
            app.WindingtemperatureEditFieldLabel.Text = 'Winding temperature';
            app.WindingtemperatureEditField = uieditfield(app.GridLayout, 'text');
            app.WindingtemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @WindingtemperatureEditFieldValueChanged, true);
            app.ActivelengthEditFieldLabel = uilabel(app.GridLayout);
            app.ActivelengthEditFieldLabel.Text = 'Active length';
            app.ActivelengthEditField = uieditfield(app.GridLayout, 'text');
            app.ActivelengthEditField.ValueChangedFcn = createCallbackFcn(app, @ActivelengthEditFieldValueChanged, true);
            app.TurnsinseriesperphaseEditFieldLabel = uilabel(app.GridLayout);
            app.TurnsinseriesperphaseEditFieldLabel.Text = 'Turns in series per phase';
            app.TurnsinseriesperphaseEditField = uieditfield(app.GridLayout, 'text');
            app.TurnsinseriesperphaseEditField.ValueChangedFcn = createCallbackFcn(app, @TurnsinseriesperphaseEditFieldValueChanged, true);
            app.Numberof3phasesetsEditFieldLabel = uilabel(app.GridLayout);
            app.Numberof3phasesetsEditFieldLabel.Text = 'Number of 3phase sets';
            app.Numberof3phasesetsEditField = uieditfield(app.GridLayout, 'text');
            app.Numberof3phasesetsEditField.ValueChangedFcn = createCallbackFcn(app, @Numberof3phasesetsEditFieldValueChanged, true);
            app.ApkLabel = uilabel(app.GridLayout);
            app.ApkLabel.Text = '[Apk]';
            app.ApkLabel_2 = uilabel(app.GridLayout);
            app.ApkLabel_2.Text = '[Apk]';
            app.VLabel = uilabel(app.GridLayout);
            app.VLabel.Text = '[V]';
            app.OhmLabel = uilabel(app.GridLayout);
            app.OhmLabel.Text = '[Ohm]';
            app.mmLabel = uilabel(app.GridLayout);
            app.mmLabel.Text = '[mm]';
            app.MaximumspeedEditFieldLabel = uilabel(app.GridLayout);
            app.MaximumspeedEditFieldLabel.Text = 'Maximum speed';
            app.MaximumspeedEditField = uieditfield(app.GridLayout, 'text');
            app.MaximumspeedEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumspeedEditFieldValueChanged, true);
            app.rpmLabel = uilabel(app.GridLayout);
            app.rpmLabel.Text = '[rpm]';
            app.CLabel = uilabel(app.GridLayout);
            app.CLabel.Text = '[°C]';
            app.RatedpowerEditFieldLabel = uilabel(app.GridLayout);
            app.RatedpowerEditFieldLabel.Text = 'Rated power';
            app.RatedpowerEditField = uieditfield(app.GridLayout, 'text');
            app.RatedpowerEditField.ValueChangedFcn = createCallbackFcn(app, @RatedpowerEditFieldValueChanged, true);
            app.WLabel = uilabel(app.GridLayout);
            app.WLabel.Text = '[W]';
            app.RatedspeedEditFieldLabel = uilabel(app.GridLayout);
            app.RatedspeedEditFieldLabel.Text = 'Rated speed';
            app.RatedspeedEditField = uieditfield(app.GridLayout, 'text');
            app.RatedspeedEditField.ValueChangedFcn = createCallbackFcn(app, @RatedspeedEditFieldValueChanged, true);
            app.rpmLabel_2 = uilabel(app.GridLayout);
            app.rpmLabel_2.Text = '[rpm]';
            app.CLabel_2 = uilabel(app.GridLayout);
            app.CLabel_2.Text = '[°C]';
            app.InertiaEditFieldLabel = uilabel(app.GridLayout);
            app.InertiaEditFieldLabel.Text = 'Inertia';
            app.InertiaEditField = uieditfield(app.GridLayout, 'text');
            app.InertiaEditField.ValueChangedFcn = createCallbackFcn(app, @InertiaEditFieldValueChanged, true);
            app.kgm2Label = uilabel(app.GridLayout);
            app.kgm2Label.Text = '[kg m^2]';
            app.PMtemperatureDropDownLabel = uilabel(app.GridLayout);
            app.PMtemperatureDropDownLabel.Text = 'PM temperature';
            app.PMtemperatureDropDown = uidropdown(app.GridLayout);
            app.PMtemperatureDropDown.Items = {'Add'};
            app.PMtemperatureDropDown.ValueChangedFcn = createCallbackFcn(app, @PMtemperatureDropDownValueChanged, true);
            app.PMtemperatureDropDown.Value = 'Add';
            app.AxistypeEditField = uieditfield(app.GridLayout, 'text');
            app.AxistypeEditField.ValueChangedFcn = createCallbackFcn(app, @AxistypeEditFieldValueChanged, true);
            app.AxistypeLabel = uilabel(app.GridLayout);
            app.AxistypeLabel.Text = 'Axis type';
            app.FileCheckPush = uibutton(app.UIFigure, 'push');
            app.FileCheckPush.ButtonPushedFcn = createCallbackFcn(app, @FileCheckPushButtonPushed, true);
            app.FileCheckPush.Text = 'FileCheck';
            app.LoadPush = uibutton(app.UIFigure, 'push');
            app.LoadPush.ButtonPushedFcn = createCallbackFcn(app, @LoadPushButtonPushed, true);
            app.LoadPush.Tooltip = {''};
            app.LoadPush.Text = 'Load';
            app.SavePush = uibutton(app.UIFigure, 'push');
            app.SavePush.ButtonPushedFcn = createCallbackFcn(app, @SavePushButtonPushed, true);
            app.SavePush.Text = 'Save';
            app.CurrentpathEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentpathEditFieldLabel.Text = 'Current path:';
            app.CurrentPathEditField = uieditfield(app.UIFigure, 'text');
            app.SaveAsPush = uibutton(app.UIFigure, 'push');
            app.SaveAsPush.ButtonPushedFcn = createCallbackFcn(app, @SaveAsPushButtonPushed, true);
            app.SaveAsPush.Text = 'Save As';
            app.NewButton = uibutton(app.UIFigure, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Text = 'New';
            app.CloseallButton = uibutton(app.UIFigure, 'push');
            app.CloseallButton.ButtonPushedFcn = createCallbackFcn(app, @CloseallButtonPushed, true);
            app.CloseallButton.Text = 'Close all';
            app.AxisLogo = uiaxes(app.UIFigure);
            app.AxisLogo.XTick = [];
            app.AxisLogo.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Syre_MMM(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)GUI_Syre_MMM_StartUp(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end